classdef gui_eng_inertia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        I_xxLabel               matlab.ui.control.Label
        I_xxEditField           matlab.ui.control.NumericEditField
        I_yxEditFieldLabel      matlab.ui.control.Label
        I_yxEditField           matlab.ui.control.NumericEditField
        I_xyEditFieldLabel      matlab.ui.control.Label
        I_xyEditField           matlab.ui.control.NumericEditField
        I_xzEditFieldLabel      matlab.ui.control.Label
        I_xzEditField           matlab.ui.control.NumericEditField
        I_yyEditFieldLabel      matlab.ui.control.Label
        I_yyEditField           matlab.ui.control.NumericEditField
        I_yzEditFieldLabel      matlab.ui.control.Label
        I_yzEditField           matlab.ui.control.NumericEditField
        I_zxEditFieldLabel      matlab.ui.control.Label
        I_zxEditField           matlab.ui.control.NumericEditField
        I_zyEditFieldLabel      matlab.ui.control.Label
        I_zyEditField           matlab.ui.control.NumericEditField
        I_zzEditFieldLabel      matlab.ui.control.Label
        I_zzEditField           matlab.ui.control.NumericEditField
        EngineInertiakgm2Label  matlab.ui.control.Label
        OKButton                matlab.ui.control.Button
        SymmetricCheckBox       matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load(['SavedResults/','gui_curr.mat']);
            load(['SavedResults/',gui_curr.input_name]);
%             h=eval(gui_curr.input_name(1:end-4));
%             app.I_xxEditField.Value = h.eng.I(1);
%             app.I_xyEditField.Value = h.eng.I(2);
%             app.I_xzEditField.Value = h.eng.I(3);
%             app.I_yxEditField.Value = h.eng.I(4);
%             app.I_yyEditField.Value = h.eng.I(5);
%             app.I_yzEditField.Value = h.eng.I(6);
%             app.I_zxEditField.Value = h.eng.I(7);
%             app.I_zyEditField.Value = h.eng.I(8);
%             app.I_zzEditField.Value = h.eng.I(9);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            value = app.SymmetricCheckBox.Value;
            if value == 1
                app.I_yxEditField.Value = app.I_xyEditField.Value;
                app.I_zxEditField.Value = app.I_xzEditField.Value;
                app.I_zyEditField.Value = app.I_yzEditField.Value;
                
            end
            
            load('SavedResults/temp_inpp.mat');
            
            temp_inpp.inertia = [app.I_xxEditField.Value app.I_xyEditField.Value app.I_xzEditField.Value;...
                            app.I_yxEditField.Value app.I_yyEditField.Value app.I_yzEditField.Value;...
                            app.I_zxEditField.Value app.I_zyEditField.Value app.I_zzEditField.Value];
                
            save('SavedResults/temp_inpp.mat','temp_inpp');
            delete(app);
            
        end

        % Value changed function: SymmetricCheckBox
        function SymmetricCheckBoxValueChanged(app, event)
            value = app.SymmetricCheckBox.Value
            if value==1
            app.I_yxEditField.Visible = 'off';
            app.I_zxEditField.Visible = 'off';
            app.I_zyEditField.Visible = 'off';
            %             app.I_yxEditField.Label = '';
            %             app.I_zxEditField.Label = '';
            %             app.I_zyEditField.Label = '';
            %             app.I_yxEditField.Value = app.I_xyEditField.Value;
            app.I_zxEditField.Value = app.I_xzEditField.Value;
            app.I_zyEditField.Value = app.I_yzEditField.Value; 
            else
                app.I_yxEditField.Visible = 'on';
            app.I_zxEditField.Visible = 'on';
            app.I_zyEditField.Visible = 'on';
            %             app.I_yxEditField.Label = 'I_yx';
            %             app.I_zxEditField.Label = 'I_zx';
            %             app.I_zyEditField.Label = 'I_zy';
            app.I_yxEditField.Value = app.I_xyEditField.Value;
            app.I_zxEditField.Value = app.I_xzEditField.Value;
            app.I_zyEditField.Value = app.I_yzEditField.Value; 


            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.I_xxLabel = uilabel(app.UIFigure);
            app.I_xxLabel.Text = 'I_xx';
            app.I_xxEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_yxEditFieldLabel = uilabel(app.UIFigure);
            app.I_yxEditFieldLabel.Text = 'I_yx';
            app.I_yxEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_xyEditFieldLabel = uilabel(app.UIFigure);
            app.I_xyEditFieldLabel.Text = 'I_xy';
            app.I_xyEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_xzEditFieldLabel = uilabel(app.UIFigure);
            app.I_xzEditFieldLabel.Text = 'I_xz';
            app.I_xzEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_yyEditFieldLabel = uilabel(app.UIFigure);
            app.I_yyEditFieldLabel.Text = 'I_yy';
            app.I_yyEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_yzEditFieldLabel = uilabel(app.UIFigure);
            app.I_yzEditFieldLabel.Text = 'I_yz';
            app.I_yzEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_zxEditFieldLabel = uilabel(app.UIFigure);
            app.I_zxEditFieldLabel.Text = 'I_zx';
            app.I_zxEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_zyEditFieldLabel = uilabel(app.UIFigure);
            app.I_zyEditFieldLabel.Text = 'I_zy';
            app.I_zyEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_zzEditFieldLabel = uilabel(app.UIFigure);
            app.I_zzEditFieldLabel.Text = 'I_zz';
            app.I_zzEditField = uieditfield(app.UIFigure, 'numeric');
            app.EngineInertiakgm2Label = uilabel(app.UIFigure);
            app.EngineInertiakgm2Label.Text = 'Engine Inertia (kg.m^2)';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SymmetricCheckBox = uicheckbox(app.UIFigure);
            app.SymmetricCheckBox.ValueChangedFcn = createCallbackFcn(app, @SymmetricCheckBoxValueChanged, true);
            app.SymmetricCheckBox.Text = 'Symmetric';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_eng_inertia

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end