classdef gui_input_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InputParametersUIFigure         matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        BodyTab                         matlab.ui.container.Tab
        SprungMassPanel                 matlab.ui.container.Panel
        ChasisIDEditFieldLabel          matlab.ui.control.Label
        ChasisIDEditField               matlab.ui.control.EditField
        SprungMasskgEditFieldLabel      matlab.ui.control.Label
        SprungMasskgEditField           matlab.ui.control.NumericEditField
        I_xxkgm2Label                   matlab.ui.control.Label
        I_xxkgm2EditField               matlab.ui.control.NumericEditField
        I_yykgm2EditFieldLabel          matlab.ui.control.Label
        I_yykgm2EditField               matlab.ui.control.NumericEditField
        RearSuspensionPanel             matlab.ui.container.Panel
        UnsprungMasskgEditFieldLabel    matlab.ui.control.Label
        UnsprungMasskgEditField         matlab.ui.control.NumericEditField
        DampingNsmmEditFieldLabel       matlab.ui.control.Label
        DampingNsmmEditField            matlab.ui.control.NumericEditField
        StiffnessNmmEditFieldLabel      matlab.ui.control.Label
        StiffnessNmmEditField           matlab.ui.control.NumericEditField
        Button                          matlab.ui.control.Button
        FrontSuspensionPanel            matlab.ui.container.Panel
        UnsprungMasskgEditField_2Label  matlab.ui.control.Label
        UnsprungMasskgEditField_2       matlab.ui.control.NumericEditField
        StiffnessNmmEditField_2Label    matlab.ui.control.Label
        StiffnessNmmEditField_2         matlab.ui.control.NumericEditField
        DampingNsmmEditField_2Label     matlab.ui.control.Label
        DampingNsmmEditField_2          matlab.ui.control.NumericEditField
        TireStiffnessEditFieldLabel     matlab.ui.control.Label
        TireStiffnessEditField          matlab.ui.control.NumericEditField
        Wheel1positionmmEditFieldLabel  matlab.ui.control.Label
        Wheel1positionmmEditField       matlab.ui.control.EditField
        Wheel2positionmmEditFieldLabel  matlab.ui.control.Label
        Wheel2positionmmEditField       matlab.ui.control.EditField
        Wheel4positionmmEditFieldLabel  matlab.ui.control.Label
        Wheel4positionmmEditField       matlab.ui.control.EditField
        Wheel3positionmmEditFieldLabel  matlab.ui.control.Label
        Wheel3positionmmEditField       matlab.ui.control.EditField
        SetLocalBodySttiffnessButton    matlab.ui.control.Button
        CoordinatDefinitionPanel        matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        TranslationEditFieldLabel       matlab.ui.control.Label
        TranslationEditField            matlab.ui.control.EditField
        RotationaboutZEditFieldLabel    matlab.ui.control.Label
        RotationaboutZEditField         matlab.ui.control.EditField
        mmLabel                         matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        COMofEngineLabel                matlab.ui.control.Label
        COMofEngineEditField            matlab.ui.control.EditField
        DefineEngineCOMwithrespecttoyourCSLabel  matlab.ui.control.Label
        EngineTab                       matlab.ui.container.Tab
        EngineNameEditFieldLabel        matlab.ui.control.Label
        EngineNameEditField             matlab.ui.control.EditField
        EngineMasskgEditFieldLabel      matlab.ui.control.Label
        EngineMasskgEditField           matlab.ui.control.NumericEditField
        EngineModelEditFieldLabel       matlab.ui.control.Label
        EngineModelEditField            matlab.ui.control.EditField
        EngineInertiaPanel              matlab.ui.container.Panel
        EngineInertiakgm2Label          matlab.ui.control.Label
        SymmetricCheckBox               matlab.ui.control.CheckBox
        I_xxEditField_2Label            matlab.ui.control.Label
        I_xxEditField_2                 matlab.ui.control.NumericEditField
        I_yxEditFieldLabel              matlab.ui.control.Label
        I_yxEditField                   matlab.ui.control.NumericEditField
        I_xyEditFieldLabel              matlab.ui.control.Label
        I_xyEditField                   matlab.ui.control.NumericEditField
        I_xzEditFieldLabel              matlab.ui.control.Label
        I_xzEditField                   matlab.ui.control.NumericEditField
        I_yyEditField_2Label            matlab.ui.control.Label
        I_yyEditField_2                 matlab.ui.control.NumericEditField
        I_yzEditFieldLabel              matlab.ui.control.Label
        I_yzEditField                   matlab.ui.control.NumericEditField
        I_zxEditFieldLabel              matlab.ui.control.Label
        I_zxEditField                   matlab.ui.control.NumericEditField
        I_zyEditFieldLabel              matlab.ui.control.Label
        I_zyEditField                   matlab.ui.control.NumericEditField
        I_zzEditFieldLabel              matlab.ui.control.Label
        I_zzEditField                   matlab.ui.control.NumericEditField
        UIAxesTorque                    matlab.ui.control.UIAxes
        updateplotButton                matlab.ui.control.Button
        rpmMatrixEditFieldLabel         matlab.ui.control.Label
        rpmMatrixEditField              matlab.ui.control.EditField
        TorqueMatrixEditFieldLabel      matlab.ui.control.Label
        TorqueMatrixEditField           matlab.ui.control.EditField
        MountPropertiesTab              matlab.ui.container.Tab
        ConfigurationDropDownLabel      matlab.ui.control.Label
        ConfigurationDropDown           matlab.ui.control.DropDown
        panel_mount1e                   matlab.ui.container.Panel
        InitialStiffnessLabel           matlab.ui.control.Label
        edit_mount1stiff                matlab.ui.control.EditField
        Label17                         matlab.ui.control.Label
        sw_mount1proe                   matlab.ui.control.Switch
        edit_mount1proelowLabel         matlab.ui.control.Label
        edit_mount1stiff_lb             matlab.ui.control.EditField
        Label17_2                       matlab.ui.control.Label
        Label17_3                       matlab.ui.control.Label
        StiffnessLabel                  matlab.ui.control.Label
        Label17_4                       matlab.ui.control.Label
        sw_mount1proe_2                 matlab.ui.control.Switch
        edit_mount1proeupLabel_2        matlab.ui.control.Label
        edit_mount1damp_ub              matlab.ui.control.EditField
        edit_mount1proelowLabel_2       matlab.ui.control.Label
        edit_mount1damp_lb              matlab.ui.control.EditField
        DampingLabel                    matlab.ui.control.Label
        InitialDampingLabel             matlab.ui.control.Label
        edit_mount1damp                 matlab.ui.control.EditField
        Label17_5                       matlab.ui.control.Label
        Label17_6                       matlab.ui.control.Label
        edit_mount1proeupLabel          matlab.ui.control.Label
        edit_mount1stiff_ub             matlab.ui.control.EditField
        panel_mount1e_2                 matlab.ui.container.Panel
        Label17_7                       matlab.ui.control.Label
        sw_mount1proe_3                 matlab.ui.control.Switch
        edit_mount1proeupLabel_3        matlab.ui.control.Label
        edit_mount2stiff_ub             matlab.ui.control.EditField
        edit_mount1proelowLabel_3       matlab.ui.control.Label
        edit_mount2stiff_lb             matlab.ui.control.EditField
        Label17_8                       matlab.ui.control.Label
        Label17_9                       matlab.ui.control.Label
        StiffnessLabel_2                matlab.ui.control.Label
        Label17_10                      matlab.ui.control.Label
        sw_mount1proe_4                 matlab.ui.control.Switch
        edit_mount1proeupLabel_4        matlab.ui.control.Label
        edit_mount2damp_ub              matlab.ui.control.EditField
        edit_mount1proelowLabel_4       matlab.ui.control.Label
        edit_mount2damp_lb              matlab.ui.control.EditField
        DampingLabel_2                  matlab.ui.control.Label
        InitialDampingLabel_2           matlab.ui.control.Label
        edit_mount2damp                 matlab.ui.control.EditField
        Label17_11                      matlab.ui.control.Label
        Label17_12                      matlab.ui.control.Label
        InitialStiffnessLabel_3         matlab.ui.control.Label
        edit_mount2stiff                matlab.ui.control.EditField
        panel_mount1e_3                 matlab.ui.container.Panel
        Label17_13                      matlab.ui.control.Label
        sw_mount1proe_5                 matlab.ui.control.Switch
        edit_mount1proeupLabel_5        matlab.ui.control.Label
        edit_mount3stiff_ub             matlab.ui.control.EditField
        edit_mount1proelowLabel_5       matlab.ui.control.Label
        edit_mount3stiff_lb             matlab.ui.control.EditField
        Label17_14                      matlab.ui.control.Label
        Label17_15                      matlab.ui.control.Label
        StiffnessLabel_3                matlab.ui.control.Label
        Label17_16                      matlab.ui.control.Label
        sw_mount1proe_6                 matlab.ui.control.Switch
        edit_mount1proeupLabel_6        matlab.ui.control.Label
        edit_mount3damp_ub              matlab.ui.control.EditField
        edit_mount1proelowLabel_6       matlab.ui.control.Label
        edit_mount3damp_lb              matlab.ui.control.EditField
        DampingLabel_3                  matlab.ui.control.Label
        Label17_17                      matlab.ui.control.Label
        Label17_18                      matlab.ui.control.Label
        InitialStiffnessLabel_5         matlab.ui.control.Label
        edit_mount3stiff                matlab.ui.control.EditField
        InitialDampingLabel_3           matlab.ui.control.Label
        edit_mount3damp                 matlab.ui.control.EditField
        Mount1HydraulicPropertiesPanel  matlab.ui.container.Panel
        Label17_27                      matlab.ui.control.Label
        edit_mount1_mm                  matlab.ui.control.EditField
        M_mLabel                        matlab.ui.control.Label
        Label17_25                      matlab.ui.control.Label
        K_mLabel                        matlab.ui.control.Label
        edit_mount1_km                  matlab.ui.control.EditField
        Label17_26                      matlab.ui.control.Label
        C_mLabel                        matlab.ui.control.Label
        edit_mount1_cm                  matlab.ui.control.EditField
        ConstraintButton                matlab.ui.control.Button
        MountPositionTab                matlab.ui.container.Tab
        panel_con_orientation           matlab.ui.container.Panel
        sw_mount1ori                    matlab.ui.control.Switch
        Mount1Label                     matlab.ui.control.Label
        edit_mount1oriupLabel           matlab.ui.control.Label
        edit_mount1oriup                matlab.ui.control.EditField
        edit_mount1orilowLabel          matlab.ui.control.Label
        edit_mount1orilow               matlab.ui.control.EditField
        edit_mount1orientationLabel     matlab.ui.control.Label
        edit_mount1orientation          matlab.ui.control.EditField
        Mount2Label                     matlab.ui.control.Label
        edit_mount2oriupLabel           matlab.ui.control.Label
        edit_mount2oriup                matlab.ui.control.EditField
        edit_mount2orilowLabel          matlab.ui.control.Label
        edit_mount2orilow               matlab.ui.control.EditField
        sw_mount2ori                    matlab.ui.control.Switch
        edit_mount2orientationLabel     matlab.ui.control.Label
        edit_mount2orientation          matlab.ui.control.EditField
        Mount3Label                     matlab.ui.control.Label
        edit_mount3oriupLabel           matlab.ui.control.Label
        edit_mount3oriup                matlab.ui.control.EditField
        edit_mount3orilowLabel          matlab.ui.control.Label
        edit_mount3orilow               matlab.ui.control.EditField
        sw_mount3ori                    matlab.ui.control.Switch
        edit_mount3orientationLabel     matlab.ui.control.Label
        edit_mount3orientation          matlab.ui.control.EditField
        panel_con_location              matlab.ui.container.Panel
        Mount1Label_2                   matlab.ui.control.Label
        edit_mount1locupLabel           matlab.ui.control.Label
        edit_mount1locup                matlab.ui.control.EditField
        edit_mount1loclowLabel          matlab.ui.control.Label
        edit_mount1loclow               matlab.ui.control.EditField
        SymValforxCheckBox              matlab.ui.control.CheckBox
        SymValforyCheckBox              matlab.ui.control.CheckBox
        SymValforzCheckBox              matlab.ui.control.CheckBox
        edit_mount1positionLabel        matlab.ui.control.Label
        edit_mount1position             matlab.ui.control.EditField
        Mount2Label_2                   matlab.ui.control.Label
        edit_mount2locupLabel           matlab.ui.control.Label
        edit_mount2locup                matlab.ui.control.EditField
        edit_mount2loclowLabel          matlab.ui.control.Label
        edit_mount2loclow               matlab.ui.control.EditField
        sw_mount2loc                    matlab.ui.control.Switch
        SymValforxCheckBox_2            matlab.ui.control.CheckBox
        SymValforyCheckBox_2            matlab.ui.control.CheckBox
        SymValforzCheckBox_2            matlab.ui.control.CheckBox
        edit_mount2positionLabel        matlab.ui.control.Label
        edit_mount2position             matlab.ui.control.EditField
        Mount3Label_2                   matlab.ui.control.Label
        edit_mount3locupLabel           matlab.ui.control.Label
        edit_mount3locup                matlab.ui.control.EditField
        edit_mount3loclowLabel          matlab.ui.control.Label
        edit_mount3loclow               matlab.ui.control.EditField
        sw_mount3loc                    matlab.ui.control.Switch
        SymValforxCheckBox_3            matlab.ui.control.CheckBox
        SymValforyCheckBox_3            matlab.ui.control.CheckBox
        SymValforzCheckBox_3            matlab.ui.control.CheckBox
        edit_mount3positionLabel        matlab.ui.control.Label
        edit_mount3position             matlab.ui.control.EditField
        sw_mount1loc                    matlab.ui.control.Switch
        LocationsandRotationsWithrespecttoyourCSLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        InputParametersLabel            matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        InputNameEditFieldLabel         matlab.ui.control.Label
        InputNameEditField              matlab.ui.control.EditField
        PreviewButton                   matlab.ui.control.Button
        LocalBodyStiffnessNmmPanel      matlab.ui.container.Panel
        Mount1EditFieldLabel            matlab.ui.control.Label
        Mount1EditField                 matlab.ui.control.EditField
        Mount2EditFieldLabel            matlab.ui.control.Label
        Mount2EditField                 matlab.ui.control.EditField
        Mount3EditFieldLabel            matlab.ui.control.Label
        Mount3EditField                 matlab.ui.control.EditField
        LocalBodyDampingNsmmPanel       matlab.ui.container.Panel
        Mount1EditField_2Label          matlab.ui.control.Label
        Mount1EditField_2               matlab.ui.control.EditField
        Mount2EditField_2Label          matlab.ui.control.Label
        Mount2EditField_2               matlab.ui.control.EditField
        Mount3EditField_2Label          matlab.ui.control.Label
        Mount3EditField_2               matlab.ui.control.EditField
    end


    properties (Access = public)
        wheel_pos=[0 0 0 0 0 0 0 0] % Description
        t1=zeros(41,1) % Description
        handle_gui_main % Description
        name_constraint='nothing' % Description
        
        t_const
        t_k1=eye(3);
        t_k2=eye(3);
        t_k3=eye(3);
        t_c1=eye(3);
        t_c2=eye(3);
        t_c3=eye(3);
    end

    methods (Access = private)
    
        function  write(app,tb,p)
             
            tb.Value = ['[',num2str(p(1)),', ',num2str(p(2)),', ',num2str(p(3)),' ]'];
            
        end
        
       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            close all;
            load('SavedResults/gui_curr.mat');
            load([gui_curr.input_PathName , gui_curr.input_FileName]);
            
            app.InputNameEditField.Value = gui_curr.input_FileName(1:end-8);
            app.InputNameEditField.Editable = 'on';
            h = eval(gui_curr.input_FileName(1:end-4));
            app.t_k1=h.stage0.t_k1;
            app.t_k2=h.stage0.t_k2;
            app.t_k3=h.stage0.t_k3;
            app.t_c1=h.stage0.t_c1;
            app.t_c2=h.stage0.t_c2;
            app.t_c3=h.stage0.t_c3;
            app.Wheel1positionmmEditField.Value=['[',num2str(h.sus.Xs1),',',num2str(h.sus.Ys1),']'];
            app.Wheel2positionmmEditField.Value=['[',num2str(h.sus.Xs2),',',num2str(h.sus.Ys2),']'];
            app.Wheel3positionmmEditField.Value=['[',num2str(h.sus.Xs3),',',num2str(h.sus.Ys3),']'];
            app.Wheel4positionmmEditField.Value=['[',num2str(h.sus.Xs4),',',num2str(h.sus.Ys4),']'];
            
            %             tb = num2cell([h.mount.ub_r_1, h.mount.ub_o_1;...
            %                   h.mount.r_1, h.mount.o_1;...
            %                   h.mount.lb_r_1, h.mount.lb_o_1;...
            %                   h.mount.ub_r_2, h.mount.ub_o_2;...
            %                   h.mount.r_2, h.mount.o_2;...
            %                   h.mount.lb_r_2, h.mount.lb_o_2;...
            %                   h.mount.ub_r_3, h.mount.ub_o_3;...
            %                   h.mount.r_3, h.mount.o_3;...
            %                   h.mount.lb_r_3, h.mount.lb_o_3]);
            %                    
            
            %             app.wheel_pos = [h.sus.Xs1,h.sus.Ys1,h.sus.Xs2,h.sus.Ys2,...
            %                             h.sus.Xs3,h.sus.Ys3,h.sus.Xs4,h.sus.Ys4];
            app.ChasisIDEditField.Value = h.sus.ChassisID;    
            app.I_xxkgm2EditField.Value = h.sus.Isxx;
            app.I_yykgm2EditField.Value = h.sus.Isyy;
            app.SprungMasskgEditField.Value = h.sus.Ms;
            app.UnsprungMasskgEditField_2.Value = h.sus.Mu1;%front
            app.UnsprungMasskgEditField_2.Value = h.sus.Mu4;%front
            app.UnsprungMasskgEditField.Value = h.sus.Mu3;%rear
            app.UnsprungMasskgEditField.Value = h.sus.Mu2;%rear
            app.StiffnessNmmEditField_2.Value = h.sus.ks1;
            app.StiffnessNmmEditField_2.Value = h.sus.ks4;
            app.StiffnessNmmEditField.Value = h.sus.ks3;
            app.StiffnessNmmEditField.Value = h.sus.ks2;
            app.DampingNsmmEditField_2.Value = h.sus.cs1;
            app.DampingNsmmEditField_2.Value = h.sus.cs4;
            app.DampingNsmmEditField.Value = h.sus.cs3;
            app.DampingNsmmEditField.Value = h.sus.cs2;
            app.TireStiffnessEditField.Value = h.sus.kt1;
            app.TireStiffnessEditField.Value = h.sus.kt2;
            app.TireStiffnessEditField.Value = h.sus.kt3;
            app.TireStiffnessEditField.Value = h.sus.kt4;
            %             write(app,app.Mount1EditField,h.StiffLocBody.k1(2:4));
            %             write(app,app.Mount2EditField,h.StiffLocBody.k2);
            %             write(app,app.Mount3EditField,h.StiffLocBody.k3);
            %             write(app,app.Mount1EditField_2,h.StiffLocBody.c1);
            %             write(app,app.Mount2EditField_2,h.StiffLocBody.c2);
            %             write(app,app.Mount3EditField_2,h.StiffLocBody.c3);
            
            write(app,app.COMofEngineEditField,h.sus.E_cm);
            write(app,app.TranslationEditField,h.sus.trans);
            app.RotationaboutZEditField.Value = h.sus.rotz;
            
            app.EngineNameEditField.Value = h.eng.name;
            app.EngineModelEditField.Value = h.eng.model;
            app.EngineMasskgEditField.Value = h.eng.mass;
            app.I_xxEditField_2.Value=h.eng.inertia(1); 
            app.I_xyEditField.Value =h.eng.inertia(4);
            app.I_xzEditField.Value=h.eng.inertia(7);
            app.I_yxEditField.Value =h.eng.inertia(2);
            app.I_yyEditField_2.Value =h.eng.inertia(5);
            app.I_yzEditField.Value=h.eng.inertia(8);
            app.I_zxEditField.Value =h.eng.inertia(3);
            app.I_zyEditField.Value =h.eng.inertia(6);
            app.I_zzEditField.Value=h.eng.inertia(9);
            
            app.rpmMatrixEditField.Value = ['[ ',num2str(h.eng.rpm),' ]'];
            app.TorqueMatrixEditField.Value = ['[ ',num2str(h.eng.torque),' ]'];
            updateplotButtonPushed(app);
            
            app.ConfigurationDropDown.Value = h.mount.config;
            ConfigurationDropDownValueChanged(app);
            
            write(app,app.edit_mount1stiff, h.mount.k_l_1);
            write(app,app.edit_mount1stiff_ub, h.mount.ub_k_1);
            write(app,app.edit_mount1stiff_lb, h.mount.lb_k_1);
            write(app,app.edit_mount1damp, h.mount.c_l_1);
            write(app,app.edit_mount1damp_ub, h.mount.ub_c_1);
            write(app,app.edit_mount1damp_lb, h.mount.lb_c_1);
            
            
            write(app,app.edit_mount2stiff, h.mount.k_l_2);
            write(app,app.edit_mount2stiff_ub, h.mount.ub_k_2);
            write(app,app.edit_mount2stiff_lb, h.mount.lb_k_2);
            write(app,app.edit_mount2damp, h.mount.c_l_2);
            write(app,app.edit_mount2damp_ub, h.mount.ub_c_2);
            write(app,app.edit_mount2damp_lb, h.mount.lb_c_2);
            
            write(app,app.edit_mount3stiff, h.mount.k_l_3);
            write(app,app.edit_mount3stiff_ub, h.mount.ub_k_3);
            write(app,app.edit_mount3stiff_lb, h.mount.lb_k_3);
            write(app,app.edit_mount3damp, h.mount.c_l_3);
            write(app,app.edit_mount3damp_ub, h.mount.ub_c_3);
            write(app,app.edit_mount3damp_lb, h.mount.lb_c_3);
            
            app.edit_mount1_km.Value = num2str(h.mount.k_m);
            app.edit_mount1_mm.Value = num2str(h.mount.m_m);
            app.edit_mount1_cm.Value = num2str(h.mount.c_m);
            
            write(app,app.edit_mount1position,h.mount.r_1+h.sus.E_cm);
            write(app,app.edit_mount2position,h.mount.r_2+h.sus.E_cm);
            write(app,app.edit_mount3position,h.mount.r_3+h.sus.E_cm);
            write(app,app.edit_mount1locup ,h.mount.ub_r_1+h.sus.E_cm);
            write(app,app.edit_mount2locup ,h.mount.ub_r_2+h.sus.E_cm);
            write(app,app.edit_mount3locup, h.mount.ub_r_3+h.sus.E_cm);
            write(app,app.edit_mount1loclow, h.mount.lb_r_1+h.sus.E_cm);
            write(app,app.edit_mount2loclow, h.mount.lb_r_2+h.sus.E_cm);
            write(app,app.edit_mount3loclow, h.mount.lb_r_3+h.sus.E_cm);
            
            write(app,app.edit_mount1orientation, h.mount.o_1);
            write(app,app.edit_mount2orientation,h.mount.o_2);
            write(app,app.edit_mount3orientation,h.mount.o_3);
            write(app,app.edit_mount1oriup,h.mount.ub_o_1);
            write(app,app.edit_mount2oriup,h.mount.ub_o_2);
            write(app,app.edit_mount3oriup,h.mount.ub_o_3);
            write(app,app.edit_mount1orilow, h.mount.lb_o_1);
            write(app,app.edit_mount2orilow, h.mount.lb_o_2);
            write(app,app.edit_mount3orilow, h.mount.lb_o_3);
            clc
            t1=h.stage0.t1
            if t1(1)==0
               app.sw_mount1loc.Value = 'Fixed';
            end
            if t1(4)==0
               app.sw_mount2loc.Value = 'Fixed';
            end
            if t1(7)==0
               app.sw_mount3loc.Value = 'Fixed';
            end
            if t1(10)==0
               app.sw_mount1ori.Value = 'Fixed';
            
            end
            if t1(13)==0
                disp('hi')
               app.sw_mount2ori.Value = 'Fixed';
            end
            if t1(16)==0
               app.sw_mount3ori.Value = 'Fixed';
            end
            if t1(19)==0
               app.sw_mount1proe.Value = 'Fixed';
            end
            if t1(22)==0
               app.sw_mount1proe_3.Value = 'Fixed';
            end
            if t1(25)==0
               app.sw_mount1proe_5.Value = 'Fixed';
            end
            if t1(28)==0
               app.sw_mount1proe_2.Value = 'Fixed';
            end
            if t1(31)==0
               app.sw_mount1proe_4.Value = 'Fixed';
            end
            if t1(24)==0
               app.sw_mount1proe_6.Value = 'Fixed';
            end


            sw_mount1locValueChanged(app);
            sw_mount2locValueChanged(app);
            sw_mount3locValueChanged(app);
            sw_mount1oriValueChanged(app);
            sw_mount2oriValueChanged(app);
            sw_mount3oriValueChanged(app);
            
            sw_mount1proeValueChanged(app);
            sw_mount1proe_2ValueChanged(app);
            sw_mount1proe_3ValueChanged(app);
            sw_mount1proe_4ValueChanged(app);
            sw_mount1proe_5ValueChanged(app);
            sw_mount1proe_6ValueChanged(app);


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: ConfigurationDropDown
        function ConfigurationDropDownValueChanged(app, event)
            value = app.ConfigurationDropDown.Value;
            if strcmp(value,'3 Elastomeric')
                app.panel_mount1e.Title = 'Mount 1 (Elastomeric)';
                app.Mount1HydraulicPropertiesPanel.Visible = 'off'
            else 
                app.panel_mount1e.Title = 'Mount 1 (Hydraulic)';
                app.Mount1HydraulicPropertiesPanel.Visible = 'on'
                
            end
        end

        % Button pushed function: ConstraintButton
        function ConstraintButtonPushed(app, event)
            
            h = gui_struct_cons;
            h.handle_gui_main = app;
        end

        % Value changed function: I_xxEditField_2
        function I_xxEditField_2ValueChanged(app, event)
            value = app.I_xxEditField_2.Value;
            SymmetricCheckBoxValueChanged(app);
        end

        % Value changed function: I_xyEditField
        function I_xyEditFieldValueChanged(app, event)
            value = app.I_xyEditField.Value;
            SymmetricCheckBoxValueChanged(app);
        end

        % Value changed function: I_xzEditField
        function I_xzEditFieldValueChanged(app, event)
            value = app.I_xzEditField.Value;
            SymmetricCheckBoxValueChanged(app);
        end

        % Value changed function: I_yyEditField_2
        function I_yyEditField_2ValueChanged(app, event)
            value = app.I_yyEditField_2.Value;
            SymmetricCheckBoxValueChanged(app);
        end

        % Value changed function: I_yzEditField
        function I_yzEditFieldValueChanged(app, event)
            value = app.I_yzEditField.Value;
            SymmetricCheckBoxValueChanged(app);
        end

        % Value changed function: I_zzEditField
        function I_zzEditFieldValueChanged(app, event)
            value = app.I_zzEditField.Value;
            SymmetricCheckBoxValueChanged(app);
        end

        % Callback function
        function LocationOrientaionButtonPushed(app, event)
            gui_loc_orie
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            gui_preview
        end

        % Callback function
        function ProportionalCheckBoxValueChanged(app, event)
            value = app.ProportionalCheckBox.Value;
            if value==1
                e = app.ethaEditField.Value;
                write(app,app.edit_mount1damp,e*str2num(app.edit_mount1stiff.Value));
                
                app.edit_mount1damp.Editable = 'off';
                app.edit_mount1damp_ub.Visible = 'off';
                app.edit_mount1damp_lb.Visible = 'off';
            else
                app.edit_mount1damp.Editable = 'on';
                app.edit_mount1damp_ub.Visible = 'on';
                app.edit_mount1damp_lb.Visible = 'on';
            end
        end

        % Callback function
        function ProportionalCheckBox_2ValueChanged(app, event)
            value = app.ProportionalCheckBox_2.Value;
            if value==1
                e = app.ethaEditField_2.Value;
                write(app,app.edit_mount2damp,e*str2num(app.edit_mount2stiff.Value));
                
                app.edit_mount2damp.Editable = 'off';
                app.edit_mount2damp_ub.Visible = 'off';
                app.edit_mount2damp_lb.Visible = 'off';
            else
                app.edit_mount2damp.Editable = 'on';
                app.edit_mount2damp_ub.Visible = 'on';
                app.edit_mount2damp_lb.Visible = 'on';
            end
        end

        % Callback function
        function ProportionalCheckBox_3ValueChanged(app, event)
            value = app.ProportionalCheckBox_3.Value;
            if value==1
                e = app.ethaEditField_3.Value;
                write(app,app.edit_mount3damp,e*str2num(app.edit_mount3stiff.Value));
                
                app.edit_mount3damp.Editable = 'off';
                app.edit_mount3damp_ub.Visible = 'off';
                app.edit_mount3damp_lb.Visible = 'off';
            else
                app.edit_mount3damp.Editable = 'on';
                app.edit_mount3damp_ub.Visible = 'on';
                app.edit_mount3damp_lb.Visible = 'on';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
           
            save_input;
            %             delete(app);
            
        end

        % Callback function
        function SetInertiaButtonPushed(app, event)
            gui_eng_inertia
        end

        % Button pushed function: SetLocalBodySttiffnessButton
        function SetLocalBodySttiffnessButtonPushed(app, event)
            winopen('data.xlsx');
        end

        % Callback function
        function SetinertiaButtonPushed(app, event)
            gui_eng_inertia
            
        end

        % Callback function
        function StiffnessDampingButtonPushed(app, event)
            gui_stiffness
        end

        % Value changed function: SymmetricCheckBox
        function SymmetricCheckBoxValueChanged(app, event)
            value = app.SymmetricCheckBox.Value;
            if value==1
                app.I_yxEditField.Editable = 'off';
                app.I_zxEditField.Editable = 'off';
                app.I_zyEditField.Editable = 'off';
                app.I_yxEditField.Value = app.I_xyEditField.Value;
                app.I_zxEditField.Value = app.I_xzEditField.Value;
                app.I_zyEditField.Value = app.I_yzEditField.Value; 
                
                
            else
                app.I_yxEditField.Editable = 'on';
                app.I_zxEditField.Editable = 'on';
                app.I_zyEditField.Editable = 'on';


            end
        end

        % Callback function
        function WheelPositionButtonPushed(app, event)
            %             prompt = {sprintf('Enter wheel position with respect to body center of mass \n(format : [Xs1 Ys1 Xs2 Ys2 Xs3 Ys3 Xs4 Ys4] in mm)')};
            %             dlg_title = 'Input'
            %             num_lines = 1;
            %             defaultans = {['[',num2str(app.wheel_pos),']']};
            %             answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
            %             if isempty(answer)
            %                 return;
            %             end
            %             app.wheel_pos = str2num(cell2mat(answer));
            
        end

        % Callback function
        function ethaEditFieldValueChanged(app, event)
            value = app.ethaEditField.Value;
            ProportionalCheckBoxValueChanged(app);
        end

        % Callback function
        function ethaEditField_2ValueChanged(app, event)
            value = app.ethaEditField_2.Value;
            ProportionalCheckBox_2ValueChanged(app);
        end

        % Callback function
        function ethaEditField_3ValueChanged(app, event)
            value = app.ethaEditField_3.Value;
            ProportionalCheckBox_3ValueChanged(app);
        end

        % Callback function
        function sw_mount1locValueChanged(app, event)
            value = app.sw_mount1loc.Value;
            if strcmp(value,'Bounded')
                app.edit_mount1locup.Editable = 'on';
                app.edit_mount1loclow.Editable = 'on';
                app.t1(1:3) = 1;
                
            else 
                app.edit_mount1locup.Editable = 'off';
                app.edit_mount1loclow.Editable = 'off';
            %                 app.edit_mount1locup.Value = app.edit_mount1position.Value;
            %                 app.edit_mount1loclow.Value = app.edit_mount1position.Value;
                app.t1(1:3) = 0;
            end
        end

        % Value changed function: sw_mount1ori
        function sw_mount1oriValueChanged(app, event)
            value = app.sw_mount1ori.Value;
            if strcmp(value,'Bounded')
                app.edit_mount1oriup.Editable = 'on';
                app.edit_mount1orilow.Editable = 'on';
                app.t1(10:12) = 1;
            else 
                app.edit_mount1oriup.Editable = 'off';
                app.edit_mount1orilow.Editable = 'off';
            %                 app.edit_mount1oriup.Value = app.edit_mount1orientation.Value;
            %                 app.edit_mount1orilow.Value = app.edit_mount1orientation.Value;
                app.t1(10:12) = 0;
            end
        end

        % Value changed function: sw_mount1proe
        function sw_mount1proeValueChanged(app, event)
            value = app.sw_mount1proe.Value;
            if strcmp(value,'Bounded')
                app.edit_mount1stiff_ub.Editable = 'on';
                app.edit_mount1stiff_lb.Editable = 'on';
                app.t1(19:21) = 1;
            %                 app.t_k1 = diag([1 1 1]);
            else 
                app.edit_mount1stiff_ub.Editable = 'off';
                app.edit_mount1stiff_lb.Editable = 'off';
            %                 app.edit_mount1stiff_ub.Value = app.edit_mount1stiff.Value;
            %                 app.edit_mount1stiff_lb.Value = app.edit_mount1stiff.Value;
                app.t1(19:21) = 0;
            %                 app.t_k1 = diag([0 0 0]);
            end
        end

        % Value changed function: sw_mount1proe_2
        function sw_mount1proe_2ValueChanged(app, event)
            value = app.sw_mount1proe_2.Value;
            if strcmp(value,'Bounded')
                app.edit_mount1damp_ub.Editable = 'on';
                app.edit_mount1damp_lb.Editable = 'on';
                app.t1(28:30) = 1;
            %                 app.t_c1 = diag([1 1 1]);
            else 
                app.edit_mount1damp_ub.Editable = 'off';
                app.edit_mount1damp_lb.Editable = 'off';
            %                 app.edit_mount1damp_ub.Value = app.edit_mount1damp.Value;
            %                 app.edit_mount1damp_lb.Value = app.edit_mount1damp.Value;
                app.t1(28:30) = 0;
            %                 app.t_c1 = diag([0 0 0]);
            end
        end

        % Value changed function: sw_mount1proe_3
        function sw_mount1proe_3ValueChanged(app, event)
            value = app.sw_mount1proe_3.Value;
            if strcmp(value,'Bounded')
                app.edit_mount2stiff_ub.Editable = 'on';
                app.edit_mount2stiff_lb.Editable = 'on';
                app.t1(22:24) = 1;
            %                 app.t_k2 = diag([1 1 1]);
            else 
                app.edit_mount2stiff_ub.Editable = 'off';
                app.edit_mount2stiff_lb.Editable = 'off';
            %                 app.edit_mount2stiff_ub.Value = app.edit_mount2stiff.Value;
            %                 app.edit_mount2stiff_lb.Value = app.edit_mount2stiff.Value;
                app.t1(22:24) = 0; 
            %                 app.t_k2 = diag([0 0 0]);
            end
        end

        % Value changed function: sw_mount1proe_4
        function sw_mount1proe_4ValueChanged(app, event)
            value = app.sw_mount1proe_4.Value;
            if strcmp(value,'Bounded')
                app.edit_mount2damp_ub.Editable = 'on';
                app.edit_mount2damp_lb.Editable = 'on';
                app.t1(31:33) = 1;
            %                 app.t_c2 = diag([1 1 1]);
            else 
                app.edit_mount2damp_ub.Editable = 'off';
                app.edit_mount2damp_lb.Editable = 'off';
            %                 app.edit_mount2damp_ub.Value = app.edit_mount2damp.Value;
            %                 app.edit_mount2damp_lb.Value = app.edit_mount2damp.Value;
                app.t1(31:33) = 0; 
            %                 app.t_c2 = diag([0 0 0]);
            end
        end

        % Value changed function: sw_mount1proe_5
        function sw_mount1proe_5ValueChanged(app, event)
            value = app.sw_mount1proe_5.Value;
            if strcmp(value,'Bounded')
                app.edit_mount3stiff_ub.Editable = 'on';
                app.edit_mount3stiff_lb.Editable = 'on';
                app.t1(25:27) = 1; 
            %                 app.t_k3 = diag([1 1 1]);
            else 
                app.edit_mount3stiff_ub.Editable = 'off';
                app.edit_mount3stiff_lb.Editable = 'off';
            %                 app.edit_mount3stiff_ub.Value = app.edit_mount3stiff.Value;
            %                 app.edit_mount3stiff_lb.Value = app.edit_mount3stiff.Value;
                app.t1(25:27) = 0; 
            %                 app.t_k3 = diag([0 0 0]);
            end
        end

        % Value changed function: sw_mount1proe_6
        function sw_mount1proe_6ValueChanged(app, event)
            value = app.sw_mount1proe_6.Value;
            if strcmp(value,'Bounded')
                app.edit_mount3damp_ub.Editable = 'on';
                app.edit_mount3damp_lb.Editable = 'on';
                app.t1(34:36) = 1; 
            %                 app.t_c3 = diag([1 1 1]);
            else 
                app.edit_mount3damp_ub.Editable = 'off';
                app.edit_mount3damp_lb.Editable = 'off';
            %                 app.edit_mount3damp_ub.Value = app.edit_mount3damp.Value;
            %                 app.edit_mount3damp_lb.Value = app.edit_mount3damp.Value;
                app.t1(34:36) = 0; 
            %                 app.t_c3 = diag([0 0 0]);
            end
        end

        % Value changed function: sw_mount2loc
        function sw_mount2locValueChanged(app, event)
            value = app.sw_mount2loc.Value;
            if strcmp(value,'Bounded')
                app.edit_mount2locup.Editable = 'on';
                app.edit_mount2loclow.Editable = 'on';
                app.t1(4:6) = 1;
            else 
                app.edit_mount2locup.Editable = 'off';
                app.edit_mount2loclow.Editable = 'off';
            %                 app.edit_mount2locup.Value = app.edit_mount2position.Value;
            %                 app.edit_mount2loclow.Value = app.edit_mount2position.Value;
                app.t1(4:6) = 0;
            end
        end

        % Value changed function: sw_mount2ori
        function sw_mount2oriValueChanged(app, event)
            value = app.sw_mount2ori.Value;
            if strcmp(value,'Bounded')
                app.edit_mount2oriup.Editable = 'on';
                app.edit_mount2orilow.Editable = 'on';
                app.t1(13:15) = 1;
            else 
                app.edit_mount2oriup.Editable = 'off';
                app.edit_mount2orilow.Editable = 'off';
            %                 app.edit_mount2oriup.Value = app.edit_mount2orientation.Value;
            %                 app.edit_mount2orilow.Value = app.edit_mount2orientation.Value;
                app.t1(13:15) = 0;
            end
        end

        % Value changed function: sw_mount3loc
        function sw_mount3locValueChanged(app, event)
            value = app.sw_mount3loc.Value;
            if strcmp(value,'Bounded')
                app.edit_mount3locup.Editable = 'on';
                app.edit_mount3loclow.Editable = 'on';
                app.t1(7:9) = 1;
            else 
                app.edit_mount3locup.Editable = 'off';
                app.edit_mount3loclow.Editable = 'off';
            %                 app.edit_mount3locup.Value = app.edit_mount3position.Value;
            %                 app.edit_mount3loclow.Value = app.edit_mount3position.Value;
                app.t1(7:9) = 0;
            end
        end

        % Value changed function: sw_mount3ori
        function sw_mount3oriValueChanged(app, event)
            value = app.sw_mount3ori.Value;
            if strcmp(value,'Bounded')
                app.edit_mount3oriup.Editable = 'on';
                app.edit_mount3orilow.Editable = 'on';
                app.t1(16:18) = 1;
            else 
                app.edit_mount3oriup.Editable = 'off';
                app.edit_mount3orilow.Editable = 'off';
            %                 app.edit_mount3oriup.Value = app.edit_mount3orientation.Value;
            %                 app.edit_mount3orilow.Value = app.edit_mount3orientation.Value;
                app.t1(16:18) = 0;
            end
        end

        % Button pushed function: updateplotButton
        function updateplotButtonPushed(app, event)
            ax = app.UIAxesTorque;
            
            rpm = eval(app.rpmMatrixEditField.Value);
            torque = eval(app.TorqueMatrixEditField.Value);
            %             f=fit(rpm',torque','poly2');
            %             x=rpm(1):1:rpm(end);
            plot(ax,rpm,torque,'*');
            xlim(ax,[500 6000]);
            ylim(ax,[50 189]);
            %             hold on;
            %             plot(ax,rpm,torque);
            
            %                 plot(f,rpm,torque);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InputParametersUIFigure = uifigure;
            app.InputParametersUIFigure.Name = 'Input Parameters';
            app.TabGroup2 = uitabgroup(app.InputParametersUIFigure);
            app.BodyTab = uitab(app.TabGroup2);
            app.BodyTab.Title = 'Body';
            app.SprungMassPanel = uipanel(app.BodyTab);
            app.SprungMassPanel.Title = 'Sprung Mass';
            app.ChasisIDEditFieldLabel = uilabel(app.SprungMassPanel);
            app.ChasisIDEditFieldLabel.Text = 'ChasisID';
            app.ChasisIDEditField = uieditfield(app.SprungMassPanel, 'text');
            app.ChasisIDEditField.Value = '123456';
            app.SprungMasskgEditFieldLabel = uilabel(app.SprungMassPanel);
            app.SprungMasskgEditFieldLabel.Text = 'Sprung Mass(kg)';
            app.SprungMasskgEditField = uieditfield(app.SprungMassPanel, 'numeric');
            app.I_xxkgm2Label = uilabel(app.SprungMassPanel);
            app.I_xxkgm2Label.Text = 'I_xx (kg.m^2)';
            app.I_xxkgm2EditField = uieditfield(app.SprungMassPanel, 'numeric');
            app.I_yykgm2EditFieldLabel = uilabel(app.SprungMassPanel);
            app.I_yykgm2EditFieldLabel.Text = 'I_yy (kg.m^2)';
            app.I_yykgm2EditField = uieditfield(app.SprungMassPanel, 'numeric');
            app.RearSuspensionPanel = uipanel(app.BodyTab);
            app.RearSuspensionPanel.Title = 'Rear Suspension';
            app.UnsprungMasskgEditFieldLabel = uilabel(app.RearSuspensionPanel);
            app.UnsprungMasskgEditFieldLabel.Text = {'Unsprung Mass'; '(kg)'};
            app.UnsprungMasskgEditField = uieditfield(app.RearSuspensionPanel, 'numeric');
            app.DampingNsmmEditFieldLabel = uilabel(app.RearSuspensionPanel);
            app.DampingNsmmEditFieldLabel.Text = {'Damping'; '(Ns/mm)'};
            app.DampingNsmmEditField = uieditfield(app.RearSuspensionPanel, 'numeric');
            app.StiffnessNmmEditFieldLabel = uilabel(app.RearSuspensionPanel);
            app.StiffnessNmmEditFieldLabel.Text = {'Stiffness'; 'N/mm'};
            app.StiffnessNmmEditField = uieditfield(app.RearSuspensionPanel, 'numeric');
            app.Button = uibutton(app.BodyTab, 'push');
            app.Button.Icon = 'car_top.png';
            app.Button.Text = '';
            app.FrontSuspensionPanel = uipanel(app.BodyTab);
            app.FrontSuspensionPanel.Title = 'Front Suspension';
            app.UnsprungMasskgEditField_2Label = uilabel(app.FrontSuspensionPanel);
            app.UnsprungMasskgEditField_2Label.Text = {'Unsprung Mass'; '(kg)'};
            app.UnsprungMasskgEditField_2 = uieditfield(app.FrontSuspensionPanel, 'numeric');
            app.StiffnessNmmEditField_2Label = uilabel(app.FrontSuspensionPanel);
            app.StiffnessNmmEditField_2Label.Text = {'Stiffness'; 'N/mm'};
            app.StiffnessNmmEditField_2 = uieditfield(app.FrontSuspensionPanel, 'numeric');
            app.DampingNsmmEditField_2Label = uilabel(app.FrontSuspensionPanel);
            app.DampingNsmmEditField_2Label.Text = {'Damping'; 'Ns/mm'};
            app.DampingNsmmEditField_2 = uieditfield(app.FrontSuspensionPanel, 'numeric');
            app.TireStiffnessEditFieldLabel = uilabel(app.BodyTab);
            app.TireStiffnessEditFieldLabel.Text = 'Tire Stiffness';
            app.TireStiffnessEditField = uieditfield(app.BodyTab, 'numeric');
            app.Wheel1positionmmEditFieldLabel = uilabel(app.BodyTab);
            app.Wheel1positionmmEditFieldLabel.Text = {'Wheel 1'; 'position(mm)'};
            app.Wheel1positionmmEditField = uieditfield(app.BodyTab, 'text');
            app.Wheel2positionmmEditFieldLabel = uilabel(app.BodyTab);
            app.Wheel2positionmmEditFieldLabel.Text = {'Wheel 2'; 'position(mm)'};
            app.Wheel2positionmmEditField = uieditfield(app.BodyTab, 'text');
            app.Wheel4positionmmEditFieldLabel = uilabel(app.BodyTab);
            app.Wheel4positionmmEditFieldLabel.Text = {'Wheel 4'; 'position(mm)'};
            app.Wheel4positionmmEditField = uieditfield(app.BodyTab, 'text');
            app.Wheel3positionmmEditFieldLabel = uilabel(app.BodyTab);
            app.Wheel3positionmmEditFieldLabel.Text = {'Wheel 3'; 'position(mm)'};
            app.Wheel3positionmmEditField = uieditfield(app.BodyTab, 'text');
            app.SetLocalBodySttiffnessButton = uibutton(app.BodyTab, 'push');
            app.SetLocalBodySttiffnessButton.ButtonPushedFcn = createCallbackFcn(app, @SetLocalBodySttiffnessButtonPushed, true);
            app.SetLocalBodySttiffnessButton.Text = 'Set Local Body Sttiffness';
            app.CoordinatDefinitionPanel = uipanel(app.BodyTab);
            app.CoordinatDefinitionPanel.Title = 'Coordinat Definition';
            app.Label = uilabel(app.CoordinatDefinitionPanel);
            app.Label.Text = {'Predefined CS is attached to CG of Body'; 'with orientation as shown in the figure'; 'Define your custom CS '; 'translation and rotation:'};
            app.TranslationEditFieldLabel = uilabel(app.CoordinatDefinitionPanel);
            app.TranslationEditFieldLabel.Text = 'Translation';
            app.TranslationEditField = uieditfield(app.CoordinatDefinitionPanel, 'text');
            app.RotationaboutZEditFieldLabel = uilabel(app.CoordinatDefinitionPanel);
            app.RotationaboutZEditFieldLabel.Text = {'Rotation'; 'about Z'};
            app.RotationaboutZEditField = uieditfield(app.CoordinatDefinitionPanel, 'text');
            app.mmLabel = uilabel(app.CoordinatDefinitionPanel);
            app.mmLabel.Text = 'mm';
            app.degLabel = uilabel(app.CoordinatDefinitionPanel);
            app.degLabel.Text = 'deg';
            app.mmLabel_2 = uilabel(app.CoordinatDefinitionPanel);
            app.mmLabel_2.Text = 'mm';
            app.COMofEngineLabel = uilabel(app.CoordinatDefinitionPanel);
            app.COMofEngineLabel.Text = {'COM of'; ' Engine'; ''};
            app.COMofEngineEditField = uieditfield(app.CoordinatDefinitionPanel, 'text');
            app.COMofEngineEditField.Value = '[0 0 0]';
            app.DefineEngineCOMwithrespecttoyourCSLabel = uilabel(app.CoordinatDefinitionPanel);
            app.DefineEngineCOMwithrespecttoyourCSLabel.Text = 'Define Engine COM with respect to your CS';
            app.EngineTab = uitab(app.TabGroup2);
            app.EngineTab.Title = 'Engine';
            app.EngineNameEditFieldLabel = uilabel(app.EngineTab);
            app.EngineNameEditFieldLabel.Text = 'Engine Name';
            app.EngineNameEditField = uieditfield(app.EngineTab, 'text');
            app.EngineNameEditField.Value = 'saipa_pride';
            app.EngineMasskgEditFieldLabel = uilabel(app.EngineTab);
            app.EngineMasskgEditFieldLabel.Text = 'Engine Mass (kg)';
            app.EngineMasskgEditField = uieditfield(app.EngineTab, 'numeric');
            app.EngineModelEditFieldLabel = uilabel(app.EngineTab);
            app.EngineModelEditFieldLabel.Text = 'Engine Model';
            app.EngineModelEditField = uieditfield(app.EngineTab, 'text');
            app.EngineModelEditField.Value = 'EF7';
            app.EngineInertiaPanel = uipanel(app.EngineTab);
            app.EngineInertiaPanel.Title = 'Engine Inertia';
            app.EngineInertiakgm2Label = uilabel(app.EngineInertiaPanel);
            app.EngineInertiakgm2Label.Text = 'Engine Inertia (kg.m^2)';
            app.SymmetricCheckBox = uicheckbox(app.EngineInertiaPanel);
            app.SymmetricCheckBox.ValueChangedFcn = createCallbackFcn(app, @SymmetricCheckBoxValueChanged, true);
            app.SymmetricCheckBox.Text = 'Symmetric';
            app.I_xxEditField_2Label = uilabel(app.EngineInertiaPanel);
            app.I_xxEditField_2Label.Text = 'I_xx';
            app.I_xxEditField_2 = uieditfield(app.EngineInertiaPanel, 'numeric');
            app.I_xxEditField_2.ValueChangedFcn = createCallbackFcn(app, @I_xxEditField_2ValueChanged, true);
            app.I_yxEditFieldLabel = uilabel(app.EngineInertiaPanel);
            app.I_yxEditFieldLabel.Text = 'I_yx';
            app.I_yxEditField = uieditfield(app.EngineInertiaPanel, 'numeric');
            app.I_xyEditFieldLabel = uilabel(app.EngineInertiaPanel);
            app.I_xyEditFieldLabel.Text = 'I_xy';
            app.I_xyEditField = uieditfield(app.EngineInertiaPanel, 'numeric');
            app.I_xyEditField.ValueChangedFcn = createCallbackFcn(app, @I_xyEditFieldValueChanged, true);
            app.I_xzEditFieldLabel = uilabel(app.EngineInertiaPanel);
            app.I_xzEditFieldLabel.Text = 'I_xz';
            app.I_xzEditField = uieditfield(app.EngineInertiaPanel, 'numeric');
            app.I_xzEditField.ValueChangedFcn = createCallbackFcn(app, @I_xzEditFieldValueChanged, true);
            app.I_yyEditField_2Label = uilabel(app.EngineInertiaPanel);
            app.I_yyEditField_2Label.Text = 'I_yy';
            app.I_yyEditField_2 = uieditfield(app.EngineInertiaPanel, 'numeric');
            app.I_yyEditField_2.ValueChangedFcn = createCallbackFcn(app, @I_yyEditField_2ValueChanged, true);
            app.I_yzEditFieldLabel = uilabel(app.EngineInertiaPanel);
            app.I_yzEditFieldLabel.Text = 'I_yz';
            app.I_yzEditField = uieditfield(app.EngineInertiaPanel, 'numeric');
            app.I_yzEditField.ValueChangedFcn = createCallbackFcn(app, @I_yzEditFieldValueChanged, true);
            app.I_zxEditFieldLabel = uilabel(app.EngineInertiaPanel);
            app.I_zxEditFieldLabel.Text = 'I_zx';
            app.I_zxEditField = uieditfield(app.EngineInertiaPanel, 'numeric');
            app.I_zyEditFieldLabel = uilabel(app.EngineInertiaPanel);
            app.I_zyEditFieldLabel.Text = 'I_zy';
            app.I_zyEditField = uieditfield(app.EngineInertiaPanel, 'numeric');
            app.I_zzEditFieldLabel = uilabel(app.EngineInertiaPanel);
            app.I_zzEditFieldLabel.Text = 'I_zz';
            app.I_zzEditField = uieditfield(app.EngineInertiaPanel, 'numeric');
            app.I_zzEditField.ValueChangedFcn = createCallbackFcn(app, @I_zzEditFieldValueChanged, true);
            app.UIAxesTorque = uiaxes(app.EngineTab);
            title(app.UIAxesTorque, 'Torque - rpm Curve')
            xlabel(app.UIAxesTorque, 'rpm')
            ylabel(app.UIAxesTorque, 'Torque (N.m.)')
            app.updateplotButton = uibutton(app.EngineTab, 'push');
            app.updateplotButton.ButtonPushedFcn = createCallbackFcn(app, @updateplotButtonPushed, true);
            app.updateplotButton.Text = 'update plot';
            app.rpmMatrixEditFieldLabel = uilabel(app.EngineTab);
            app.rpmMatrixEditFieldLabel.Text = 'rpm Matrix';
            app.rpmMatrixEditField = uieditfield(app.EngineTab, 'text');
            app.TorqueMatrixEditFieldLabel = uilabel(app.EngineTab);
            app.TorqueMatrixEditFieldLabel.Text = 'Torque Matrix';
            app.TorqueMatrixEditField = uieditfield(app.EngineTab, 'text');
            app.MountPropertiesTab = uitab(app.TabGroup2);
            app.MountPropertiesTab.Title = 'Mount Properties';
            app.ConfigurationDropDownLabel = uilabel(app.MountPropertiesTab);
            app.ConfigurationDropDownLabel.Text = 'Configuration';
            app.ConfigurationDropDown = uidropdown(app.MountPropertiesTab);
            app.ConfigurationDropDown.Items = {'3 Elastomeric', '1 Hydraulic-2 Elastomeric'};
            app.ConfigurationDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigurationDropDownValueChanged, true);
            app.ConfigurationDropDown.Value = '3 Elastomeric';
            app.panel_mount1e = uipanel(app.MountPropertiesTab);
            app.panel_mount1e.Title = 'Mount 1 (Elastomeric)';
            app.InitialStiffnessLabel = uilabel(app.panel_mount1e);
            app.InitialStiffnessLabel.Text = 'Initial Stiffness:';
            app.edit_mount1stiff = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1stiff.Value = '[18.2, 40, 31]';
            app.Label17 = uilabel(app.panel_mount1e);
            app.Label17.Text = 'N/mm';
            app.sw_mount1proe = uiswitch(app.panel_mount1e, 'slider');
            app.sw_mount1proe.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe.ValueChangedFcn = createCallbackFcn(app, @sw_mount1proeValueChanged, true);
            app.sw_mount1proe.Value = 'Bounded';
            app.edit_mount1proelowLabel = uilabel(app.panel_mount1e);
            app.edit_mount1proelowLabel.Text = 'Lower Bound:';
            app.edit_mount1stiff_lb = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1stiff_lb.Value = '[10, 30, 20]';
            app.Label17_2 = uilabel(app.panel_mount1e);
            app.Label17_2.Text = 'N/mm';
            app.Label17_3 = uilabel(app.panel_mount1e);
            app.Label17_3.Text = 'N/mm';
            app.StiffnessLabel = uilabel(app.panel_mount1e);
            app.StiffnessLabel.Text = 'Stiffness';
            app.Label17_4 = uilabel(app.panel_mount1e);
            app.Label17_4.Text = 'N.s/m';
            app.sw_mount1proe_2 = uiswitch(app.panel_mount1e, 'slider');
            app.sw_mount1proe_2.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe_2.ValueChangedFcn = createCallbackFcn(app, @sw_mount1proe_2ValueChanged, true);
            app.sw_mount1proe_2.Value = 'Bounded';
            app.edit_mount1proeupLabel_2 = uilabel(app.panel_mount1e);
            app.edit_mount1proeupLabel_2.Text = 'Upper Bound:';
            app.edit_mount1damp_ub = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1damp_ub.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_2 = uilabel(app.panel_mount1e);
            app.edit_mount1proelowLabel_2.Text = 'Lower Bound:';
            app.edit_mount1damp_lb = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1damp_lb.Value = '[10, 30, 20]';
            app.DampingLabel = uilabel(app.panel_mount1e);
            app.DampingLabel.Text = 'Damping';
            app.InitialDampingLabel = uilabel(app.panel_mount1e);
            app.InitialDampingLabel.Text = 'Initial ِDamping:';
            app.edit_mount1damp = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1damp.Value = '[18.2, 40, 31]';
            app.Label17_5 = uilabel(app.panel_mount1e);
            app.Label17_5.Text = 'N.s/m';
            app.Label17_6 = uilabel(app.panel_mount1e);
            app.Label17_6.Text = 'N.s/m';
            app.edit_mount1proeupLabel = uilabel(app.panel_mount1e);
            app.edit_mount1proeupLabel.Text = 'Upper Bound:';
            app.edit_mount1stiff_ub = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1stiff_ub.Value = '[30, 50, 40]';
            app.panel_mount1e_2 = uipanel(app.MountPropertiesTab);
            app.panel_mount1e_2.Title = 'Mount 2 (Elastomeric)';
            app.Label17_7 = uilabel(app.panel_mount1e_2);
            app.Label17_7.Text = 'N/mm';
            app.sw_mount1proe_3 = uiswitch(app.panel_mount1e_2, 'slider');
            app.sw_mount1proe_3.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe_3.ValueChangedFcn = createCallbackFcn(app, @sw_mount1proe_3ValueChanged, true);
            app.sw_mount1proe_3.Value = 'Bounded';
            app.edit_mount1proeupLabel_3 = uilabel(app.panel_mount1e_2);
            app.edit_mount1proeupLabel_3.Text = 'Upper Bound:';
            app.edit_mount2stiff_ub = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2stiff_ub.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_3 = uilabel(app.panel_mount1e_2);
            app.edit_mount1proelowLabel_3.Text = 'Lower Bound:';
            app.edit_mount2stiff_lb = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2stiff_lb.Value = '[10, 30, 20]';
            app.Label17_8 = uilabel(app.panel_mount1e_2);
            app.Label17_8.Text = 'N/mm';
            app.Label17_9 = uilabel(app.panel_mount1e_2);
            app.Label17_9.Text = 'N/mm';
            app.StiffnessLabel_2 = uilabel(app.panel_mount1e_2);
            app.StiffnessLabel_2.Text = 'Stiffness';
            app.Label17_10 = uilabel(app.panel_mount1e_2);
            app.Label17_10.Text = 'N.s/m';
            app.sw_mount1proe_4 = uiswitch(app.panel_mount1e_2, 'slider');
            app.sw_mount1proe_4.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe_4.ValueChangedFcn = createCallbackFcn(app, @sw_mount1proe_4ValueChanged, true);
            app.sw_mount1proe_4.Value = 'Bounded';
            app.edit_mount1proeupLabel_4 = uilabel(app.panel_mount1e_2);
            app.edit_mount1proeupLabel_4.Text = 'Upper Bound:';
            app.edit_mount2damp_ub = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2damp_ub.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_4 = uilabel(app.panel_mount1e_2);
            app.edit_mount1proelowLabel_4.Text = 'Lower Bound:';
            app.edit_mount2damp_lb = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2damp_lb.Value = '[10, 30, 20]';
            app.DampingLabel_2 = uilabel(app.panel_mount1e_2);
            app.DampingLabel_2.Text = 'Damping';
            app.InitialDampingLabel_2 = uilabel(app.panel_mount1e_2);
            app.InitialDampingLabel_2.Text = 'Initial Damping:';
            app.edit_mount2damp = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2damp.Value = '[18.2, 40, 31]';
            app.Label17_11 = uilabel(app.panel_mount1e_2);
            app.Label17_11.Text = 'N.s/m';
            app.Label17_12 = uilabel(app.panel_mount1e_2);
            app.Label17_12.Text = 'N.s/m';
            app.InitialStiffnessLabel_3 = uilabel(app.panel_mount1e_2);
            app.InitialStiffnessLabel_3.Text = 'Initial Stiffness:';
            app.edit_mount2stiff = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2stiff.Value = '[18.2, 40, 31]';
            app.panel_mount1e_3 = uipanel(app.MountPropertiesTab);
            app.panel_mount1e_3.Title = 'Mount 3 (Elastomeric)';
            app.Label17_13 = uilabel(app.panel_mount1e_3);
            app.Label17_13.Text = 'N/mm';
            app.sw_mount1proe_5 = uiswitch(app.panel_mount1e_3, 'slider');
            app.sw_mount1proe_5.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe_5.ValueChangedFcn = createCallbackFcn(app, @sw_mount1proe_5ValueChanged, true);
            app.sw_mount1proe_5.Value = 'Bounded';
            app.edit_mount1proeupLabel_5 = uilabel(app.panel_mount1e_3);
            app.edit_mount1proeupLabel_5.Text = 'Upper Bound:';
            app.edit_mount3stiff_ub = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3stiff_ub.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_5 = uilabel(app.panel_mount1e_3);
            app.edit_mount1proelowLabel_5.Text = 'Lower Bound:';
            app.edit_mount3stiff_lb = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3stiff_lb.Value = '[10, 30, 20]';
            app.Label17_14 = uilabel(app.panel_mount1e_3);
            app.Label17_14.Text = 'N/mm';
            app.Label17_15 = uilabel(app.panel_mount1e_3);
            app.Label17_15.Text = 'N/mm';
            app.StiffnessLabel_3 = uilabel(app.panel_mount1e_3);
            app.StiffnessLabel_3.Text = 'Stiffness';
            app.Label17_16 = uilabel(app.panel_mount1e_3);
            app.Label17_16.Text = 'N.s/m';
            app.sw_mount1proe_6 = uiswitch(app.panel_mount1e_3, 'slider');
            app.sw_mount1proe_6.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe_6.ValueChangedFcn = createCallbackFcn(app, @sw_mount1proe_6ValueChanged, true);
            app.sw_mount1proe_6.Value = 'Bounded';
            app.edit_mount1proeupLabel_6 = uilabel(app.panel_mount1e_3);
            app.edit_mount1proeupLabel_6.Text = 'Upper Bound:';
            app.edit_mount3damp_ub = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3damp_ub.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_6 = uilabel(app.panel_mount1e_3);
            app.edit_mount1proelowLabel_6.Text = 'Lower Bound:';
            app.edit_mount3damp_lb = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3damp_lb.Value = '[10, 30, 20]';
            app.DampingLabel_3 = uilabel(app.panel_mount1e_3);
            app.DampingLabel_3.Text = 'Damping';
            app.Label17_17 = uilabel(app.panel_mount1e_3);
            app.Label17_17.Text = 'N.s/m';
            app.Label17_18 = uilabel(app.panel_mount1e_3);
            app.Label17_18.Text = 'N.s/m';
            app.InitialStiffnessLabel_5 = uilabel(app.panel_mount1e_3);
            app.InitialStiffnessLabel_5.Text = 'Initial Stiffness:';
            app.edit_mount3stiff = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3stiff.Value = '[18.2, 40, 31]';
            app.InitialDampingLabel_3 = uilabel(app.panel_mount1e_3);
            app.InitialDampingLabel_3.Text = 'Initial Damping:';
            app.edit_mount3damp = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3damp.Value = '[18.2, 40, 31]';
            app.Mount1HydraulicPropertiesPanel = uipanel(app.MountPropertiesTab);
            app.Mount1HydraulicPropertiesPanel.Title = 'Mount1 (Hydraulic Properties)';
            app.Label17_27 = uilabel(app.Mount1HydraulicPropertiesPanel);
            app.Label17_27.Text = 'Kg';
            app.edit_mount1_mm = uieditfield(app.Mount1HydraulicPropertiesPanel, 'text');
            app.edit_mount1_mm.Value = '50';
            app.M_mLabel = uilabel(app.Mount1HydraulicPropertiesPanel);
            app.M_mLabel.Text = 'M_m:';
            app.Label17_25 = uilabel(app.Mount1HydraulicPropertiesPanel);
            app.Label17_25.Text = 'N/mm';
            app.K_mLabel = uilabel(app.Mount1HydraulicPropertiesPanel);
            app.K_mLabel.Text = 'K_m:';
            app.edit_mount1_km = uieditfield(app.Mount1HydraulicPropertiesPanel, 'text');
            app.edit_mount1_km.Value = '100';
            app.Label17_26 = uilabel(app.Mount1HydraulicPropertiesPanel);
            app.Label17_26.Text = 'N.s/m';
            app.C_mLabel = uilabel(app.Mount1HydraulicPropertiesPanel);
            app.C_mLabel.Text = 'C_m:';
            app.edit_mount1_cm = uieditfield(app.Mount1HydraulicPropertiesPanel, 'text');
            app.edit_mount1_cm.Value = '2';
            app.ConstraintButton = uibutton(app.MountPropertiesTab, 'push');
            app.ConstraintButton.ButtonPushedFcn = createCallbackFcn(app, @ConstraintButtonPushed, true);
            app.ConstraintButton.Icon = 'setting.png';
            app.ConstraintButton.Text = 'Constraint';
            app.MountPositionTab = uitab(app.TabGroup2);
            app.MountPositionTab.Title = 'Mount Position';
            app.panel_con_orientation = uipanel(app.MountPositionTab);
            app.panel_con_orientation.Title = 'Orientation';
            app.sw_mount1ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount1ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount1ori.ValueChangedFcn = createCallbackFcn(app, @sw_mount1oriValueChanged, true);
            app.sw_mount1ori.Value = 'Bounded';
            app.Mount1Label = uilabel(app.panel_con_orientation);
            app.Mount1Label.Text = 'Mount 1';
            app.edit_mount1oriupLabel = uilabel(app.panel_con_orientation);
            app.edit_mount1oriupLabel.Text = 'Upper Bound:';
            app.edit_mount1oriup = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount1oriup.Value = '[90, 90, 90]';
            app.edit_mount1orilowLabel = uilabel(app.panel_con_orientation);
            app.edit_mount1orilowLabel.Text = 'Lower Bound:';
            app.edit_mount1orilow = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount1orilow.Value = '[0, 0, 0]';
            app.edit_mount1orientationLabel = uilabel(app.panel_con_orientation);
            app.edit_mount1orientationLabel.Text = 'Fixed Orientation:';
            app.edit_mount1orientation = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount1orientation.Value = '[0, 0, 0]';
            app.Mount2Label = uilabel(app.panel_con_orientation);
            app.Mount2Label.Text = 'Mount 2';
            app.edit_mount2oriupLabel = uilabel(app.panel_con_orientation);
            app.edit_mount2oriupLabel.Text = 'Upper Bound:';
            app.edit_mount2oriup = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount2oriup.Value = '[90, 90, 90]';
            app.edit_mount2orilowLabel = uilabel(app.panel_con_orientation);
            app.edit_mount2orilowLabel.Text = 'Lower Bound:';
            app.edit_mount2orilow = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount2orilow.Value = '[0, 0, 0]';
            app.sw_mount2ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount2ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount2ori.ValueChangedFcn = createCallbackFcn(app, @sw_mount2oriValueChanged, true);
            app.sw_mount2ori.Value = 'Bounded';
            app.edit_mount2orientationLabel = uilabel(app.panel_con_orientation);
            app.edit_mount2orientationLabel.Text = 'Fixed Orientation:';
            app.edit_mount2orientation = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount2orientation.Value = '[0, 0, 0]';
            app.Mount3Label = uilabel(app.panel_con_orientation);
            app.Mount3Label.Text = 'Mount 3';
            app.edit_mount3oriupLabel = uilabel(app.panel_con_orientation);
            app.edit_mount3oriupLabel.Text = 'Upper Bound:';
            app.edit_mount3oriup = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount3oriup.Value = '[90, 90, 90]';
            app.edit_mount3orilowLabel = uilabel(app.panel_con_orientation);
            app.edit_mount3orilowLabel.Text = 'Lower Bound:';
            app.edit_mount3orilow = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount3orilow.Value = '[0, 0, 0]';
            app.sw_mount3ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount3ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount3ori.ValueChangedFcn = createCallbackFcn(app, @sw_mount3oriValueChanged, true);
            app.sw_mount3ori.Value = 'Bounded';
            app.edit_mount3orientationLabel = uilabel(app.panel_con_orientation);
            app.edit_mount3orientationLabel.Text = 'Fixed Orientation:';
            app.edit_mount3orientation = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount3orientation.Value = '[0, 0, 0]';
            app.panel_con_location = uipanel(app.MountPositionTab);
            app.panel_con_location.Title = 'Location';
            app.Mount1Label_2 = uilabel(app.panel_con_location);
            app.Mount1Label_2.Text = 'Mount 1';
            app.edit_mount1locupLabel = uilabel(app.panel_con_location);
            app.edit_mount1locupLabel.Text = 'Upper Bound:';
            app.edit_mount1locup = uieditfield(app.panel_con_location, 'text');
            app.edit_mount1locup.Value = '[200, 380, 215]';
            app.edit_mount1loclowLabel = uilabel(app.panel_con_location);
            app.edit_mount1loclowLabel.Text = 'Lower Bound:';
            app.edit_mount1loclow = uieditfield(app.panel_con_location, 'text');
            app.edit_mount1loclow.Value = '[-250, -400, -50]';
            app.SymValforxCheckBox = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox.Visible = 'off';
            app.SymValforxCheckBox.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox.Visible = 'off';
            app.SymValforyCheckBox.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox.Visible = 'off';
            app.SymValforzCheckBox.Text = 'Sym. Val. for z';
            app.edit_mount1positionLabel = uilabel(app.panel_con_location);
            app.edit_mount1positionLabel.Text = 'Fixed Position:';
            app.edit_mount1position = uieditfield(app.panel_con_location, 'text');
            app.edit_mount1position.Value = '[13.5, -389, 203]';
            app.Mount2Label_2 = uilabel(app.panel_con_location);
            app.Mount2Label_2.Text = 'Mount 2';
            app.edit_mount2locupLabel = uilabel(app.panel_con_location);
            app.edit_mount2locupLabel.Text = 'Upper Bound:';
            app.edit_mount2locup = uieditfield(app.panel_con_location, 'text');
            app.edit_mount2locup.Value = '[-415, 390, -170]';
            app.edit_mount2loclowLabel = uilabel(app.panel_con_location);
            app.edit_mount2loclowLabel.Text = 'Lower Bound:';
            app.edit_mount2loclow = uieditfield(app.panel_con_location, 'text');
            app.edit_mount2loclow.Value = '[-430, -210, -180]';
            app.sw_mount2loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount2loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount2loc.ValueChangedFcn = createCallbackFcn(app, @sw_mount2locValueChanged, true);
            app.sw_mount2loc.Value = 'Bounded';
            app.SymValforxCheckBox_2 = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox_2.Visible = 'off';
            app.SymValforxCheckBox_2.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox_2 = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox_2.Visible = 'off';
            app.SymValforyCheckBox_2.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox_2 = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox_2.Visible = 'off';
            app.SymValforzCheckBox_2.Text = 'Sym. Val. for z';
            app.edit_mount2positionLabel = uilabel(app.panel_con_location);
            app.edit_mount2positionLabel.Text = 'Fixed Position:';
            app.edit_mount2position = uieditfield(app.panel_con_location, 'text');
            app.edit_mount2position.Value = '[-424, 92, -175]';
            app.Mount3Label_2 = uilabel(app.panel_con_location);
            app.Mount3Label_2.Text = 'Mount 3';
            app.edit_mount3locupLabel = uilabel(app.panel_con_location);
            app.edit_mount3locupLabel.Text = 'Upper Bound:';
            app.edit_mount3locup = uieditfield(app.panel_con_location, 'text');
            app.edit_mount3locup.Value = '[190, 460, 210]';
            app.edit_mount3loclowLabel = uilabel(app.panel_con_location);
            app.edit_mount3loclowLabel.Text = 'Lower Bound:';
            app.edit_mount3loclow = uieditfield(app.panel_con_location, 'text');
            app.edit_mount3loclow.Value = '[-250, 450, -70]';
            app.sw_mount3loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount3loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount3loc.ValueChangedFcn = createCallbackFcn(app, @sw_mount3locValueChanged, true);
            app.sw_mount3loc.Value = 'Bounded';
            app.SymValforxCheckBox_3 = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox_3.Visible = 'off';
            app.SymValforxCheckBox_3.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox_3 = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox_3.Visible = 'off';
            app.SymValforyCheckBox_3.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox_3 = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox_3.Visible = 'off';
            app.SymValforzCheckBox_3.Text = 'Sym. Val. for z';
            app.edit_mount3positionLabel = uilabel(app.panel_con_location);
            app.edit_mount3positionLabel.Text = 'Fixed Position:';
            app.edit_mount3position = uieditfield(app.panel_con_location, 'text');
            app.edit_mount3position.Value = '[182, 454, -68]';
            app.sw_mount1loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount1loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount1loc.ValueChangedFcn = createCallbackFcn(app, @sw_mount1locValueChanged, true);
            app.sw_mount1loc.Value = 'Bounded';
            app.LocationsandRotationsWithrespecttoyourCSLabel = uilabel(app.MountPositionTab);
            app.LocationsandRotationsWithrespecttoyourCSLabel.Text = {'Locations and Rotations'; 'With respect to your CS'};
            app.SaveButton = uibutton(app.InputParametersUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.InputParametersLabel = uilabel(app.InputParametersUIFigure);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.CloseButton = uibutton(app.InputParametersUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InputNameEditFieldLabel = uilabel(app.InputParametersUIFigure);
            app.InputNameEditFieldLabel.Text = 'Input Name';
            app.InputNameEditField = uieditfield(app.InputParametersUIFigure, 'text');
            app.PreviewButton = uibutton(app.InputParametersUIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.LocalBodyStiffnessNmmPanel = uipanel(app.InputParametersUIFigure);
            app.LocalBodyStiffnessNmmPanel.Title = 'Local Body Stiffness (N/mm)';
            app.Mount1EditFieldLabel = uilabel(app.LocalBodyStiffnessNmmPanel);
            app.Mount1EditFieldLabel.Text = 'Mount 1';
            app.Mount1EditField = uieditfield(app.LocalBodyStiffnessNmmPanel, 'text');
            app.Mount2EditFieldLabel = uilabel(app.LocalBodyStiffnessNmmPanel);
            app.Mount2EditFieldLabel.Text = 'Mount 2';
            app.Mount2EditField = uieditfield(app.LocalBodyStiffnessNmmPanel, 'text');
            app.Mount3EditFieldLabel = uilabel(app.LocalBodyStiffnessNmmPanel);
            app.Mount3EditFieldLabel.Text = 'Mount 3';
            app.Mount3EditField = uieditfield(app.LocalBodyStiffnessNmmPanel, 'text');
            app.LocalBodyDampingNsmmPanel = uipanel(app.InputParametersUIFigure);
            app.LocalBodyDampingNsmmPanel.Title = 'Local Body Damping (N.s/mm)';
            app.Mount1EditField_2Label = uilabel(app.LocalBodyDampingNsmmPanel);
            app.Mount1EditField_2Label.Text = 'Mount 1';
            app.Mount1EditField_2 = uieditfield(app.LocalBodyDampingNsmmPanel, 'text');
            app.Mount2EditField_2Label = uilabel(app.LocalBodyDampingNsmmPanel);
            app.Mount2EditField_2Label.Text = 'Mount 2';
            app.Mount2EditField_2 = uieditfield(app.LocalBodyDampingNsmmPanel, 'text');
            app.Mount3EditField_2Label = uilabel(app.LocalBodyDampingNsmmPanel);
            app.Mount3EditField_2Label.Text = 'Mount 3';
            app.Mount3EditField_2 = uieditfield(app.LocalBodyDampingNsmmPanel, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_input_parameters

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InputParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InputParametersUIFigure)
        end
    end
end