classdef gui_loadinput < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RefreshButton               matlab.ui.control.Button
        AvailableFilesListBoxLabel  matlab.ui.control.Label
        AvailableFilesListBox       matlab.ui.control.ListBox
        LoadButton                  matlab.ui.control.Button
        CloseButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        
    end

    properties (Access = public)
        app_main % Description
        item_input
    end

    methods (Access = public)
    
        function results = func3(app)
            results = 2 *a;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             mat_files=dir('SavedResults/*.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.AvailableFilesListBox.Items(i)={mat_files(i).name};
                
            end
            app.AvailableFilesListBox.Value=app.AvailableFilesListBox.Items(1);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
           mat_files=dir('SavedResults/*.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.AvailableFilesListBox.Items(i)={mat_files(i).name};
                
            end
             app.AvailableFilesListBox.Value=app.AvailableFilesListBox.Items(1);


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.item_input = app.AvailableFilesListBox.Value;
            disp(app.AvailableFilesListBox.Value)
            app.app_main.NameEditField.text = app.item_input;
            disp('hi2')    
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.AvailableFilesListBoxLabel = uilabel(app.UIFigure);
            app.AvailableFilesListBoxLabel.Text = 'Available Files';
            app.AvailableFilesListBox = uilistbox(app.UIFigure);
            app.AvailableFilesListBox.Items = {};
            app.AvailableFilesListBox.Value = {};
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_loadinput()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end