classdef gui_loc_orie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        panel_mountingsystem         matlab.ui.container.Panel
        panel_con_orientation        matlab.ui.container.Panel
        sw_mount1ori                 matlab.ui.control.Switch
        Mount1Label                  matlab.ui.control.Label
        edit_mount1oriupLabel        matlab.ui.control.Label
        edit_mount1oriup             matlab.ui.control.EditField
        edit_mount1orilowLabel       matlab.ui.control.Label
        edit_mount1orilow            matlab.ui.control.EditField
        edit_mount1orientationLabel  matlab.ui.control.Label
        edit_mount1orientation       matlab.ui.control.EditField
        Mount2Label                  matlab.ui.control.Label
        edit_mount2oriupLabel        matlab.ui.control.Label
        edit_mount2oriup             matlab.ui.control.EditField
        edit_mount2orilowLabel       matlab.ui.control.Label
        edit_mount2orilow            matlab.ui.control.EditField
        sw_mount2ori                 matlab.ui.control.Switch
        edit_mount2orientationLabel  matlab.ui.control.Label
        edit_mount2orientation       matlab.ui.control.EditField
        Mount3Label                  matlab.ui.control.Label
        edit_mount3oriupLabel        matlab.ui.control.Label
        edit_mount3oriup             matlab.ui.control.EditField
        edit_mount3orilowLabel       matlab.ui.control.Label
        edit_mount3orilow            matlab.ui.control.EditField
        sw_mount3ori                 matlab.ui.control.Switch
        edit_mount3orientationLabel  matlab.ui.control.Label
        edit_mount3orientation       matlab.ui.control.EditField
        panel_con_location           matlab.ui.container.Panel
        sw_mount1loc                 matlab.ui.control.Switch
        Mount1Label_2                matlab.ui.control.Label
        edit_mount1locupLabel        matlab.ui.control.Label
        edit_mount1locup             matlab.ui.control.EditField
        edit_mount1loclowLabel       matlab.ui.control.Label
        edit_mount1loclow            matlab.ui.control.EditField
        SymValforxCheckBox           matlab.ui.control.CheckBox
        SymValforyCheckBox           matlab.ui.control.CheckBox
        SymValforzCheckBox           matlab.ui.control.CheckBox
        edit_mount1positionLabel     matlab.ui.control.Label
        edit_mount1position          matlab.ui.control.EditField
        Mount2Label_2                matlab.ui.control.Label
        edit_mount2locupLabel        matlab.ui.control.Label
        edit_mount2locup             matlab.ui.control.EditField
        edit_mount2loclowLabel       matlab.ui.control.Label
        edit_mount2loclow            matlab.ui.control.EditField
        sw_mount2loc                 matlab.ui.control.Switch
        SymValforxCheckBox_2         matlab.ui.control.CheckBox
        SymValforyCheckBox_2         matlab.ui.control.CheckBox
        SymValforzCheckBox_2         matlab.ui.control.CheckBox
        edit_mount2positionLabel     matlab.ui.control.Label
        edit_mount2position          matlab.ui.control.EditField
        Mount3Label_2                matlab.ui.control.Label
        edit_mount3locupLabel        matlab.ui.control.Label
        edit_mount3locup             matlab.ui.control.EditField
        edit_mount3loclowLabel       matlab.ui.control.Label
        edit_mount3loclow            matlab.ui.control.EditField
        sw_mount3loc                 matlab.ui.control.Switch
        SymValforxCheckBox_3         matlab.ui.control.CheckBox
        SymValforyCheckBox_3         matlab.ui.control.CheckBox
        SymValforzCheckBox_3         matlab.ui.control.CheckBox
        edit_mount3positionLabel     matlab.ui.control.Label
        edit_mount3position          matlab.ui.control.EditField
        Label15                      matlab.ui.control.Label
        Label14                      matlab.ui.control.Label
        OKButton                     matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('gui_curr.mat');
            if strcmp(gui_curr.input_name , 'not selected')==0
                load(['SavedResults/',gui_curr.input_name]);
                
            else
                
               load(['SavedResults/','temp.mat']);
             
            end
            
            app.edit_mount1position.Value = mat2str(r_1');
            app.edit_mount1locup.Value = mat2str(ub_r_1');
            app.edit_mount1loclow.Value = mat2str(lb_r_1');
            app.edit_mount1orientation.Value = mat2str(o_1'.* 180/pi);
            app.edit_mount1oriup.Value = mat2str(ub_o_1'.* 180/pi);
            app.edit_mount1orilow.Value = mat2str(lb_o_1'.* 180/pi);
            
            app.edit_mount2position.Value = mat2str(r_2');
            app.edit_mount2locup.Value = mat2str(ub_r_2');
            app.edit_mount2loclow.Value = mat2str(lb_r_2');
            app.edit_mount2orientation.Value = mat2str(o_2'.* 180/pi);
            app.edit_mount2oriup.Value = mat2str(ub_o_2'.* 180/pi);
            app.edit_mount2orilow.Value = mat2str(lb_o_2'.* 180/pi);
            
            app.edit_mount3position.Value = mat2str(r_3');
            app.edit_mount3locup.Value = mat2str(ub_r_3');
            app.edit_mount3loclow.Value = mat2str(lb_r_3');
            app.edit_mount3orientation.Value = mat2str(o_3'.* 180/pi);
            app.edit_mount3oriup.Value = mat2str(ub_o_3'.* 180/pi);
            app.edit_mount3orilow.Value = mat2str(lb_o_3'.* 180/pi);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            load(['SavedResults/','temp.mat']);
            r_1 = str2num(app.edit_mount1position.Value);
            ub_r_1 = str2num(app.edit_mount1locup.Value);
            lb_r_1 = str2num(app.edit_mount1loclow.Value);
            o_1 = str2num(app.edit_mount1orientation.Value);
            ub_o_1 = str2num(app.edit_mount1oriup.Value);
            lb_o_1 = str2num(app.edit_mount1orilow.Value);
            
            r_2 = str2num(app.edit_mount2position.Value);
            ub_r_2 = str2num(app.edit_mount2locup.Value);
            lb_r_2 = str2num(app.edit_mount2loclow.Value);
            o_2 = str2num(app.edit_mount2orientation.Value);
            ub_o_2 = str2num(app.edit_mount2oriup.Value);
            lb_o_2 = str2num(app.edit_mount2orilow.Value);
            
            r_3 = str2num(app.edit_mount3position.Value);
            ub_r_3 = str2num(app.edit_mount3locup.Value);
            lb_r_3 = str2num(app.edit_mount3loclow.Value);
            o_3 = str2num(app.edit_mount3orientation.Value);
            ub_o_3 = str2num(app.edit_mount3oriup.Value);
            lb_o_3 = str2num(app.edit_mount3orilow.Value);
            save('SavedResults/temp_inpp.mat',...
                'r_1','lb_r_1','ub_r_1','o_1','lb_o_1','ub_o_1',...
                'r_2','lb_r_2','ub_r_2','o_2','lb_o_2','ub_o_2',...
                'r_3','lb_r_3','ub_r_3','o_3','lb_o_3','ub_o_3');
            delete(app)
        end

        % Value changed function: sw_mount1loc
        function sw_mount1locValueChanged(app, event)
            value = app.sw_mount1loc.Value;
            if strcmp(value , 'Bounded') 
                app.edit_mount1locup.Enable = 'on';
                app.edit_mount1loclow.Enable = 'on';
                app.edit_mount1locup.Value = app.edit_mount1position.Value
                app.edit_mount1loclow.Value = app.edit_mount1position.Value
            else
                app.edit_mount1locup.Enable = 'off';
                app.edit_mount1loclow.Enable = 'off';
                app.edit_mount1locup.Value = 'Fixed';
                app.edit_mount1loclow.Value = 'Fixed';
                
            end
        end

        % Value changed function: sw_mount1ori
        function sw_mount1oriValueChanged(app, event)
            value = app.sw_mount1ori.Value;
            if strcmp(value , 'Bounded') 
                app.edit_mount1oriup.Enable = 'on';
                app.edit_mount1orilow.Enable = 'on';
                app.edit_mount1oriup.Value = app.edit_mount1position.Value
                app.edit_mount1orilow.Value = app.edit_mount1position.Value
            else
                app.edit_mount1oriup.Enable = 'off';
                app.edit_mount1orilow.Enable = 'off';
                app.edit_mount1oriup.Value = 'Fixed';
                app.edit_mount1orilow.Value = 'Fixed';
                
            end
        end

        % Value changed function: sw_mount2loc
        function sw_mount2locValueChanged(app, event)
            value = app.sw_mount2loc.Value;
            if strcmp(value , 'Bounded') 
                app.edit_mount2locup.Enable = 'on';
                app.edit_mount2loclow.Enable = 'on';
                app.edit_mount2locup.Value = app.edit_mount1position.Value
                app.edit_mount2loclow.Value = app.edit_mount1position.Value
            else
                app.edit_mount2locup.Enable = 'off';
                app.edit_mount2loclow.Enable = 'off';
                app.edit_mount2locup.Value = 'Fixed';
                app.edit_mount2loclow.Value = 'Fixed';
                
            end
        end

        % Value changed function: sw_mount2ori
        function sw_mount2oriValueChanged(app, event)
            value = app.sw_mount2ori.Value;
            if strcmp(value , 'Bounded') 
                app.edit_mount2oriup.Enable = 'on';
                app.edit_mount2orilow.Enable = 'on';
                app.edit_mount2oriup.Value = app.edit_mount1position.Value
                app.edit_mount2orilow.Value = app.edit_mount1position.Value
            else
                app.edit_mount2oriup.Enable = 'off';
                app.edit_mount2orilow.Enable = 'off';
                app.edit_mount2oriup.Value = 'Fixed';
                app.edit_mount2orilow.Value = 'Fixed';
                
            end
        end

        % Value changed function: sw_mount3loc
        function sw_mount3locValueChanged(app, event)
            value = app.sw_mount3loc.Value;
            if strcmp(value , 'Bounded') 
                app.edit_mount3locup.Enable = 'on';
                app.edit_mount3loclow.Enable = 'on';
                app.edit_mount3locup.Value = app.edit_mount1position.Value
                app.edit_mount3loclow.Value = app.edit_mount1position.Value
            else
                app.edit_mount3locup.Enable = 'off';
                app.edit_mount3loclow.Enable = 'off';
                app.edit_mount3locup.Value = 'Fixed';
                app.edit_mount3loclow.Value = 'Fixed';
                
            end
        end

        % Value changed function: sw_mount3ori
        function sw_mount3oriValueChanged(app, event)
            value = app.sw_mount3ori.Value;
            if strcmp(value , 'Bounded') 
                app.edit_mount3oriup.Enable = 'on';
                app.edit_mount3orilow.Enable = 'on';
                app.edit_mount3oriup.Value = app.edit_mount1position.Value
                app.edit_mount3orilow.Value = app.edit_mount1position.Value
            else
                app.edit_mount3oriup.Enable = 'off';
                app.edit_mount3orilow.Enable = 'off';
                app.edit_mount3oriup.Value = 'Fixed';
                app.edit_mount3orilow.Value = 'Fixed';
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.panel_mountingsystem = uipanel(app.UIFigure);
            app.panel_mountingsystem.Title = 'Mounting System';
            app.panel_con_orientation = uipanel(app.panel_mountingsystem);
            app.panel_con_orientation.Title = 'Orientation';
            app.sw_mount1ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount1ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount1ori.ValueChangedFcn = createCallbackFcn(app, @sw_mount1oriValueChanged, true);
            app.sw_mount1ori.Value = 'Bounded';
            app.Mount1Label = uilabel(app.panel_con_orientation);
            app.Mount1Label.Text = 'Mount 1';
            app.edit_mount1oriupLabel = uilabel(app.panel_con_orientation);
            app.edit_mount1oriupLabel.Text = 'Upper Bound:';
            app.edit_mount1oriup = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount1oriup.Value = '[90, 90, 90]';
            app.edit_mount1orilowLabel = uilabel(app.panel_con_orientation);
            app.edit_mount1orilowLabel.Text = 'Lower Bound:';
            app.edit_mount1orilow = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount1orilow.Value = '[0, 0, 0]';
            app.edit_mount1orientationLabel = uilabel(app.panel_con_orientation);
            app.edit_mount1orientationLabel.Text = 'Fixed Orientation:';
            app.edit_mount1orientation = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount1orientation.Value = '[0, 0, 0]';
            app.Mount2Label = uilabel(app.panel_con_orientation);
            app.Mount2Label.Text = 'Mount 2';
            app.edit_mount2oriupLabel = uilabel(app.panel_con_orientation);
            app.edit_mount2oriupLabel.Text = 'Upper Bound:';
            app.edit_mount2oriup = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount2oriup.Value = '[90, 90, 90]';
            app.edit_mount2orilowLabel = uilabel(app.panel_con_orientation);
            app.edit_mount2orilowLabel.Text = 'Lower Bound:';
            app.edit_mount2orilow = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount2orilow.Value = '[0, 0, 0]';
            app.sw_mount2ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount2ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount2ori.ValueChangedFcn = createCallbackFcn(app, @sw_mount2oriValueChanged, true);
            app.sw_mount2ori.Value = 'Bounded';
            app.edit_mount2orientationLabel = uilabel(app.panel_con_orientation);
            app.edit_mount2orientationLabel.Text = 'Fixed Orientation:';
            app.edit_mount2orientation = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount2orientation.Value = '[0, 0, 0]';
            app.Mount3Label = uilabel(app.panel_con_orientation);
            app.Mount3Label.Text = 'Mount 3';
            app.edit_mount3oriupLabel = uilabel(app.panel_con_orientation);
            app.edit_mount3oriupLabel.Text = 'Upper Bound:';
            app.edit_mount3oriup = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount3oriup.Value = '[90, 90, 90]';
            app.edit_mount3orilowLabel = uilabel(app.panel_con_orientation);
            app.edit_mount3orilowLabel.Text = 'Lower Bound:';
            app.edit_mount3orilow = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount3orilow.Value = '[0, 0, 0]';
            app.sw_mount3ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount3ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount3ori.ValueChangedFcn = createCallbackFcn(app, @sw_mount3oriValueChanged, true);
            app.sw_mount3ori.Value = 'Bounded';
            app.edit_mount3orientationLabel = uilabel(app.panel_con_orientation);
            app.edit_mount3orientationLabel.Text = 'Fixed Orientation:';
            app.edit_mount3orientation = uieditfield(app.panel_con_orientation, 'text');
            app.edit_mount3orientation.Value = '[0, 0, 0]';
            app.panel_con_location = uipanel(app.panel_mountingsystem);
            app.panel_con_location.Title = 'Location';
            app.sw_mount1loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount1loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount1loc.ValueChangedFcn = createCallbackFcn(app, @sw_mount1locValueChanged, true);
            app.sw_mount1loc.Value = 'Bounded';
            app.Mount1Label_2 = uilabel(app.panel_con_location);
            app.Mount1Label_2.Text = 'Mount 1';
            app.edit_mount1locupLabel = uilabel(app.panel_con_location);
            app.edit_mount1locupLabel.Text = 'Upper Bound:';
            app.edit_mount1locup = uieditfield(app.panel_con_location, 'text');
            app.edit_mount1locup.Value = '[200, 380, 215]';
            app.edit_mount1loclowLabel = uilabel(app.panel_con_location);
            app.edit_mount1loclowLabel.Text = 'Lower Bound:';
            app.edit_mount1loclow = uieditfield(app.panel_con_location, 'text');
            app.edit_mount1loclow.Value = '[-250, -400, -50]';
            app.SymValforxCheckBox = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox.Visible = 'off';
            app.SymValforxCheckBox.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox.Visible = 'off';
            app.SymValforyCheckBox.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox.Visible = 'off';
            app.SymValforzCheckBox.Text = 'Sym. Val. for z';
            app.edit_mount1positionLabel = uilabel(app.panel_con_location);
            app.edit_mount1positionLabel.Text = 'Fixed Position:';
            app.edit_mount1position = uieditfield(app.panel_con_location, 'text');
            app.edit_mount1position.Value = '[13.5, -389, 203]';
            app.Mount2Label_2 = uilabel(app.panel_con_location);
            app.Mount2Label_2.Text = 'Mount 2';
            app.edit_mount2locupLabel = uilabel(app.panel_con_location);
            app.edit_mount2locupLabel.Text = 'Upper Bound:';
            app.edit_mount2locup = uieditfield(app.panel_con_location, 'text');
            app.edit_mount2locup.Value = '[-415, 390, -170]';
            app.edit_mount2loclowLabel = uilabel(app.panel_con_location);
            app.edit_mount2loclowLabel.Text = 'Lower Bound:';
            app.edit_mount2loclow = uieditfield(app.panel_con_location, 'text');
            app.edit_mount2loclow.Value = '[-430, -210, -180]';
            app.sw_mount2loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount2loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount2loc.ValueChangedFcn = createCallbackFcn(app, @sw_mount2locValueChanged, true);
            app.sw_mount2loc.Value = 'Bounded';
            app.SymValforxCheckBox_2 = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox_2.Visible = 'off';
            app.SymValforxCheckBox_2.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox_2 = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox_2.Visible = 'off';
            app.SymValforyCheckBox_2.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox_2 = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox_2.Visible = 'off';
            app.SymValforzCheckBox_2.Text = 'Sym. Val. for z';
            app.edit_mount2positionLabel = uilabel(app.panel_con_location);
            app.edit_mount2positionLabel.Text = 'Fixed Position:';
            app.edit_mount2position = uieditfield(app.panel_con_location, 'text');
            app.edit_mount2position.Value = '[-424, 92, -175]';
            app.Mount3Label_2 = uilabel(app.panel_con_location);
            app.Mount3Label_2.Text = 'Mount 3';
            app.edit_mount3locupLabel = uilabel(app.panel_con_location);
            app.edit_mount3locupLabel.Text = 'Upper Bound:';
            app.edit_mount3locup = uieditfield(app.panel_con_location, 'text');
            app.edit_mount3locup.Value = '[190, 460, 210]';
            app.edit_mount3loclowLabel = uilabel(app.panel_con_location);
            app.edit_mount3loclowLabel.Text = 'Lower Bound:';
            app.edit_mount3loclow = uieditfield(app.panel_con_location, 'text');
            app.edit_mount3loclow.Value = '[-250, 450, -70]';
            app.sw_mount3loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount3loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount3loc.ValueChangedFcn = createCallbackFcn(app, @sw_mount3locValueChanged, true);
            app.sw_mount3loc.Value = 'Bounded';
            app.SymValforxCheckBox_3 = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox_3.Visible = 'off';
            app.SymValforxCheckBox_3.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox_3 = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox_3.Visible = 'off';
            app.SymValforyCheckBox_3.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox_3 = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox_3.Visible = 'off';
            app.SymValforzCheckBox_3.Text = 'Sym. Val. for z';
            app.edit_mount3positionLabel = uilabel(app.panel_con_location);
            app.edit_mount3positionLabel.Text = 'Fixed Position:';
            app.edit_mount3position = uieditfield(app.panel_con_location, 'text');
            app.edit_mount3position.Value = '[182, 454, -68]';
            app.Label15 = uilabel(app.panel_mountingsystem);
            app.Label15.Text = 'deg';
            app.Label14 = uilabel(app.panel_mountingsystem);
            app.Label14.Text = 'mm';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_loc_orie

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end