classdef gui_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EngineMountOptimizationToolboxUIFigure  matlab.ui.Figure
        EngineMountOptimizationToolboxLabel  matlab.ui.control.Label
        byNVHteamLabel                  matlab.ui.control.Label
        SharifUniversityofTechnologyLabel  matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        ViewResultsButton               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SingleModeTab                   matlab.ui.container.Tab
        OptimizationProcessButtonGroup  matlab.ui.container.ButtonGroup
        Button_5                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        ChangeStageButton               matlab.ui.control.Button
        DefinedStagesListBoxLabel       matlab.ui.control.Label
        DefinedStagesListBox            matlab.ui.control.ListBox
        Stage1Button                    matlab.ui.control.RadioButton
        Stage2Button                    matlab.ui.control.RadioButton
        Stage3Button                    matlab.ui.control.RadioButton
        OptTable                        matlab.ui.control.Table
        colListBox                      matlab.ui.control.ListBox
        FileEditFieldLabel_2            matlab.ui.control.Label
        DescriptionOfStageLabel         matlab.ui.control.Label
        DescriptionOfStageTextArea      matlab.ui.control.TextArea
        STARTButton                     matlab.ui.control.Button
        EngineParametersPanel           matlab.ui.container.Panel
        EditButton_2                    matlab.ui.control.Button
        CreateButton                    matlab.ui.control.Button
        FileEditFieldLabel              matlab.ui.control.Label
        FileEditField                   matlab.ui.control.EditField
        OpenButton                      matlab.ui.control.Button
        FilePanel                       matlab.ui.container.Panel
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        DescriptionOfFileTextAreaLabel  matlab.ui.control.Label
        DescriptionOfFileTextArea       matlab.ui.control.TextArea
        PathEditField                   matlab.ui.control.EditField
        PathEditFieldLabel              matlab.ui.control.Label
        Button_7                        matlab.ui.control.Button
        BatchModeTab                    matlab.ui.container.Tab
        Button_6                        matlab.ui.control.Button
        BatchRunButton                  matlab.ui.control.Button
        WriteToExcelButton              matlab.ui.control.Button
        FromEditFieldLabel              matlab.ui.control.Label
        FromEditField                   matlab.ui.control.NumericEditField
        ToEditFieldLabel                matlab.ui.control.Label
        ToEditField                     matlab.ui.control.NumericEditField
        ExcelFileEditFieldLabel         matlab.ui.control.Label
        ExcelFileEditField              matlab.ui.control.EditField
        PreProcessButton                matlab.ui.control.Button
    end


    properties (Access = public)
        
        num_run=1;
        OPtMat=0
        data
        input_FileName
        input_PathName
        excel_FileName
        excel_PathName
        folder_name
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           clc;
           close all;
           
            RefreshButtonPushed(app)
            load(['SavedResults/','gui_def.mat']);
            gui_curr = gui_def;
            
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
            RefreshButtonPushed(app)
            
            app.OPtMat = {'run_1','11','12','13'};
            app.OptTable.Data = app.OPtMat;
            
        end

        % Button pushed function: BatchRunButton
        function BatchRunButtonPushed(app, event)
            
            if strcmp(app.ExcelFileEditField.Value,'**NotSelected**')
                errordlg('Please select a file!');
                return;
            end
            uiwait(errordlg('please close all excel files from Task Manager! Engine input file must be located in SavedResults\ Folder.'));
            excel_file = [app.excel_PathName,app.excel_FileName];
            
            num_1 = app.FromEditField.Value;
            num_2 = app.ToEditField.Value;
            [num,txt,raw]  = xlsread(excel_file,1,['C',char(string(7+num_1)),':AH',char(string(7+num_2))]);
            
            
            for i=1:num_2-num_1+1
                input_name = [ char(raw(i,3)),'_inp.mat'];
                input_path = 'SavedResults/'
                load([input_path,input_name]);
                g = eval(input_name(1:end-4));
                g.eng.rpm = cell2mat(raw(i,4));
                g.eng.torque = cell2mat(raw(i,5));
                eval([input_name(1:end-4), '= g']);
                save([input_path,input_name],input_name(1:end-4));
                
                stage_name = char(raw(i,32));
                load(['SavedResults/stg_',stage_name,'.mat']);
                h = eval(stage_name);
                h.lb_purt.loc1 = -(str2num(char(raw(i,6))))./(g.mount.r_1-g.mount.lb_r_1)*100;
                h.lb_purt.loc2 = -(str2num(char(raw(i,8))))./(g.mount.r_2-g.mount.lb_r_2)*100;
                h.lb_purt.loc3 = -(str2num(char(raw(i,10))))./(g.mount.r_3-g.mount.lb_r_3)*100;
                h.ub_purt.loc1 = (str2num(char(raw(i,7))))./(g.mount.ub_r_1-g.mount.r_1)*100;
                h.ub_purt.loc2 = (str2num(char(raw(i,9))))./(g.mount.ub_r_2-g.mount.r_2)*100;
                h.ub_purt.loc3 = (str2num(char(raw(i,11))))./(g.mount.ub_r_3-g.mount.r_3)*100;
                h.lb_purt.ori1 = -(str2num(char(raw(i,12))))./(g.mount.o_1-g.mount.lb_o_1)*100;
                h.lb_purt.ori2 = -(str2num(char(raw(i,14))))./(g.mount.o_2-g.mount.lb_o_2)*100;
                h.lb_purt.ori3 = -(str2num(char(raw(i,16))))./(g.mount.o_3-g.mount.lb_o_3)*100;
                h.ub_purt.ori1 = (str2num(char(raw(i,13))))./(g.mount.ub_o_1-g.mount.o_1)*100;
                h.ub_purt.ori2 = (str2num(char(raw(i,15))))./(g.mount.ub_o_2-g.mount.o_2)*100;
                h.ub_purt.ori3 = (str2num(char(raw(i,17))))./(g.mount.ub_o_3-g.mount.o_3)*100;
                
                
                h.lb_purt.stiff1 = str2num(char(raw(i,18)));
                h.lb_purt.stiff2 = str2num(char(raw(i,20)));
                h.lb_purt.stiff3 = str2num(char(raw(i,22)));
                h.ub_purt.stiff1 = str2num(char(raw(i,19)));
                h.ub_purt.stiff2 = str2num(char(raw(i,21)));
                h.ub_purt.stiff3 = str2num(char(raw(i,23)));
                h.lb_purt.damp1 = str2num(char(raw(i,24)));
                h.lb_purt.damp2 = str2num(char(raw(i,26)));
                h.lb_purt.damp3 = str2num(char(raw(i,28)));
                h.ub_purt.damp1 = str2num(char(raw(i,25)));
                h.ub_purt.damp2 = str2num(char(raw(i,27)));
                h.ub_purt.damp3 = str2num(char(raw(i,29)));
            %                 raw(i,28)
            %                 aq=str2double(char(raw(i,28)))
            %                 term
                eval([stage_name, '= h']);
                save(['SavedResults/stg_',stage_name,'.mat'],stage_name);
                                
                
                file_name = char(raw(i,2));
                desc = char(raw(i,31));
                opt_matrix = {file_name, stage_name,'12','13'};
                
                    gui_Run_Me2(input_name,input_path,opt_matrix,file_name,desc,'SavedResults');
                    
                    
            end
                     
            msgbox('Optimization Done.result (*_res.mat) file saved in ''SavedResults\''.Use write to save the results in excel file');
        end

        % Callback function
        function Button_3Pushed(app, event)
            app.OptTable.Data = app.OptTable.Data(1:end-1,:);
            app.num_run = app.num_run-1;
        end

        % Callback function
        function Button_4Pushed(app, event)
            app.num_run = app.num_run + 1;
            n = app.num_run;
            data = app.OptTable.Data;
            data = [data;{['run_',num2str(n)],[num2str(n),'1'],[num2str(n),'2'],[num2str(n),'3']}]
            app.OptTable.Data = data;
        end

        % Callback function
        function Button_5Pushed(app, event)
            value = app.DefinedStagesListBox.Value;
            if isempty(value)
                return;
            end
            data = app.OptTable.Data;
            
            row = str2num(app.colListBox.Value);
            col = app.Stage1Button.Value + app.Stage2Button.Value*2 + app.Stage3Button.Value*3;
            
            data(row,1+col) = {value};
            app.OptTable.Data = data;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            [app.excel_FileName,app.excel_PathName,FilterIndex] = uigetfile('*.xlsx');
            app.ExcelFileEditField.Value = app.excel_FileName;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            
            app.folder_name = uigetdir(cd,'Select a folder to save');
            app.PathEditField.Value = app.folder_name;
        end

        % Callback function
        function ChooseFileDropDownValueChanged(app, event)
            %             value_inp = app.ChooseFileDropDown.Value;
            %             load(['SavedResults/','gui_curr.mat']);
            %             gui_curr.input_name = [value_inp,'_inp'];
            %             save(['SavedResults/','gui_curr.mat'],'gui_curr');
        end

        % Callback function
        function ChooseFileDropDown_2ValueChanged(app, event)
            %             value_inp = app.ChooseFileDropDown_2.Value;
            %             load(['SavedResults/','gui_curr.mat']);
            %             gui_curr.opt_name = value_inp;
            %             save(['SavedResults/','gui_curr.mat'],'gui_curr');
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            
            %             load(['SavedResults/','gui_curr.mat']);
                        gui_curr.input_PathName = 'SavedResults/';
                        gui_curr.input_FileName = 'user_new_inp.mat';
                        
                        save(['SavedResults/','gui_curr.mat'],'gui_curr');
            
            
                gui_input_parameters;
            
        end

        % Callback function
        function CreateButton_2Pushed(app, event)
            %             load(['SavedResults/','gui_curr.mat']);
            %             load(['SavedResults/','user_new_opt.mat']);
            %             
            %             %             gui_curr.opt_name = [app.NewEditField_2.Value,'_opt.mat'];
            %             gui_curr.opt_name = 'user_new_opt.mat';
            %             eval([gui_curr.opt_name(1:end-4),'=user_new_opt;']);
            %             
            %             save(['SavedResults/','gui_curr.mat'],'gui_curr');
            %             
            %             %             save(['SavedResults/',gui_curr.opt_name],gui_curr.opt_name(1:end-4));
            %             if strcmp(gui_curr.input_name,'not selected')==0           
            %             gui_optimization;
            %             end
        end

        % Callback function
        function DefineButtonPushed(app, event)
            %             load(['SavedResults/','gui_curr.mat']);
            %             value = app.DefinedStagesListBox.Value;
            %             if isempty(value)
            %                return 
            %             end
            %             gui_curr.stage_name = value;
            %              save('SavedResults/gui_curr.mat','gui_curr');
            %              gui_optimization;
        end

        % Callback function
        function DefinedStagesListBoxValueChanged(app, event)
            value = app.DefinedStagesListBox.Value;
            load (['SavedResults/stg_',value,'.mat']);
            h_stage = eval(value);
            app.DescriptionOfStageTextArea.Value = h_stage.description;
            
        end

        % Value changed function: DescriptionOfStageTextArea
        function DescriptionOfStageTextAreaValueChanged(app, event)
            value = app.DescriptionOfStageTextArea.Value;
            
        end

        % Callback function
        function EditButton_3Pushed(app, event)
            %             load(['SavedResults/','gui_curr.mat']);
            %             gui_curr.opt_name = app.ChooseFileDropDown_2.Value;
            %             save(['SavedResults/','gui_curr.mat'],'gui_curr');
            %             strcmp(gui_curr.opt_name,'not selected')
            %             if strcmp(gui_curr.opt_name,'not selected')==0 && strcmp(gui_curr.input_name,'not selected')==0
            %                 gui_optimization;
            %             end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: EditButton_2
        function GoStep1ButtonPushed(app, event)
            if strcmp(app.FileEditField.Value,'**NotSelected**')
                erroedlg('Please select a file!');
                return;
            end
            s = app.input_PathName
            s=app.input_FileName
            load([app.input_PathName,app.input_FileName]);
                        gui_curr.input_PathName = app.input_PathName;
                        gui_curr.input_FileName = app.input_FileName;
                        
                        save(['SavedResults/','gui_curr.mat'],'gui_curr');
            
            
                gui_input_parameters;
            
        end

        % Callback function
        function GoStep2ButtonPushed(app, event)
            gui_optimization;
        end

        % Button pushed function: ViewResultsButton
        function GoStep3ButtonPushed(app, event)
            %             load(['SavedResults/','gui_curr.mat']);
            %             value = app.ChooseFileDropDown_3.Value;
            
                gui_results;
            
            
        end

        % Callback function
        function LoadButton_2Pushed(app, event)
            gui_optinput
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [app.input_FileName,app.input_PathName,input_FilterIndex] = uigetfile('*_inp.mat');
            app.FileEditField.Value = app.input_FileName;
        end

        % Button pushed function: PreProcessButton
        function PreProcessButtonPushed(app, event)
            gui_pre;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            %             load(['SavedResults/','gui_curr.mat']);
            
            
            %             mat_files=dir('SavedResults/*_inp.mat'); %finding .mat files
            %             k=size(mat_files);
            %             for i=1:k(1)
            %                 name = mat_files(i).name;
            %                 app.ChooseFileDropDown.Items(i+1)={name(1:end-8)};
            %                 
            %             end
            %             app.ChooseFileDropDown.Value=app.ChooseFileDropDown.Items(1);
            
            %             mat_files=dir('SavedResults/*_opt.mat'); %finding .mat files
            %             k=size(mat_files);
            %             for i=1:k(1)
            %             
            %                 app.ChooseFileDropDown_2.Items(i+1)={mat_files(i).name};
            %                 
            %             end
            %             app.ChooseFileDropDown_2.Value=app.ChooseFileDropDown_2.Items(1);
            
            
            mat_files=dir('SavedResults/stg_*.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.DefinedStagesListBox.Items(i)={mat_files(i).name(5:end-4)};
            %                 app.DefinedStagesListBox.ItemsData(i)
            %                 y=app.DefinedStagesListBox.Items(i)
            end
            %             app.ChooseFileDropDown_2.Value=app.ChooseFileDropDown_2.Items(1);
            
        end

        % Callback function
        function STARTButtonPushed(app, event)
            if strcmp(app.FileEditField.Value , '**NotSelected**')==1
               uiwait(errordlg('please enter input data','error_input_file')); 
               return;
            end
            if isvarname(app.FileNameEditField.Value) ==0
               uiwait(errordlg('please enter valid file name','error_input_file')); 
               return;
            end
            if strcmp(app.PathEditField.Value,'**NotSelected**')
               uiwait(errordlg('please enter valid path','error_path')); 
               return;
            end
            for i=1:app.num_run
               if isvarname(char(app.OptTable.Data(i,1)))==0
                   uiwait(errordlg(['run ',num2str(i),' name is not valid'],'error_variable name'));
                   return;
               end
            end
            app.data = app.OptTable.Data;
            %             gui_Run_Me
            
            opt_matrix = app.OptTable.Data;
            desc = app.DescriptionOfFileTextArea.Value;
            file_name = app.FileNameEditField.Value;
            load ('SavedResults/val.mat');
            if val_now==val_up
                 msgbox(['you use this software for ',num2str(val_up),' times. It is UNLICENSED now!. please contact us']);
                 return;
            end
            gui_Run_Me2(app.input_FileName,app.input_PathName,opt_matrix,file_name,desc,app.folder_name);
            val_now = val_now+1
            save('SavedResults/val.mat','val_now','val_up');
            msgbox('Optimization Done. Results Saved.');
        end

        % Callback function
        function Step1EditButton_2Pushed(app, event)
            gui_input_parameters
        end

        % Button pushed function: WriteToExcelButton
        function WriteToExcelButtonPushed(app, event)
            if strcmp(app.ExcelFileEditField.Value,'**NotSelected**')
                errordlg('Please select a file!');
                return;
            end
            uiwait(errordlg('please close the excel before run!'));
            excel_file = [app.excel_PathName,app.excel_FileName];
            
            %             num_1 = xlsread(excel_file,1,'B15');
            %             num_2 = xlsread(excel_file,1,'B16');
            num_1 = app.FromEditField.Value;
            num_2 = app.ToEditField.Value;
            [num,txt,raw]  = xlsread(excel_file,1,['C',char(string(7+num_1)),':AH',char(string(7+num_2))]);
            %save('xp');
            
            print = zeros(num_2-num_1+1,24);
            xlswrite(excel_file,["NF1","NF2","NF3","NF4","NF5","NF6","KED1","KED2","KED3","KED4","KED5","KED6","pur_TRA","pur_TF","pur_TA","pur_Ar","pur_NF","pur_KED"],1,'AP7');
            for i=num_1:num_2
                    
                    file_name = char(raw(i-num_1+1,2));
                    if (exist([file_name,'_res.mat'])~=2)
            %                         print=[print;zeros(size(print,2))];
                            print(i,:)=zeros(1,24);               
                             
                            continue;
                    end
            %                     disp('file number:')
                    i
                    load(['SavedResults/',file_name,'_res.mat']);
                    k = eval([file_name,'_res']);
                    s = k.run.stage.results; 
            %                     if (i==num_1)
            %                         print(1,:)=[s.val_TRA,s.val_TF,s.val_TA,s.val_Ar,s.val_NF,s.val_KED, s.Freq',max(s.KED)];
            %                         continue;
            %                     end
                    [per_TRA, per_TF, per_TA, per_Ar, per_NF, per_KED]=purturb(k.run);
                    print(i,:) = [s.val_TRA,s.val_TF,s.val_TA,s.val_Ar,s.val_NF,s.val_KED, s.Freq',max(s.KED),per_TRA, per_TF, per_TA, per_Ar, per_NF, per_KED];
            %                     print=[print;current];
                    clear [file_name,'_res'];
            end
            xlswrite(excel_file,print,1,['AJ',char(string(8))]);
            msgbox('Optimization Done. Results Exported to Excel file.');
        
        end

        % Callback function
        function openlogfileButtonPushed(app, event)
            winopen('log.txt');
        end

        % Callback function
        function step1LoadButtonPushed(app, event)
            
            gui_loadinput;
            %             disp(app.item_input)
            %             app.NameEditField.Value=app.item_input;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EngineMountOptimizationToolboxUIFigure = uifigure;
            app.EngineMountOptimizationToolboxUIFigure.Name = 'Engine Mount Optimization Toolbox';
            app.EngineMountOptimizationToolboxLabel = uilabel(app.EngineMountOptimizationToolboxUIFigure);
            app.EngineMountOptimizationToolboxLabel.Text = 'Engine Mount Optimization Toolbox';
            app.byNVHteamLabel = uilabel(app.EngineMountOptimizationToolboxUIFigure);
            app.byNVHteamLabel.Text = 'by NVH team';
            app.SharifUniversityofTechnologyLabel = uilabel(app.EngineMountOptimizationToolboxUIFigure);
            app.SharifUniversityofTechnologyLabel.Text = 'Sharif University of Technology';
            app.Button = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.Button.Icon = 'SHARIFlogo.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.Button_2.Icon = 'SAIPAlogo.png';
            app.Button_2.Text = '';
            app.RefreshButton = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = 'refresh.png';
            app.RefreshButton.Text = 'Refresh';
            app.ExitButton = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = 'close.png';
            app.ExitButton.Text = 'Exit';
            app.ViewResultsButton = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.ViewResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GoStep3ButtonPushed, true);
            app.ViewResultsButton.Icon = 'Results_icon.png';
            app.ViewResultsButton.Text = 'View Results';
            app.TabGroup = uitabgroup(app.EngineMountOptimizationToolboxUIFigure);
            app.SingleModeTab = uitab(app.TabGroup);
            app.SingleModeTab.Title = 'Single Mode';
            app.OptimizationProcessButtonGroup = uibuttongroup(app.SingleModeTab);
            app.OptimizationProcessButtonGroup.Title = 'Optimization Process';
            app.Button_5 = uibutton(app.OptimizationProcessButtonGroup, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'add.png';
            app.Button_5.Text = '';
            app.Button_3 = uibutton(app.OptimizationProcessButtonGroup, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'minus.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.OptimizationProcessButtonGroup, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'plus.png';
            app.Button_4.Text = '';
            app.ChangeStageButton = uibutton(app.OptimizationProcessButtonGroup, 'push');
            app.ChangeStageButton.ButtonPushedFcn = createCallbackFcn(app, @DefineButtonPushed, true);
            app.ChangeStageButton.Text = 'Change Stage';
            app.DefinedStagesListBoxLabel = uilabel(app.OptimizationProcessButtonGroup);
            app.DefinedStagesListBoxLabel.Text = 'Defined Stages';
            app.DefinedStagesListBox = uilistbox(app.OptimizationProcessButtonGroup);
            app.DefinedStagesListBox.Items = {};
            app.DefinedStagesListBox.ValueChangedFcn = createCallbackFcn(app, @DefinedStagesListBoxValueChanged, true);
            app.DefinedStagesListBox.Value = {};
            app.Stage1Button = uiradiobutton(app.OptimizationProcessButtonGroup);
            app.Stage1Button.Text = 'Stage 1';
            app.Stage1Button.Value = true;
            app.Stage2Button = uiradiobutton(app.OptimizationProcessButtonGroup);
            app.Stage2Button.Text = 'Stage 2';
            app.Stage3Button = uiradiobutton(app.OptimizationProcessButtonGroup);
            app.Stage3Button.Text = 'Stage 3';
            app.OptTable = uitable(app.OptimizationProcessButtonGroup);
            app.OptTable.ColumnName = {'Run Name'; ''; ''; ''};
            app.OptTable.RowName = {};
            app.OptTable.ColumnEditable = [true false false false];
            app.colListBox = uilistbox(app.OptimizationProcessButtonGroup);
            app.colListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.colListBox.Value = '1';
            app.FileEditFieldLabel_2 = uilabel(app.OptimizationProcessButtonGroup);
            app.FileEditFieldLabel_2.Text = 'Add';
            app.DescriptionOfStageLabel = uilabel(app.OptimizationProcessButtonGroup);
            app.DescriptionOfStageLabel.Text = 'Description Of Stage';
            app.DescriptionOfStageTextArea = uitextarea(app.OptimizationProcessButtonGroup);
            app.DescriptionOfStageTextArea.ValueChangedFcn = createCallbackFcn(app, @DescriptionOfStageTextAreaValueChanged, true);
            app.STARTButton = uibutton(app.OptimizationProcessButtonGroup, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Icon = 'start.png';
            app.STARTButton.Text = 'START';
            app.EngineParametersPanel = uipanel(app.SingleModeTab);
            app.EngineParametersPanel.Title = 'Engine Parameters';
            app.EditButton_2 = uibutton(app.EngineParametersPanel, 'push');
            app.EditButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoStep1ButtonPushed, true);
            app.EditButton_2.Text = 'Edit';
            app.CreateButton = uibutton(app.EngineParametersPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.FileEditFieldLabel = uilabel(app.EngineParametersPanel);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.EngineParametersPanel, 'text');
            app.FileEditField.Editable = 'off';
            app.FileEditField.Value = '**NotSelected**';
            app.OpenButton = uibutton(app.EngineParametersPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.FilePanel = uipanel(app.SingleModeTab);
            app.FilePanel.Title = 'File';
            app.FileNameEditFieldLabel = uilabel(app.FilePanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.FilePanel, 'text');
            app.DescriptionOfFileTextAreaLabel = uilabel(app.FilePanel);
            app.DescriptionOfFileTextAreaLabel.Text = {'Description'; ' Of File'};
            app.DescriptionOfFileTextArea = uitextarea(app.FilePanel);
            app.PathEditField = uieditfield(app.FilePanel, 'text');
            app.PathEditField.Editable = 'off';
            app.PathEditField.Value = '**NotSelected**';
            app.PathEditFieldLabel = uilabel(app.FilePanel);
            app.PathEditFieldLabel.Text = 'Path';
            app.Button_7 = uibutton(app.FilePanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '...';
            app.BatchModeTab = uitab(app.TabGroup);
            app.BatchModeTab.Title = 'Batch Mode';
            app.Button_6 = uibutton(app.BatchModeTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'Excel_icon.png';
            app.Button_6.Text = '';
            app.BatchRunButton = uibutton(app.BatchModeTab, 'push');
            app.BatchRunButton.ButtonPushedFcn = createCallbackFcn(app, @BatchRunButtonPushed, true);
            app.BatchRunButton.Icon = 'start.png';
            app.BatchRunButton.Text = 'Batch Run';
            app.WriteToExcelButton = uibutton(app.BatchModeTab, 'push');
            app.WriteToExcelButton.ButtonPushedFcn = createCallbackFcn(app, @WriteToExcelButtonPushed, true);
            app.WriteToExcelButton.Text = 'Write To Excel';
            app.FromEditFieldLabel = uilabel(app.BatchModeTab);
            app.FromEditFieldLabel.Text = 'ّFrom';
            app.FromEditField = uieditfield(app.BatchModeTab, 'numeric');
            app.FromEditField.Value = 1;
            app.ToEditFieldLabel = uilabel(app.BatchModeTab);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.BatchModeTab, 'numeric');
            app.ToEditField.Value = 40;
            app.ExcelFileEditFieldLabel = uilabel(app.BatchModeTab);
            app.ExcelFileEditFieldLabel.Text = 'Excel File';
            app.ExcelFileEditField = uieditfield(app.BatchModeTab, 'text');
            app.ExcelFileEditField.Editable = 'off';
            app.ExcelFileEditField.Value = '**NotSelected**';
            app.PreProcessButton = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.PreProcessButton.ButtonPushedFcn = createCallbackFcn(app, @PreProcessButtonPushed, true);
            app.PreProcessButton.Text = 'Pre Process';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EngineMountOptimizationToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EngineMountOptimizationToolboxUIFigure)
        end
    end
end