classdef gui_main2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EngineMountOptimizationToolboxUIFigure  matlab.ui.Figure
        EngineMountOptimizationToolboxLabel  matlab.ui.control.Label
        byNVHteamLabel                matlab.ui.control.Label
        SharifUniversityofTechnologyLabel  matlab.ui.control.Label
        Step1SetinputparametersPanel  matlab.ui.container.Panel
        EditButton_2                  matlab.ui.control.Button
        ChooseFileDropDownLabel       matlab.ui.control.Label
        ChooseFileDropDown            matlab.ui.control.DropDown
        CreateButton                  matlab.ui.control.Button
        Step3ViewResultsPanel         matlab.ui.container.Panel
        ViewResultsButton             matlab.ui.control.Button
        ChooseFileDropDown_3Label     matlab.ui.control.Label
        ChooseFileDropDown_3          matlab.ui.control.DropDown
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Step2OptimizationPanel        matlab.ui.container.Panel
        StagesListBox_2Label          matlab.ui.control.Label
        StagesListBox_2               matlab.ui.control.ListBox
        DefineButton                  matlab.ui.control.Button
        AddtoTableButton              matlab.ui.control.Button
        positionEditFieldLabel        matlab.ui.control.Label
        positionEditField             matlab.ui.control.NumericEditField
        OptTable                      matlab.ui.control.Table
        StartButton                   matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        openlogfileButton             matlab.ui.control.Button
    end


    properties (Access = public)
        
        app_main
        item_input = 'not selected' % Description
        OPtMat=0
        data
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             img = imread('splash.jpg');  %# A sample image to display
            %             jimg = im2java(img);
            %             frame = javax.swing.JFrame;
            %             frame.setUndecorated(true);
            %             icon = javax.swing.ImageIcon(jimg);
            %             label = javax.swing.JLabel(icon);
            %             frame.getContentPane.add(label);
            %             frame.pack;
            %             screenSize = get(0,'ScreenSize');  %# Get the screen size from the root object
            %             %             frame.setSize(screenSize(3)/2,screenSize(4)/2);
            %             frame.setLocation(screenSize(3)/2-300,screenSize(4)/2-200);
            %             frame.show;
            %             pause(3);
            %             frame.hide
            RefreshButtonPushed(app)
            load(['SavedResults/','gui_def.mat']);
            gui_curr = gui_def;
            
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
            RefreshButtonPushed(app)
            app.OPtMat
            app.OPtMat = {'run_1','11','12','13';...
                            'run_2','21','22','23';...
                            'run_3','31','32','33';...
                            'run_4','41','42','43';...
                            'run_5','51','52','53';...
                            'run_6','61','62','63'};
            app.OptTable.Data = app.OPtMat;
            
        end

        % Button pushed function: AddtoTableButton
        function AddtoTableButtonPushed(app, event)
            value = app.StagesListBox_2.Value
            if isempty(value)
                return
            end
            data = app.OptTable.Data
            pos = app.positionEditField.Value;
            
            data(floor(pos/10),mod(pos,10)+1) = {value};
            app.OptTable.Data = data;
        end

        % Value changed function: ChooseFileDropDown
        function ChooseFileDropDownValueChanged(app, event)
            value_inp = app.ChooseFileDropDown.Value;
            load(['SavedResults/','gui_curr.mat']);
            gui_curr.input_name = value_inp;
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
        end

        % Callback function
        function ChooseFileDropDown_2ValueChanged(app, event)
            value_inp = app.ChooseFileDropDown_2.Value;
            load(['SavedResults/','gui_curr.mat']);
            gui_curr.opt_name = value_inp;
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            load(['SavedResults/','user_new_inp.mat']);
            
            %             gui_curr.input_name = [app.NewEditField.Value,'_inp.mat'];
            gui_curr.input_name = 'user_new_inp.mat';             
            eval([gui_curr.input_name(1:end-4),'=user_new_inp;']);
            
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
            % %             save(['SavedResults/',gui_curr.input_name],gui_curr.input_name(1:end-4));
            
            
            gui_input_parameters;
        end

        % Callback function
        function CreateButton_2Pushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            load(['SavedResults/','user_new_opt.mat']);
            
            %             gui_curr.opt_name = [app.NewEditField_2.Value,'_opt.mat'];
            gui_curr.opt_name = 'user_new_opt.mat';
            eval([gui_curr.opt_name(1:end-4),'=user_new_opt;']);
            
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
            
            %             save(['SavedResults/',gui_curr.opt_name],gui_curr.opt_name(1:end-4));
            if strcmp(gui_curr.input_name,'not selected')==0           
            gui_optimization;
            end
        end

        % Button pushed function: DefineButton
        function DefineButtonPushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            value = app.StagesListBox_2.Value;
            if isempty(value)
               return 
            end
            gui_curr.stage_name = value;
             save('SavedResults/gui_curr.mat','gui_curr');
             gui_optimization2;
        end

        % Callback function
        function EditButton_3Pushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            gui_curr.opt_name = app.ChooseFileDropDown_2.Value;
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
            strcmp(gui_curr.opt_name,'not selected')
            if strcmp(gui_curr.opt_name,'not selected')==0 && strcmp(gui_curr.input_name,'not selected')==0
                gui_optimization;
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: EditButton_2
        function GoStep1ButtonPushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            gui_curr.input_name = app.ChooseFileDropDown.Value;
            save(['SavedResults/','gui_curr.mat'],'gui_curr');
            
            if strcmp(gui_curr.input_name,'not selected')==0
                gui_input_parameters2;
            end
        end

        % Callback function
        function GoStep2ButtonPushed(app, event)
            gui_optimization;
        end

        % Button pushed function: ViewResultsButton
        function GoStep3ButtonPushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            value = app.ChooseFileDropDown_3.Value;
            if strcmp(value,'not selected')==0
                gui_results2;
            end
            
        end

        % Callback function
        function LoadButton_2Pushed(app, event)
            gui_optinput
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            
            
            mat_files=dir('SavedResults/*.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.ChooseFileDropDown.Items(i+1)={mat_files(i).name};
                
            end
            app.ChooseFileDropDown.Value=app.ChooseFileDropDown.Items(1);
            
            %             mat_files=dir('SavedResults/*_opt.mat'); %finding .mat files
            %             k=size(mat_files);
            %             for i=1:k(1)
            %             
            %                 app.ChooseFileDropDown_2.Items(i+1)={mat_files(i).name};
            %                 
            %             end
            %             app.ChooseFileDropDown_2.Value=app.ChooseFileDropDown_2.Items(1);
            
            mat_files=dir('SavedResults/*_res.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.ChooseFileDropDown_3.Items(i+1)={mat_files(i).name};
                
            end
            app.ChooseFileDropDown_3.Value=app.ChooseFileDropDown_3.Items(1);
            
            mat_files=dir('SavedResults/stg_*.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.StagesListBox_2.Items(i)={mat_files(i).name(5:end-4)};
                
            end
            %             app.ChooseFileDropDown_2.Value=app.ChooseFileDropDown_2.Items(1);
            
        end

        % Callback function
        function STARTButtonPushed(app, event)
            
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %             prompt = {sprintf('Enter the result name to be saved :')};
            %             dlg_title = 'Result Name';
            %             num_lines = 1;
            %              load(['SavedResults/','gui_curr.mat']);
            %             defaultans = {'untitled'};
            %             answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
            %             gui_curr.res_name = [char(answer),'_res.mat'];
            %             
            %             save(['SavedResults/','gui_curr.mat'],'gui_curr');
            if strcmp(app.ChooseFileDropDown.Value , 'not selected')==1
               errordlg('please enter input data','error_input_file'); 
            end
            for i=1:6
               if isvarname(char(app.OptTable.Data(i,1)))==0
                   errordlg(['run ',num2str(i),' name is not valid'],'error_variable name');
               end
            end
            app.data = app.OptTable.Data;
            gui_Run_Me2
            
        end

        % Callback function
        function Step1EditButton_2Pushed(app, event)
            gui_input_parameters
        end

        % Button pushed function: openlogfileButton
        function openlogfileButtonPushed(app, event)
            winopen('log.txt');
        end

        % Callback function
        function step1LoadButtonPushed(app, event)
            
            gui_loadinput;
            %             disp(app.item_input)
            %             app.NameEditField.Value=app.item_input;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EngineMountOptimizationToolboxUIFigure = uifigure;
            app.EngineMountOptimizationToolboxUIFigure.Name = 'Engine Mount Optimization Toolbox';
            app.EngineMountOptimizationToolboxLabel = uilabel(app.EngineMountOptimizationToolboxUIFigure);
            app.EngineMountOptimizationToolboxLabel.Text = 'Engine Mount Optimization Toolbox';
            app.byNVHteamLabel = uilabel(app.EngineMountOptimizationToolboxUIFigure);
            app.byNVHteamLabel.Text = 'by NVH team';
            app.SharifUniversityofTechnologyLabel = uilabel(app.EngineMountOptimizationToolboxUIFigure);
            app.SharifUniversityofTechnologyLabel.Text = 'Sharif University of Technology';
            app.Step1SetinputparametersPanel = uipanel(app.EngineMountOptimizationToolboxUIFigure);
            app.Step1SetinputparametersPanel.Title = 'Step 1 : Set input parameters';
            app.EditButton_2 = uibutton(app.Step1SetinputparametersPanel, 'push');
            app.EditButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoStep1ButtonPushed, true);
            app.EditButton_2.Text = 'Edit';
            app.ChooseFileDropDownLabel = uilabel(app.Step1SetinputparametersPanel);
            app.ChooseFileDropDownLabel.Text = 'Choose File:';
            app.ChooseFileDropDown = uidropdown(app.Step1SetinputparametersPanel);
            app.ChooseFileDropDown.Items = {'not selected'};
            app.ChooseFileDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseFileDropDownValueChanged, true);
            app.ChooseFileDropDown.Value = 'not selected';
            app.CreateButton = uibutton(app.Step1SetinputparametersPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.Step3ViewResultsPanel = uipanel(app.EngineMountOptimizationToolboxUIFigure);
            app.Step3ViewResultsPanel.Title = 'Step 3 : View Results';
            app.ViewResultsButton = uibutton(app.Step3ViewResultsPanel, 'push');
            app.ViewResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GoStep3ButtonPushed, true);
            app.ViewResultsButton.Text = 'View Results';
            app.ChooseFileDropDown_3Label = uilabel(app.Step3ViewResultsPanel);
            app.ChooseFileDropDown_3Label.Text = 'Choose File:';
            app.ChooseFileDropDown_3 = uidropdown(app.Step3ViewResultsPanel);
            app.ChooseFileDropDown_3.Items = {'not selected'};
            app.ChooseFileDropDown_3.Value = 'not selected';
            app.Button = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.Button.Icon = 'SHARIFlogo.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.Button_2.Icon = 'SAIPAlogo.png';
            app.Button_2.Text = '';
            app.Step2OptimizationPanel = uipanel(app.EngineMountOptimizationToolboxUIFigure);
            app.Step2OptimizationPanel.Title = 'Step 2 : Optimization';
            app.StagesListBox_2Label = uilabel(app.Step2OptimizationPanel);
            app.StagesListBox_2Label.Text = 'Stages';
            app.StagesListBox_2 = uilistbox(app.Step2OptimizationPanel);
            app.StagesListBox_2.Items = {'osb'};
            app.StagesListBox_2.Value = 'osb';
            app.DefineButton = uibutton(app.Step2OptimizationPanel, 'push');
            app.DefineButton.ButtonPushedFcn = createCallbackFcn(app, @DefineButtonPushed, true);
            app.DefineButton.Text = 'Define';
            app.AddtoTableButton = uibutton(app.Step2OptimizationPanel, 'push');
            app.AddtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoTableButtonPushed, true);
            app.AddtoTableButton.Text = 'Add to Table';
            app.positionEditFieldLabel = uilabel(app.Step2OptimizationPanel);
            app.positionEditFieldLabel.Text = 'position';
            app.positionEditField = uieditfield(app.Step2OptimizationPanel, 'numeric');
            app.positionEditField.Limits = [11 99];
            app.positionEditField.Value = 11;
            app.OptTable = uitable(app.Step2OptimizationPanel);
            app.OptTable.ColumnName = {'name'; 'stage 1'; 'stage 2'; 'stage 3'; 'stage 4'};
            app.OptTable.RowName = {};
            app.OptTable.ColumnEditable = [true false false false false];
            app.StartButton = uibutton(app.Step2OptimizationPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RefreshButton = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = 'refresh.png';
            app.RefreshButton.Text = 'Refresh';
            app.ExitButton = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = 'close.png';
            app.ExitButton.Text = 'Exit';
            app.openlogfileButton = uibutton(app.EngineMountOptimizationToolboxUIFigure, 'push');
            app.openlogfileButton.ButtonPushedFcn = createCallbackFcn(app, @openlogfileButtonPushed, true);
            app.openlogfileButton.Text = 'open log file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_main2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EngineMountOptimizationToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EngineMountOptimizationToolboxUIFigure)
        end
    end
end