classdef gui_optimization2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptimizationUIFigure            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StrategyTab                     matlab.ui.container.Tab
        TFPanel_1                       matlab.ui.container.Panel
        CriteriaButtonGroup_4           matlab.ui.container.ButtonGroup
        MinimizemaximumForceButton_2    matlab.ui.control.RadioButton
        MinimizesummationofForcesButton_2  matlab.ui.control.RadioButton
        Mount1SelectedDirectionPanel    matlab.ui.container.Panel
        LocalXCheckBox_4                matlab.ui.control.CheckBox
        LocalYCheckBox_4                matlab.ui.control.CheckBox
        LocalZCheckBox_4                matlab.ui.control.CheckBox
        MagnitudeCheckBox_4             matlab.ui.control.CheckBox
        Mount2SelectedDirectionPanel    matlab.ui.container.Panel
        LocalXCheckBox_7                matlab.ui.control.CheckBox
        LocalYCheckBox_7                matlab.ui.control.CheckBox
        LocalZCheckBox_7                matlab.ui.control.CheckBox
        MagnitudeCheckBox_7             matlab.ui.control.CheckBox
        Mount3SelectedDirectionPanel    matlab.ui.container.Panel
        LocalXCheckBox_8                matlab.ui.control.CheckBox
        LocalYCheckBox_8                matlab.ui.control.CheckBox
        LocalZCheckBox_8                matlab.ui.control.CheckBox
        MagnitudeCheckBox_8             matlab.ui.control.CheckBox
        StagetypeDropDownLabel          matlab.ui.control.Label
        StagetypeDropDown               matlab.ui.control.DropDown
        ArPanel_1                       matlab.ui.container.Panel
        CriteriaButtonGroup_6           matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationratioButton_2  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationratiosButton_2  matlab.ui.control.RadioButton
        Mount1SelectedDirectionPanel_3  matlab.ui.container.Panel
        LocalXCheckBox_12               matlab.ui.control.CheckBox
        LocalYCheckBox_12               matlab.ui.control.CheckBox
        LocalZCheckBox_12               matlab.ui.control.CheckBox
        MagnitudeCheckBox_12            matlab.ui.control.CheckBox
        Mount2SelectedDirectionPanel_3  matlab.ui.container.Panel
        LocalXCheckBox_13               matlab.ui.control.CheckBox
        LocalYCheckBox_13               matlab.ui.control.CheckBox
        LocalZCheckBox_13               matlab.ui.control.CheckBox
        MagnitudeCheckBox_13            matlab.ui.control.CheckBox
        Mount3SelectedDirectionPanel_3  matlab.ui.container.Panel
        LocalXCheckBox_14               matlab.ui.control.CheckBox
        LocalYCheckBox_14               matlab.ui.control.CheckBox
        LocalZCheckBox_14               matlab.ui.control.CheckBox
        MagnitudeCheckBox_14            matlab.ui.control.CheckBox
        TAPanel_1                       matlab.ui.container.Panel
        CriteriaButtonGroup_5           matlab.ui.container.ButtonGroup
        MinimizemaximumAccelerationButton_2  matlab.ui.control.RadioButton
        MinimizesummationofAccelerationsButton_2  matlab.ui.control.RadioButton
        Mount1SelectedDirectionPanel_2  matlab.ui.container.Panel
        LocalXCheckBox_9                matlab.ui.control.CheckBox
        LocalYCheckBox_9                matlab.ui.control.CheckBox
        LocalZCheckBox_9                matlab.ui.control.CheckBox
        MagnitudeCheckBox_9             matlab.ui.control.CheckBox
        Mount2SelectedDirectionPanel_2  matlab.ui.container.Panel
        LocalXCheckBox_10               matlab.ui.control.CheckBox
        LocalYCheckBox_10               matlab.ui.control.CheckBox
        LocalZCheckBox_10               matlab.ui.control.CheckBox
        MagnitudeCheckBox_10            matlab.ui.control.CheckBox
        Mount3SelectedDirectionPanel_2  matlab.ui.container.Panel
        LocalXCheckBox_11               matlab.ui.control.CheckBox
        LocalYCheckBox_11               matlab.ui.control.CheckBox
        LocalZCheckBox_11               matlab.ui.control.CheckBox
        MagnitudeCheckBox_11            matlab.ui.control.CheckBox
        PSOOptionsPanel                 matlab.ui.container.Panel
        swarmsizeEditFieldLabel         matlab.ui.control.Label
        swarmsizeEditField              matlab.ui.control.NumericEditField
        MaxFuncEvalEditFieldLabel       matlab.ui.control.Label
        MaxFuncEvalEditField            matlab.ui.control.NumericEditField
        FuncTolEditFieldLabel           matlab.ui.control.Label
        FuncTolEditField                matlab.ui.control.NumericEditField
        MaxIterEditFieldLabel           matlab.ui.control.Label
        MaxIterEditField                matlab.ui.control.NumericEditField
        PenFuncWeightEditFieldLabel     matlab.ui.control.Label
        PenFuncWeightEditField          matlab.ui.control.NumericEditField
        StageNameEditFieldLabel         matlab.ui.control.Label
        StageNameEditField              matlab.ui.control.EditField
        StagetypeDropDown_2Label        matlab.ui.control.Label
        StagetypeDropDown_2             matlab.ui.control.DropDown
        ConstraintsTab_2                matlab.ui.container.Tab
        KEDcriteriaCheckBox             matlab.ui.control.CheckBox
        percentageEditFieldLabel        matlab.ui.control.Label
        percentageEditField             matlab.ui.control.NumericEditField
        NaturalFrequenciesPanel         matlab.ui.container.Panel
        LongitudinalLabel_3             matlab.ui.control.Label
        LateralLabel_4                  matlab.ui.control.Label
        BounceLabel_4                   matlab.ui.control.Label
        RollLabel_4                     matlab.ui.control.Label
        PitchLabel_4                    matlab.ui.control.Label
        BounceLabel_5                   matlab.ui.control.Label
        EditField_31                    matlab.ui.control.NumericEditField
        LowerHzEditFieldLabel_2         matlab.ui.control.Label
        EditField_32                    matlab.ui.control.NumericEditField
        UpperHzEditFieldLabel_2         matlab.ui.control.Label
        EditField_33                    matlab.ui.control.NumericEditField
        EditField_34                    matlab.ui.control.NumericEditField
        EditField_35                    matlab.ui.control.NumericEditField
        EditField_36                    matlab.ui.control.NumericEditField
        EditField_37                    matlab.ui.control.NumericEditField
        EditField_38                    matlab.ui.control.NumericEditField
        EditField_39                    matlab.ui.control.NumericEditField
        EditField_40                    matlab.ui.control.NumericEditField
        EditField_41                    matlab.ui.control.NumericEditField
        EditField_42                    matlab.ui.control.NumericEditField
        StaticDeflectionsPanel          matlab.ui.container.Panel
        LongitudinalLabel_2             matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        LateralLabel_3                  matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        BounceLabel_3                   matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        RollLabel_3                     matlab.ui.control.Label
        degLabel_4                      matlab.ui.control.Label
        PitchLabel_3                    matlab.ui.control.Label
        degLabel_5                      matlab.ui.control.Label
        YawLabel_3                      matlab.ui.control.Label
        degLabel_6                      matlab.ui.control.Label
        EditField_25                    matlab.ui.control.NumericEditField
        EditField_26                    matlab.ui.control.NumericEditField
        EditField_27                    matlab.ui.control.NumericEditField
        EditField_28                    matlab.ui.control.NumericEditField
        EditField_29                    matlab.ui.control.NumericEditField
        EditField_30                    matlab.ui.control.NumericEditField
        BoundsTab                       matlab.ui.container.Tab
        panel_mount1e                   matlab.ui.container.Panel
        Label17                         matlab.ui.control.Label
        edit_mount1proeupLabel          matlab.ui.control.Label
        edit_mount1stiff_ub             matlab.ui.control.EditField
        edit_mount1proelowLabel         matlab.ui.control.Label
        edit_mount1stiff_lb             matlab.ui.control.EditField
        Label17_2                       matlab.ui.control.Label
        StiffnessLabel                  matlab.ui.control.Label
        edit_mount1proeupLabel_2        matlab.ui.control.Label
        edit_mount1damp_ub              matlab.ui.control.EditField
        edit_mount1proelowLabel_2       matlab.ui.control.Label
        edit_mount1damp_lb              matlab.ui.control.EditField
        DampingLabel                    matlab.ui.control.Label
        Label17_5                       matlab.ui.control.Label
        Label17_6                       matlab.ui.control.Label
        LocationLabel                   matlab.ui.control.Label
        OrientationLabel                matlab.ui.control.Label
        edit_mount1locupLabel           matlab.ui.control.Label
        edit_mount1locup                matlab.ui.control.EditField
        edit_mount1loclowLabel          matlab.ui.control.Label
        edit_mount1loclow               matlab.ui.control.EditField
        edit_mount1locupLabel_2         matlab.ui.control.Label
        edit_mount1oriup                matlab.ui.control.EditField
        edit_mount1loclowLabel_2        matlab.ui.control.Label
        edit_mount1orilow               matlab.ui.control.EditField
        Label17_7                       matlab.ui.control.Label
        Label17_8                       matlab.ui.control.Label
        Label17_9                       matlab.ui.control.Label
        Label17_10                      matlab.ui.control.Label
        panel_mount1e_2                 matlab.ui.container.Panel
        Label17_11                      matlab.ui.control.Label
        edit_mount1proeupLabel_3        matlab.ui.control.Label
        edit_mount2stiff_ub             matlab.ui.control.EditField
        edit_mount1proelowLabel_3       matlab.ui.control.Label
        edit_mount2stiff_lb             matlab.ui.control.EditField
        Label17_12                      matlab.ui.control.Label
        StiffnessLabel_2                matlab.ui.control.Label
        edit_mount1proeupLabel_4        matlab.ui.control.Label
        edit_mount2damp_ub              matlab.ui.control.EditField
        edit_mount1proelowLabel_4       matlab.ui.control.Label
        edit_mount2damp_lb              matlab.ui.control.EditField
        DampingLabel_2                  matlab.ui.control.Label
        Label17_13                      matlab.ui.control.Label
        Label17_14                      matlab.ui.control.Label
        LocationLabel_2                 matlab.ui.control.Label
        OrientationLabel_2              matlab.ui.control.Label
        edit_mount1locupLabel_3         matlab.ui.control.Label
        edit_mount2locup                matlab.ui.control.EditField
        edit_mount1loclowLabel_3        matlab.ui.control.Label
        edit_mount2loclow               matlab.ui.control.EditField
        edit_mount1locupLabel_4         matlab.ui.control.Label
        edit_mount2oriup                matlab.ui.control.EditField
        edit_mount1loclowLabel_4        matlab.ui.control.Label
        edit_mount2orilow               matlab.ui.control.EditField
        Label17_15                      matlab.ui.control.Label
        Label17_16                      matlab.ui.control.Label
        Label17_17                      matlab.ui.control.Label
        Label17_18                      matlab.ui.control.Label
        panel_mount1e_3                 matlab.ui.container.Panel
        Label17_19                      matlab.ui.control.Label
        edit_mount1proeupLabel_5        matlab.ui.control.Label
        edit_mount3stiff_ub             matlab.ui.control.EditField
        edit_mount1proelowLabel_5       matlab.ui.control.Label
        edit_mount3stiff_lb             matlab.ui.control.EditField
        Label17_20                      matlab.ui.control.Label
        StiffnessLabel_3                matlab.ui.control.Label
        edit_mount1proeupLabel_6        matlab.ui.control.Label
        edit_mount3damp_ub              matlab.ui.control.EditField
        edit_mount1proelowLabel_6       matlab.ui.control.Label
        edit_mount3damp_lb              matlab.ui.control.EditField
        DampingLabel_3                  matlab.ui.control.Label
        Label17_21                      matlab.ui.control.Label
        Label17_22                      matlab.ui.control.Label
        LocationLabel_3                 matlab.ui.control.Label
        OrientationLabel_3              matlab.ui.control.Label
        edit_mount1locupLabel_5         matlab.ui.control.Label
        edit_mount3locup                matlab.ui.control.EditField
        edit_mount1loclowLabel_5        matlab.ui.control.Label
        edit_mount3loclow               matlab.ui.control.EditField
        edit_mount1locupLabel_6         matlab.ui.control.Label
        edit_mount3oriup                matlab.ui.control.EditField
        edit_mount1loclowLabel_6        matlab.ui.control.Label
        edit_mount3orilow               matlab.ui.control.EditField
        Label17_23                      matlab.ui.control.Label
        Label17_24                      matlab.ui.control.Label
        Label17_25                      matlab.ui.control.Label
        Label17_26                      matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        CreateNewStageLabel             matlab.ui.control.Label
    end


    methods (Access = private)
    
        function  write(app,tb,p)
             
            tb.Value = ['[',num2str(p(1)),', ',num2str(p(2)),', ',num2str(p(3)),' ]'];
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                clc
                load(['SavedResults/','gui_curr.mat']);
            
                load(['SavedResults/stg_',gui_curr.stage_name]);
                h = eval(gui_curr.stage_name);
                app.StageNameEditField.Value = gui_curr.stage_name;
                app.StageNameEditField.Editable = 'on';
                app.StagetypeDropDown.Value = h.type;
                
                StagetypeDropDownValueChanged(app);
                
                app.percentageEditField.Value = h.KED;
                app.EditField_31.Value = h.lb_freq(1);
                app.EditField_33.Value = h.lb_freq(2);
                app.EditField_34.Value = h.lb_freq(3);
                app.EditField_35.Value = h.lb_freq(4);
                app.EditField_36.Value = h.lb_freq(5);
                app.EditField_37.Value = h.lb_freq(6);
                
                
                app.EditField_32.Value = h.ub_freq(1);
                app.EditField_38.Value = h.ub_freq(2);
                app.EditField_39.Value = h.ub_freq(3);
                app.EditField_40.Value = h.ub_freq(4);
                app.EditField_41.Value = h.ub_freq(5);
                app.EditField_42.Value = h.ub_freq(6);
                
                app.EditField_25.Value = h.DeltaStatic(1);
                app.EditField_26.Value = h.DeltaStatic(2);
                app.EditField_27.Value = h.DeltaStatic(3);
                app.EditField_28.Value = h.DeltaStatic(4);
                app.EditField_29.Value = h.DeltaStatic(5);
           
                write(app,app.edit_mount1stiff_ub,h.ub_purt.stiff1);
                write(app,app.edit_mount1stiff_lb,h.lb_purt.stiff1);
                write(app,app.edit_mount1damp_ub,h.ub_purt.damp1);
                write(app,app.edit_mount1damp_lb,h.lb_purt.damp1);
                write(app,app.edit_mount1locup,h.ub_purt.loc1);
                write(app,app.edit_mount1loclow,h.lb_purt.loc1);
                write(app,app.edit_mount1oriup,h.ub_purt.ori1);
                write(app,app.edit_mount1orilow,h.lb_purt.ori1);
                
                 write(app,app.edit_mount2stiff_ub,h.ub_purt.stiff2);
                write(app,app.edit_mount2stiff_lb,h.lb_purt.stiff2);
                write(app,app.edit_mount2damp_ub,h.ub_purt.damp2);
                write(app,app.edit_mount2damp_lb,h.lb_purt.damp2);
                write(app,app.edit_mount2locup,h.ub_purt.loc2);
                write(app,app.edit_mount2loclow,h.lb_purt.loc2);
                write(app,app.edit_mount2oriup,h.ub_purt.ori2);
                write(app,app.edit_mount2orilow,h.lb_purt.ori2);
                
                 write(app,app.edit_mount3stiff_ub,h.ub_purt.stiff3);
                write(app,app.edit_mount3stiff_lb,h.lb_purt.stiff3);
                write(app,app.edit_mount3damp_ub,h.ub_purt.damp3);
                write(app,app.edit_mount3damp_lb,h.lb_purt.damp3);
                write(app,app.edit_mount3locup,h.ub_purt.loc3);
                write(app,app.edit_mount3loclow,h.lb_purt.loc3);
                write(app,app.edit_mount3oriup,h.ub_purt.ori3);
                write(app,app.edit_mount3orilow,h.lb_purt.ori3);
                
                            app.swarmsizeEditField.Value = h.swarmsize;
                            app.MaxFuncEvalEditField.Value = h.MaxFuncEval;
                            app.FuncTolEditField.Value = h.FuncTol;
                            app.MaxIterEditField.Value = h.MaxIter;
                            app.PenFuncWeightEditField.Value = h.PenFuncWeight;
                
                
            %                 app.UITable_purt  = ['Stage 1',num2cell(   ones(1,9));...
            %                                          'Stage 2',num2cell(.2*ones(1,9));...
            %                                          'Stage 3',num2cell(.2*ones(1,9))];
                
                %                 load('SavedResults/temp_optt.mat');
                
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            load(['SavedResults/','gui_curr.mat']);
            % load(['SavedResults/',gui_curr.opt_name]);
            % load(['SavedResults/',gui_curr.input_name]);
            gui_curr.stage_name = app.StageNameEditField.Value;
            % h = eval(gui_curr.opt_name(1:end-4));
            
            h.name = app.StageNameEditField.Value;
            h.type = app.StagetypeDropDown.Value;


            h.TF.method = [app.MinimizemaximumForceButton_2.Value,               app.MinimizesummationofForcesButton_2.Value];
            h.TA.method = [app.MinimizemaximumAccelerationButton_2.Value,        app.MinimizesummationofAccelerationsButton_2.Value];
            h.Ar.method = [app.MinimizemaximumAccelerationratioButton_2.Value,   app.MinimizesummationofAccelerationratiosButton_2.Value];
            
            
            h.TA.dir = [app.LocalXCheckBox_9.Value,  app.LocalYCheckBox_9.Value, app.LocalZCheckBox_9.Value, app.MagnitudeCheckBox_9.Value;...
                   app.LocalXCheckBox_10.Value,  app.LocalYCheckBox_10.Value, app.LocalZCheckBox_10.Value, app.MagnitudeCheckBox_10.Value;... 
                   app.LocalXCheckBox_11.Value,  app.LocalYCheckBox_11.Value, app.LocalZCheckBox_11.Value, app.MagnitudeCheckBox_11.Value];
            
            h.Ar.dir = [app.LocalXCheckBox_12.Value,  app.LocalYCheckBox_12.Value, app.LocalZCheckBox_12.Value, app.MagnitudeCheckBox_12.Value;...
                   app.LocalXCheckBox_13.Value,  app.LocalYCheckBox_13.Value, app.LocalZCheckBox_13.Value, app.MagnitudeCheckBox_13.Value;... 
                   app.LocalXCheckBox_14.Value,  app.LocalYCheckBox_14.Value, app.LocalZCheckBox_14.Value, app.MagnitudeCheckBox_14.Value];
            
            h.TF.dir = [app.LocalXCheckBox_4.Value,  app.LocalYCheckBox_4.Value, app.LocalZCheckBox_4.Value, app.MagnitudeCheckBox_4.Value;...
                   app.LocalXCheckBox_7.Value,  app.LocalYCheckBox_7.Value, app.LocalZCheckBox_7.Value, app.MagnitudeCheckBox_7.Value;... 
                   app.LocalXCheckBox_8.Value,  app.LocalYCheckBox_8.Value, app.LocalZCheckBox_8.Value, app.MagnitudeCheckBox_8.Value];


            h.KED = app.percentageEditField.Value;
            h.DeltaStatic =[app.EditField_25.Value,app.EditField_26.Value,...
                app.EditField_27.Value,app.EditField_28.Value,...
                app.EditField_29.Value,app.EditField_30.Value];
            h.ub_freq =[app.EditField_32.Value,app.EditField_38.Value,...
            app.EditField_39.Value,app.EditField_40.Value,...
            app.EditField_41.Value,app.EditField_42.Value];
            h.lb_freq =[app.EditField_31.Value,app.EditField_33.Value,...
            app.EditField_34.Value,app.EditField_35.Value,...
            app.EditField_36.Value,app.EditField_37.Value];
            
            h.ub_purt.stiff1 = eval(app.edit_mount1stiff_ub.Value);
            h.lb_purt.stiff1 = eval(app.edit_mount1stiff_lb.Value);
            h.ub_purt.damp1 = eval(app.edit_mount1damp_ub.Value);
            h.lb_purt.damp1 = eval(app.edit_mount1damp_lb.Value);
            h.ub_purt.loc1 = eval(app.edit_mount1locup.Value);
            h.lb_purt.loc1 = eval(app.edit_mount1loclow.Value);
            h.ub_purt.ori1 = eval(app.edit_mount1locup.Value );
            h.lb_purt.ori1 = eval(app.edit_mount1loclow.Value );
            
             h.ub_purt.stiff2 = eval(app.edit_mount2stiff_ub.Value);
            h.lb_purt.stiff2 = eval(app.edit_mount2stiff_lb.Value);
            h.ub_purt.damp2 = eval(app.edit_mount2damp_ub.Value);
            h.lb_purt.damp2 = eval(app.edit_mount2damp_lb.Value);
            h.ub_purt.loc2 = eval(app.edit_mount2locup.Value);
            h.lb_purt.loc2 = eval(app.edit_mount2loclow.Value);
            h.ub_purt.ori2 = eval(app.edit_mount2locup.Value );
            h.lb_purt.ori2 = eval(app.edit_mount2loclow.Value );
            
             h.ub_purt.stiff3 = eval(app.edit_mount3stiff_ub.Value);
            h.lb_purt.stiff3 = eval(app.edit_mount3stiff_lb.Value);
            h.ub_purt.damp3 = eval(app.edit_mount3damp_ub.Value);
            h.lb_purt.damp3 = eval(app.edit_mount3damp_lb.Value);
            h.ub_purt.loc3 = eval(app.edit_mount3locup.Value);
            h.lb_purt.loc3 = eval(app.edit_mount3loclow.Value);
            h.ub_purt.ori3 = eval(app.edit_mount3locup.Value );
            h.lb_purt.ori3 = eval(app.edit_mount3loclow.Value );
            
            
            h.swarmsize = app.swarmsizeEditField.Value;
            h.MaxFuncEval = app.MaxFuncEvalEditField.Value;
            h.FuncTol = app.FuncTolEditField.Value;
            h.MaxIter = app.MaxIterEditField.Value;
            h.PenFuncWeight = app.PenFuncWeightEditField.Value;
            
            % delete(['SavedResults/',gui_curr.opt_name]);
            % gui_curr.opt_name = app.OptimizationFileEditField.Value;
            eval([gui_curr.stage_name,'=h;']);
            save('SavedResults/gui_curr.mat','gui_curr')
            save(['SavedResults/stg_',gui_curr.stage_name],gui_curr.stage_name);


        end

        % Callback function
        function Stage2CheckBoxValueChanged(app, event)
            value = app.Stage2CheckBox.Value;
            if value==1
                app.Stage2Panel.Visible = 'on';
            else 
                app.Stage2Panel.Visible = 'off';     
            end
        end

        % Callback function
        function Stage2DropDownValueChanged(app, event)
            value = app.Stage2DropDown.Value;
            app.Stage2Panel_2.Visible = 'on';
            
            x=298; y=20; w=275; h=385;
            
            switch value
                case 'TRA'
                    app.ArPanel_2.Visible = 'off'
                    app.TFPanel_2.Visible = 'off'
                    app.TAPanel_2.Visible = 'off'
                    
                case 'TF'
                    app.ArPanel_2.Visible = 'off'
                    app.TFPanel_2.Visible = 'on'
                    app.TAPanel_2.Visible = 'off'
                    app.TFPanel_2.Position = [x,y,w,h];
                case 'TA'
                    app.ArPanel_2.Visible = 'off'
                    app.TFPanel_2.Visible = 'off'
                    app.TAPanel_2.Visible = 'on'
                    app.TAPanel_2.Position = [x,y,w,h];
                case 'Ar'
                    app.ArPanel_2.Visible = 'on'
                    app.TFPanel_2.Visible = 'off'
                    app.TAPanel_2.Visible = 'off'
                    app.ArPanel_2.Position = [x,y,w,h];
                case 'None'   
                    app.ArPanel_2.Visible = 'off'
                    app.TFPanel_2.Visible = 'off'
                    app.TAPanel_2.Visible = 'off'
                    app.Stage2Panel_2.Visible = 'off';
                 
            end
            
        end

        % Callback function
        function Stage3CheckBoxValueChanged(app, event)
            value = app.Stage3CheckBox.Value;
            if value==1
                app.Stage3Panel.Visible = 'on';
            else 
                app.Stage3Panel.Visible = 'off';     
            end
        end

        % Callback function
        function Stage3DropDownValueChanged(app, event)
            value = app.Stage3DropDown.Value;
            app.Stage3Panel_2.Visible = 'on';
            
            x=590; y=20; w=275; h=385;
            switch value
                case 'TRA'
                    app.ArPanel_3.Visible = 'off'
                    app.TFPanel_3.Visible = 'off'
                    app.TAPanel_3.Visible = 'off'
                    
                case 'TF'
                    app.ArPanel_3.Visible = 'off'
                    app.TFPanel_3.Visible = 'on'
                    app.TAPanel_3.Visible = 'off'
                    app.TFPanel_3.Position = [x,y,w,h];
                case 'TA'
                    app.ArPanel_3.Visible = 'off'
                    app.TFPanel_3.Visible = 'off'
                    app.TAPanel_3.Visible = 'on'
                    app.TAPanel_3.Position = [x,y,w,h];
                case 'Ar'
                    app.ArPanel_3.Visible = 'on'
                    app.TFPanel_3.Visible = 'off'
                    app.TAPanel_3.Visible = 'off'
                    app.ArPanel_3.Position = [x,y,w,h];
                case 'None'   
                    app.ArPanel_3.Visible = 'off'
                    app.TFPanel_3.Visible = 'off'
                    app.TAPanel_3.Visible = 'off'
                    app.Stage3Panel_2.Visible = 'off';
            
            end
            
        end

        % Value changed function: StagetypeDropDown
        function StagetypeDropDownValueChanged(app, event)
            value = app.StagetypeDropDown.Value;
            disp(value)
            x=7; y=20; w=275; h=385;
            switch value
                case 'TRA'
                    app.ArPanel_1.Visible = 'off';
                    app.TFPanel_1.Visible = 'off';
                    app.TAPanel_1.Visible = 'off';
                    
                case 'TF'
                    app.ArPanel_1.Visible = 'off';
                    app.TFPanel_1.Visible = 'on';
                    app.TAPanel_1.Visible = 'off';
                    app.TFPanel_1.Position = [x,y,w,h];
                case 'TA'
                    app.ArPanel_1.Visible = 'off';
                    app.TFPanel_1.Visible = 'off';
                    app.TAPanel_1.Visible = 'on';
                    app.TAPanel_1.Position = [x,y,w,h];
                case 'Ar'
                    app.ArPanel_1.Visible = 'on';
                    app.TFPanel_1.Visible = 'off';
                    app.TAPanel_1.Visible = 'off';
                    app.ArPanel_1.Position = [x,y,w,h];
            %                 case 'None'
            %                     app.Stage1Panel_2.Visible = 'off';
                    
            end
                    
        end

        % Callback function
        function TRASettingsButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptimizationUIFigure = uifigure;
            app.OptimizationUIFigure.Name = 'Optimization';
            app.TabGroup = uitabgroup(app.OptimizationUIFigure);
            app.StrategyTab = uitab(app.TabGroup);
            app.StrategyTab.Title = 'Strategy';
            app.TFPanel_1 = uipanel(app.StrategyTab);
            app.TFPanel_1.Title = 'Transmitted Force';
            app.CriteriaButtonGroup_4 = uibuttongroup(app.TFPanel_1);
            app.CriteriaButtonGroup_4.Title = 'Criteria';
            app.MinimizemaximumForceButton_2 = uiradiobutton(app.CriteriaButtonGroup_4);
            app.MinimizemaximumForceButton_2.Text = 'Minimize maximum Force';
            app.MinimizemaximumForceButton_2.Value = true;
            app.MinimizesummationofForcesButton_2 = uiradiobutton(app.CriteriaButtonGroup_4);
            app.MinimizesummationofForcesButton_2.Text = 'Minimize summation of Forces';
            app.Mount1SelectedDirectionPanel = uipanel(app.TFPanel_1);
            app.Mount1SelectedDirectionPanel.Title = 'Mount 1 : Selected Direction';
            app.LocalXCheckBox_4 = uicheckbox(app.Mount1SelectedDirectionPanel);
            app.LocalXCheckBox_4.Text = 'Local X';
            app.LocalYCheckBox_4 = uicheckbox(app.Mount1SelectedDirectionPanel);
            app.LocalYCheckBox_4.Text = 'Local Y';
            app.LocalZCheckBox_4 = uicheckbox(app.Mount1SelectedDirectionPanel);
            app.LocalZCheckBox_4.Text = 'Local Z';
            app.MagnitudeCheckBox_4 = uicheckbox(app.Mount1SelectedDirectionPanel);
            app.MagnitudeCheckBox_4.Text = 'Magnitude';
            app.Mount2SelectedDirectionPanel = uipanel(app.TFPanel_1);
            app.Mount2SelectedDirectionPanel.Title = 'Mount 2 : Selected Direction';
            app.LocalXCheckBox_7 = uicheckbox(app.Mount2SelectedDirectionPanel);
            app.LocalXCheckBox_7.Text = 'Local X';
            app.LocalYCheckBox_7 = uicheckbox(app.Mount2SelectedDirectionPanel);
            app.LocalYCheckBox_7.Text = 'Local Y';
            app.LocalZCheckBox_7 = uicheckbox(app.Mount2SelectedDirectionPanel);
            app.LocalZCheckBox_7.Text = 'Local Z';
            app.MagnitudeCheckBox_7 = uicheckbox(app.Mount2SelectedDirectionPanel);
            app.MagnitudeCheckBox_7.Text = 'Magnitude';
            app.Mount3SelectedDirectionPanel = uipanel(app.TFPanel_1);
            app.Mount3SelectedDirectionPanel.Title = 'Mount 3 : Selected Direction';
            app.LocalXCheckBox_8 = uicheckbox(app.Mount3SelectedDirectionPanel);
            app.LocalXCheckBox_8.Text = 'Local X';
            app.LocalYCheckBox_8 = uicheckbox(app.Mount3SelectedDirectionPanel);
            app.LocalYCheckBox_8.Text = 'Local Y';
            app.LocalZCheckBox_8 = uicheckbox(app.Mount3SelectedDirectionPanel);
            app.LocalZCheckBox_8.Text = 'Local Z';
            app.MagnitudeCheckBox_8 = uicheckbox(app.Mount3SelectedDirectionPanel);
            app.MagnitudeCheckBox_8.Text = 'Magnitude';
            app.StagetypeDropDownLabel = uilabel(app.StrategyTab);
            app.StagetypeDropDownLabel.Text = 'Stage type';
            app.StagetypeDropDown = uidropdown(app.StrategyTab);
            app.StagetypeDropDown.Items = {'TRA', 'TF', 'TA', 'Ar'};
            app.StagetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StagetypeDropDownValueChanged, true);
            app.StagetypeDropDown.Value = 'TRA';
            app.ArPanel_1 = uipanel(app.StrategyTab);
            app.ArPanel_1.Title = 'Acceleration ratio';
            app.CriteriaButtonGroup_6 = uibuttongroup(app.ArPanel_1);
            app.CriteriaButtonGroup_6.Title = 'Criteria';
            app.MinimizemaximumAccelerationratioButton_2 = uiradiobutton(app.CriteriaButtonGroup_6);
            app.MinimizemaximumAccelerationratioButton_2.Text = 'Minimize maximum Acceleration ratio';
            app.MinimizemaximumAccelerationratioButton_2.Value = true;
            app.MinimizesummationofAccelerationratiosButton_2 = uiradiobutton(app.CriteriaButtonGroup_6);
            app.MinimizesummationofAccelerationratiosButton_2.Text = 'Minimize summation of Acceleration ratios';
            app.Mount1SelectedDirectionPanel_3 = uipanel(app.ArPanel_1);
            app.Mount1SelectedDirectionPanel_3.Title = 'Mount 1 : Selected Direction';
            app.LocalXCheckBox_12 = uicheckbox(app.Mount1SelectedDirectionPanel_3);
            app.LocalXCheckBox_12.Text = 'Local X';
            app.LocalYCheckBox_12 = uicheckbox(app.Mount1SelectedDirectionPanel_3);
            app.LocalYCheckBox_12.Text = 'Local Y';
            app.LocalZCheckBox_12 = uicheckbox(app.Mount1SelectedDirectionPanel_3);
            app.LocalZCheckBox_12.Text = 'Local Z';
            app.MagnitudeCheckBox_12 = uicheckbox(app.Mount1SelectedDirectionPanel_3);
            app.MagnitudeCheckBox_12.Text = 'Magnitude';
            app.Mount2SelectedDirectionPanel_3 = uipanel(app.ArPanel_1);
            app.Mount2SelectedDirectionPanel_3.Title = 'Mount 2 : Selected Direction';
            app.LocalXCheckBox_13 = uicheckbox(app.Mount2SelectedDirectionPanel_3);
            app.LocalXCheckBox_13.Text = 'Local X';
            app.LocalYCheckBox_13 = uicheckbox(app.Mount2SelectedDirectionPanel_3);
            app.LocalYCheckBox_13.Text = 'Local Y';
            app.LocalZCheckBox_13 = uicheckbox(app.Mount2SelectedDirectionPanel_3);
            app.LocalZCheckBox_13.Text = 'Local Z';
            app.MagnitudeCheckBox_13 = uicheckbox(app.Mount2SelectedDirectionPanel_3);
            app.MagnitudeCheckBox_13.Text = 'Magnitude';
            app.Mount3SelectedDirectionPanel_3 = uipanel(app.ArPanel_1);
            app.Mount3SelectedDirectionPanel_3.Title = 'Mount 3 : Selected Direction';
            app.LocalXCheckBox_14 = uicheckbox(app.Mount3SelectedDirectionPanel_3);
            app.LocalXCheckBox_14.Text = 'Local X';
            app.LocalYCheckBox_14 = uicheckbox(app.Mount3SelectedDirectionPanel_3);
            app.LocalYCheckBox_14.Text = 'Local Y';
            app.LocalZCheckBox_14 = uicheckbox(app.Mount3SelectedDirectionPanel_3);
            app.LocalZCheckBox_14.Text = 'Local Z';
            app.MagnitudeCheckBox_14 = uicheckbox(app.Mount3SelectedDirectionPanel_3);
            app.MagnitudeCheckBox_14.Text = 'Magnitude';
            app.TAPanel_1 = uipanel(app.StrategyTab);
            app.TAPanel_1.Title = 'Transmitted Acceleration';
            app.CriteriaButtonGroup_5 = uibuttongroup(app.TAPanel_1);
            app.CriteriaButtonGroup_5.Title = 'Criteria';
            app.MinimizemaximumAccelerationButton_2 = uiradiobutton(app.CriteriaButtonGroup_5);
            app.MinimizemaximumAccelerationButton_2.Text = 'Minimize maximum Acceleration';
            app.MinimizemaximumAccelerationButton_2.Value = true;
            app.MinimizesummationofAccelerationsButton_2 = uiradiobutton(app.CriteriaButtonGroup_5);
            app.MinimizesummationofAccelerationsButton_2.Text = 'Minimize summation of Accelerations';
            app.Mount1SelectedDirectionPanel_2 = uipanel(app.TAPanel_1);
            app.Mount1SelectedDirectionPanel_2.Title = 'Mount 1 : Selected Direction';
            app.LocalXCheckBox_9 = uicheckbox(app.Mount1SelectedDirectionPanel_2);
            app.LocalXCheckBox_9.Text = 'Local X';
            app.LocalYCheckBox_9 = uicheckbox(app.Mount1SelectedDirectionPanel_2);
            app.LocalYCheckBox_9.Text = 'Local Y';
            app.LocalZCheckBox_9 = uicheckbox(app.Mount1SelectedDirectionPanel_2);
            app.LocalZCheckBox_9.Text = 'Local Z';
            app.MagnitudeCheckBox_9 = uicheckbox(app.Mount1SelectedDirectionPanel_2);
            app.MagnitudeCheckBox_9.Text = 'Magnitude';
            app.Mount2SelectedDirectionPanel_2 = uipanel(app.TAPanel_1);
            app.Mount2SelectedDirectionPanel_2.Title = 'Mount 2 : Selected Direction';
            app.LocalXCheckBox_10 = uicheckbox(app.Mount2SelectedDirectionPanel_2);
            app.LocalXCheckBox_10.Text = 'Local X';
            app.LocalYCheckBox_10 = uicheckbox(app.Mount2SelectedDirectionPanel_2);
            app.LocalYCheckBox_10.Text = 'Local Y';
            app.LocalZCheckBox_10 = uicheckbox(app.Mount2SelectedDirectionPanel_2);
            app.LocalZCheckBox_10.Text = 'Local Z';
            app.MagnitudeCheckBox_10 = uicheckbox(app.Mount2SelectedDirectionPanel_2);
            app.MagnitudeCheckBox_10.Text = 'Magnitude';
            app.Mount3SelectedDirectionPanel_2 = uipanel(app.TAPanel_1);
            app.Mount3SelectedDirectionPanel_2.Title = 'Mount 3 : Selected Direction';
            app.LocalXCheckBox_11 = uicheckbox(app.Mount3SelectedDirectionPanel_2);
            app.LocalXCheckBox_11.Text = 'Local X';
            app.LocalYCheckBox_11 = uicheckbox(app.Mount3SelectedDirectionPanel_2);
            app.LocalYCheckBox_11.Text = 'Local Y';
            app.LocalZCheckBox_11 = uicheckbox(app.Mount3SelectedDirectionPanel_2);
            app.LocalZCheckBox_11.Text = 'Local Z';
            app.MagnitudeCheckBox_11 = uicheckbox(app.Mount3SelectedDirectionPanel_2);
            app.MagnitudeCheckBox_11.Text = 'Magnitude';
            app.PSOOptionsPanel = uipanel(app.StrategyTab);
            app.PSOOptionsPanel.Title = 'PSO Options';
            app.swarmsizeEditFieldLabel = uilabel(app.PSOOptionsPanel);
            app.swarmsizeEditFieldLabel.Text = 'swarmsize';
            app.swarmsizeEditField = uieditfield(app.PSOOptionsPanel, 'numeric');
            app.MaxFuncEvalEditFieldLabel = uilabel(app.PSOOptionsPanel);
            app.MaxFuncEvalEditFieldLabel.Text = 'MaxFuncEval';
            app.MaxFuncEvalEditField = uieditfield(app.PSOOptionsPanel, 'numeric');
            app.FuncTolEditFieldLabel = uilabel(app.PSOOptionsPanel);
            app.FuncTolEditFieldLabel.Text = 'FuncTol';
            app.FuncTolEditField = uieditfield(app.PSOOptionsPanel, 'numeric');
            app.MaxIterEditFieldLabel = uilabel(app.PSOOptionsPanel);
            app.MaxIterEditFieldLabel.Text = 'MaxIter';
            app.MaxIterEditField = uieditfield(app.PSOOptionsPanel, 'numeric');
            app.PenFuncWeightEditFieldLabel = uilabel(app.PSOOptionsPanel);
            app.PenFuncWeightEditFieldLabel.Text = 'PenFuncWeight';
            app.PenFuncWeightEditField = uieditfield(app.PSOOptionsPanel, 'numeric');
            app.StageNameEditFieldLabel = uilabel(app.StrategyTab);
            app.StageNameEditFieldLabel.Text = 'Stage Name';
            app.StageNameEditField = uieditfield(app.StrategyTab, 'text');
            app.StagetypeDropDown_2Label = uilabel(app.StrategyTab);
            app.StagetypeDropDown_2Label.Text = 'Stage type';
            app.StagetypeDropDown_2 = uidropdown(app.StrategyTab);
            app.StagetypeDropDown_2.Items = {'TRA', 'TF', 'TA', 'Ar'};
            app.StagetypeDropDown_2.Value = 'TRA';
            app.ConstraintsTab_2 = uitab(app.TabGroup);
            app.ConstraintsTab_2.Title = 'Constraints';
            app.KEDcriteriaCheckBox = uicheckbox(app.ConstraintsTab_2);
            app.KEDcriteriaCheckBox.Text = 'KED criteria';
            app.KEDcriteriaCheckBox.Value = true;
            app.percentageEditFieldLabel = uilabel(app.ConstraintsTab_2);
            app.percentageEditFieldLabel.Text = 'percentage';
            app.percentageEditField = uieditfield(app.ConstraintsTab_2, 'numeric');
            app.percentageEditField.Value = 85;
            app.NaturalFrequenciesPanel = uipanel(app.ConstraintsTab_2);
            app.NaturalFrequenciesPanel.Title = 'Natural Frequencies';
            app.LongitudinalLabel_3 = uilabel(app.NaturalFrequenciesPanel);
            app.LongitudinalLabel_3.Text = 'Longitudinal';
            app.LateralLabel_4 = uilabel(app.NaturalFrequenciesPanel);
            app.LateralLabel_4.Text = 'Lateral';
            app.BounceLabel_4 = uilabel(app.NaturalFrequenciesPanel);
            app.BounceLabel_4.Text = 'Bounce';
            app.RollLabel_4 = uilabel(app.NaturalFrequenciesPanel);
            app.RollLabel_4.Text = 'Roll';
            app.PitchLabel_4 = uilabel(app.NaturalFrequenciesPanel);
            app.PitchLabel_4.Text = 'Pitch';
            app.BounceLabel_5 = uilabel(app.NaturalFrequenciesPanel);
            app.BounceLabel_5.Text = 'Bounce';
            app.EditField_31 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.LowerHzEditFieldLabel_2 = uilabel(app.NaturalFrequenciesPanel);
            app.LowerHzEditFieldLabel_2.Text = 'Lower (Hz)';
            app.EditField_32 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.UpperHzEditFieldLabel_2 = uilabel(app.NaturalFrequenciesPanel);
            app.UpperHzEditFieldLabel_2.Text = 'Upper (Hz)';
            app.EditField_33 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_34 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_35 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_36 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_37 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_38 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_39 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_40 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_41 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.EditField_42 = uieditfield(app.NaturalFrequenciesPanel, 'numeric');
            app.StaticDeflectionsPanel = uipanel(app.ConstraintsTab_2);
            app.StaticDeflectionsPanel.Title = 'Static ِDeflections';
            app.LongitudinalLabel_2 = uilabel(app.StaticDeflectionsPanel);
            app.LongitudinalLabel_2.Text = 'Longitudinal';
            app.mmLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.mmLabel_3.Text = 'mm';
            app.LateralLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.LateralLabel_3.Text = 'Lateral';
            app.mmLabel_4 = uilabel(app.StaticDeflectionsPanel);
            app.mmLabel_4.Text = 'mm';
            app.BounceLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.BounceLabel_3.Text = 'Bounce';
            app.mmLabel_5 = uilabel(app.StaticDeflectionsPanel);
            app.mmLabel_5.Text = 'mm';
            app.RollLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.RollLabel_3.Text = 'Roll';
            app.degLabel_4 = uilabel(app.StaticDeflectionsPanel);
            app.degLabel_4.Text = 'deg';
            app.PitchLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.PitchLabel_3.Text = 'Pitch';
            app.degLabel_5 = uilabel(app.StaticDeflectionsPanel);
            app.degLabel_5.Text = 'deg';
            app.YawLabel_3 = uilabel(app.StaticDeflectionsPanel);
            app.YawLabel_3.Text = 'Yaw';
            app.degLabel_6 = uilabel(app.StaticDeflectionsPanel);
            app.degLabel_6.Text = 'deg';
            app.EditField_25 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_26 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_27 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_28 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_29 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.EditField_30 = uieditfield(app.StaticDeflectionsPanel, 'numeric');
            app.BoundsTab = uitab(app.TabGroup);
            app.BoundsTab.Title = 'Bounds';
            app.panel_mount1e = uipanel(app.BoundsTab);
            app.panel_mount1e.Title = 'Mount 1 (Elastomeric)';
            app.Label17 = uilabel(app.panel_mount1e);
            app.Label17.Text = '%';
            app.edit_mount1proeupLabel = uilabel(app.panel_mount1e);
            app.edit_mount1proeupLabel.Text = 'Upper Bound:';
            app.edit_mount1stiff_ub = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1stiff_ub.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel = uilabel(app.panel_mount1e);
            app.edit_mount1proelowLabel.Text = 'Lower Bound:';
            app.edit_mount1stiff_lb = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1stiff_lb.Value = '[10, 30, 20]';
            app.Label17_2 = uilabel(app.panel_mount1e);
            app.Label17_2.Text = '%';
            app.StiffnessLabel = uilabel(app.panel_mount1e);
            app.StiffnessLabel.Text = 'Stiffness';
            app.edit_mount1proeupLabel_2 = uilabel(app.panel_mount1e);
            app.edit_mount1proeupLabel_2.Text = 'Upper Bound:';
            app.edit_mount1damp_ub = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1damp_ub.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_2 = uilabel(app.panel_mount1e);
            app.edit_mount1proelowLabel_2.Text = 'Lower Bound:';
            app.edit_mount1damp_lb = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1damp_lb.Value = '[10, 30, 20]';
            app.DampingLabel = uilabel(app.panel_mount1e);
            app.DampingLabel.Text = 'Damping';
            app.Label17_5 = uilabel(app.panel_mount1e);
            app.Label17_5.Text = '%';
            app.Label17_6 = uilabel(app.panel_mount1e);
            app.Label17_6.Text = '%';
            app.LocationLabel = uilabel(app.panel_mount1e);
            app.LocationLabel.Text = 'Location';
            app.OrientationLabel = uilabel(app.panel_mount1e);
            app.OrientationLabel.Text = 'Orientation';
            app.edit_mount1locupLabel = uilabel(app.panel_mount1e);
            app.edit_mount1locupLabel.Text = 'Upper Bound:';
            app.edit_mount1locup = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1locup.Value = '[50,50,50]';
            app.edit_mount1loclowLabel = uilabel(app.panel_mount1e);
            app.edit_mount1loclowLabel.Text = 'Lower Bound:';
            app.edit_mount1loclow = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1loclow.Value = '[-50,-50,-50]';
            app.edit_mount1locupLabel_2 = uilabel(app.panel_mount1e);
            app.edit_mount1locupLabel_2.Text = 'Upper Bound:';
            app.edit_mount1oriup = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1oriup.Value = '[50,50,50]';
            app.edit_mount1loclowLabel_2 = uilabel(app.panel_mount1e);
            app.edit_mount1loclowLabel_2.Text = 'Lower Bound:';
            app.edit_mount1orilow = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1orilow.Value = '[-50,-50,-50]';
            app.Label17_7 = uilabel(app.panel_mount1e);
            app.Label17_7.Text = '%';
            app.Label17_8 = uilabel(app.panel_mount1e);
            app.Label17_8.Text = '%';
            app.Label17_9 = uilabel(app.panel_mount1e);
            app.Label17_9.Text = '%';
            app.Label17_10 = uilabel(app.panel_mount1e);
            app.Label17_10.Text = '%';
            app.panel_mount1e_2 = uipanel(app.BoundsTab);
            app.panel_mount1e_2.Title = 'Mount 2 (Elastomeric)';
            app.Label17_11 = uilabel(app.panel_mount1e_2);
            app.Label17_11.Text = '%';
            app.edit_mount1proeupLabel_3 = uilabel(app.panel_mount1e_2);
            app.edit_mount1proeupLabel_3.Text = 'Upper Bound:';
            app.edit_mount2stiff_ub = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2stiff_ub.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_3 = uilabel(app.panel_mount1e_2);
            app.edit_mount1proelowLabel_3.Text = 'Lower Bound:';
            app.edit_mount2stiff_lb = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2stiff_lb.Value = '[10, 30, 20]';
            app.Label17_12 = uilabel(app.panel_mount1e_2);
            app.Label17_12.Text = '%';
            app.StiffnessLabel_2 = uilabel(app.panel_mount1e_2);
            app.StiffnessLabel_2.Text = 'Stiffness';
            app.edit_mount1proeupLabel_4 = uilabel(app.panel_mount1e_2);
            app.edit_mount1proeupLabel_4.Text = 'Upper Bound:';
            app.edit_mount2damp_ub = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2damp_ub.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_4 = uilabel(app.panel_mount1e_2);
            app.edit_mount1proelowLabel_4.Text = 'Lower Bound:';
            app.edit_mount2damp_lb = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2damp_lb.Value = '[10, 30, 20]';
            app.DampingLabel_2 = uilabel(app.panel_mount1e_2);
            app.DampingLabel_2.Text = 'Damping';
            app.Label17_13 = uilabel(app.panel_mount1e_2);
            app.Label17_13.Text = '%';
            app.Label17_14 = uilabel(app.panel_mount1e_2);
            app.Label17_14.Text = '%';
            app.LocationLabel_2 = uilabel(app.panel_mount1e_2);
            app.LocationLabel_2.Text = 'Location';
            app.OrientationLabel_2 = uilabel(app.panel_mount1e_2);
            app.OrientationLabel_2.Text = 'Orientation';
            app.edit_mount1locupLabel_3 = uilabel(app.panel_mount1e_2);
            app.edit_mount1locupLabel_3.Text = 'Upper Bound:';
            app.edit_mount2locup = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2locup.Value = '[50,50,50]';
            app.edit_mount1loclowLabel_3 = uilabel(app.panel_mount1e_2);
            app.edit_mount1loclowLabel_3.Text = 'Lower Bound:';
            app.edit_mount2loclow = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2loclow.Value = '[-50,-50,-50]';
            app.edit_mount1locupLabel_4 = uilabel(app.panel_mount1e_2);
            app.edit_mount1locupLabel_4.Text = 'Upper Bound:';
            app.edit_mount2oriup = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2oriup.Value = '[50,50,50]';
            app.edit_mount1loclowLabel_4 = uilabel(app.panel_mount1e_2);
            app.edit_mount1loclowLabel_4.Text = 'Lower Bound:';
            app.edit_mount2orilow = uieditfield(app.panel_mount1e_2, 'text');
            app.edit_mount2orilow.Value = '[-50,-50,-50]';
            app.Label17_15 = uilabel(app.panel_mount1e_2);
            app.Label17_15.Text = '%';
            app.Label17_16 = uilabel(app.panel_mount1e_2);
            app.Label17_16.Text = '%';
            app.Label17_17 = uilabel(app.panel_mount1e_2);
            app.Label17_17.Text = '%';
            app.Label17_18 = uilabel(app.panel_mount1e_2);
            app.Label17_18.Text = '%';
            app.panel_mount1e_3 = uipanel(app.BoundsTab);
            app.panel_mount1e_3.Title = 'Mount 3 (Elastomeric)';
            app.Label17_19 = uilabel(app.panel_mount1e_3);
            app.Label17_19.Text = '%';
            app.edit_mount1proeupLabel_5 = uilabel(app.panel_mount1e_3);
            app.edit_mount1proeupLabel_5.Text = 'Upper Bound:';
            app.edit_mount3stiff_ub = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3stiff_ub.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_5 = uilabel(app.panel_mount1e_3);
            app.edit_mount1proelowLabel_5.Text = 'Lower Bound:';
            app.edit_mount3stiff_lb = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3stiff_lb.Value = '[10, 30, 20]';
            app.Label17_20 = uilabel(app.panel_mount1e_3);
            app.Label17_20.Text = '%';
            app.StiffnessLabel_3 = uilabel(app.panel_mount1e_3);
            app.StiffnessLabel_3.Text = 'Stiffness';
            app.edit_mount1proeupLabel_6 = uilabel(app.panel_mount1e_3);
            app.edit_mount1proeupLabel_6.Text = 'Upper Bound:';
            app.edit_mount3damp_ub = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3damp_ub.Value = '[30, 50, 40]';
            app.edit_mount1proelowLabel_6 = uilabel(app.panel_mount1e_3);
            app.edit_mount1proelowLabel_6.Text = 'Lower Bound:';
            app.edit_mount3damp_lb = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3damp_lb.Value = '[10, 30, 20]';
            app.DampingLabel_3 = uilabel(app.panel_mount1e_3);
            app.DampingLabel_3.Text = 'Damping';
            app.Label17_21 = uilabel(app.panel_mount1e_3);
            app.Label17_21.Text = '%';
            app.Label17_22 = uilabel(app.panel_mount1e_3);
            app.Label17_22.Text = '%';
            app.LocationLabel_3 = uilabel(app.panel_mount1e_3);
            app.LocationLabel_3.Text = 'Location';
            app.OrientationLabel_3 = uilabel(app.panel_mount1e_3);
            app.OrientationLabel_3.Text = 'Orientation';
            app.edit_mount1locupLabel_5 = uilabel(app.panel_mount1e_3);
            app.edit_mount1locupLabel_5.Text = 'Upper Bound:';
            app.edit_mount3locup = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3locup.Value = '[50,50,50]';
            app.edit_mount1loclowLabel_5 = uilabel(app.panel_mount1e_3);
            app.edit_mount1loclowLabel_5.Text = 'Lower Bound:';
            app.edit_mount3loclow = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3loclow.Value = '[-50,-50,-50]';
            app.edit_mount1locupLabel_6 = uilabel(app.panel_mount1e_3);
            app.edit_mount1locupLabel_6.Text = 'Upper Bound:';
            app.edit_mount3oriup = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3oriup.Value = '[50,50,50]';
            app.edit_mount1loclowLabel_6 = uilabel(app.panel_mount1e_3);
            app.edit_mount1loclowLabel_6.Text = 'Lower Bound:';
            app.edit_mount3orilow = uieditfield(app.panel_mount1e_3, 'text');
            app.edit_mount3orilow.Value = '[-50,-50,-50]';
            app.Label17_23 = uilabel(app.panel_mount1e_3);
            app.Label17_23.Text = '%';
            app.Label17_24 = uilabel(app.panel_mount1e_3);
            app.Label17_24.Text = '%';
            app.Label17_25 = uilabel(app.panel_mount1e_3);
            app.Label17_25.Text = '%';
            app.Label17_26 = uilabel(app.panel_mount1e_3);
            app.Label17_26.Text = '%';
            app.CloseButton = uibutton(app.OptimizationUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.OptimizationUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CreateNewStageLabel = uilabel(app.OptimizationUIFigure);
            app.CreateNewStageLabel.Text = 'Create New Stage';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_optimization2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptimizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptimizationUIFigure)
        end
    end
end