classdef gui_pre < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SelectResultsTab                matlab.ui.container.Tab
        AvailableinputfilesListBoxLabel  matlab.ui.control.Label
        AvailableinputfilesListBox      matlab.ui.control.ListBox
        AddFileButton                   matlab.ui.control.Button
        SelectedinputfilesListBoxLabel  matlab.ui.control.Label
        SelectedinputfilesListBox       matlab.ui.control.ListBox
        RefreshButton                   matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        MountForcesButton               matlab.ui.control.Button
        KEDButton                       matlab.ui.control.Button
        FrequencyButton                 matlab.ui.control.Button
        MountLocationsButton            matlab.ui.control.Button
        MountStiffnessButton            matlab.ui.control.Button
        PreprocessButton                matlab.ui.control.Button
        FFTButton                       matlab.ui.control.Button
        ValidationTab                   matlab.ui.container.Tab
        Mount1Panel_2                   matlab.ui.container.Panel
        PositionXCheckBox               matlab.ui.control.CheckBox
        PositionYCheckBox               matlab.ui.control.CheckBox
        PositionZCheckBox               matlab.ui.control.CheckBox
        OrientationXCheckBox            matlab.ui.control.CheckBox
        OrientationYCheckBox            matlab.ui.control.CheckBox
        OrientationZCheckBox            matlab.ui.control.CheckBox
        StiffnessZCheckBox              matlab.ui.control.CheckBox
        StiffnessYCheckBox              matlab.ui.control.CheckBox
        StiffnessXCheckBox              matlab.ui.control.CheckBox
        Mount2Panel                     matlab.ui.container.Panel
        PositionXCheckBox_2             matlab.ui.control.CheckBox
        PositionYCheckBox_2             matlab.ui.control.CheckBox
        PositionZCheckBox_2             matlab.ui.control.CheckBox
        OrientationXCheckBox_2          matlab.ui.control.CheckBox
        OrientationYCheckBox_2          matlab.ui.control.CheckBox
        OrientationZCheckBox_2          matlab.ui.control.CheckBox
        StiffnessZCheckBox_2            matlab.ui.control.CheckBox
        StiffnessYCheckBox_2            matlab.ui.control.CheckBox
        StiffnessXCheckBox_2            matlab.ui.control.CheckBox
        Mount3Panel                     matlab.ui.container.Panel
        PositionXCheckBox_3             matlab.ui.control.CheckBox
        PositionYCheckBox_3             matlab.ui.control.CheckBox
        PositionZCheckBox_3             matlab.ui.control.CheckBox
        OrientationXCheckBox_3          matlab.ui.control.CheckBox
        OrientationYCheckBox_3          matlab.ui.control.CheckBox
        OrientationZCheckBox_3          matlab.ui.control.CheckBox
        StiffnessZCheckBox_3            matlab.ui.control.CheckBox
        StiffnessYCheckBox_3            matlab.ui.control.CheckBox
        StiffnessXCheckBox_3            matlab.ui.control.CheckBox
        PurturbButton                   matlab.ui.control.Button
        inthissectionyoucanplotLabel    matlab.ui.control.Label
        SelectvaluestobepurturbedLabel  matlab.ui.control.Label
        StaticTestsTab                  matlab.ui.container.Tab
        StaticTestsPanel                matlab.ui.container.Panel
        UITable_test                    matlab.ui.control.Table
        EnterStaticAcceleraioningLabel  matlab.ui.control.Label
        StaticTestsButton_2             matlab.ui.control.Button
        PreProcessLabel                 matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        CGDisplacementsButton           matlab.ui.control.Button
        StaticDeflectionsButton         matlab.ui.control.Button
        StaticTestsButton               matlab.ui.control.Button
        SelectedEditFieldLabel          matlab.ui.control.Label
        SelectedEditField               matlab.ui.control.EditField
    end


    properties (Access = private)
         % Description
         
         
    end

    properties (Access = public)
        n=0;%number of selected results
        items % cell array of selected items
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            RefreshButtonPushed(app);


        end

        % Button pushed function: AddFileButton
        function AddFileButtonPushed(app, event)
            app.n=app.n+1;
            app.SelectedinputfilesListBox.Items(app.n)= {app.AvailableinputfilesListBox.Value};
            app.items = app.SelectedinputfilesListBox.Items;
        end

        % Value changed function: AvailableinputfilesListBox
        function AvailableinputfilesListBoxValueChanged(app, event)


        end

        % Callback function
        function CompareButtonPushed(app, event)
            
            k=size(app.items);
            figure; hold on;
            for i=1:k(2)
               
               load(['SavedResults/',[app.items{i}(1:end-8),'_pre.mat']]);
               h = eval([app.items{i}(1:end-8),'_pre']);
               
               name = app.items{i}(1:end-8);
               
               for i=1:h.N
                   subplot(2,2,1)
                   plot(i,h.stage(i).results.val_TRA,'o')
                   xlim([0 3])
                   
                   subplot(2,2,2)
                   plot(i,h.stage(i).results.val_TF,'o')
                   xlim([0 3])
                   asq = h.stage(i).results.val_TF
                   subplot(2,2,3)
                   plot(i,h.stage(i).results.val_TA,'o')
                   xlim([0 3])
                   
                   subplot(2,2,4)
                   plot(i,h.stage(i).results.val_Ar,'o')
                   xlim([0 3])


               end
                
            end
            
        end

        % Callback function
        function ComparisonButtonPushed(app, event)
            figure; hold on;
            k=size(app.items);
            y = zeros(36,k);
            for i=1:k(2)
               y(:,i) = h.opt.x(19:22);
               load(['SavedResults/',[app.items{i}(1:end-8),'_pre.mat']]);
               h = eval([app.items{i}(1:end-8),'_pre']);
               
               y(:,i) = h.opt.x(1:36);
               name = app.items{i}(1:end-8);
               ax.Name = [name,' >> Variation vs Results'];
               value = app.SelectedResultsListBox_2.Value;
               switch value
                   case 'Mount 1 : Stiffness'
                      plot(1:k,y(19:22,:));     
                   case 'Mount 2 : Stiffness'
                      plot(1:k,y(22:24,:)); 
                   case 'Mount 3 : Stiffness'
                      plot(1:k,y(25:27,:));
               end
            
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.SelectedinputfilesListBox.Items={''}
            
           app.n=0
        end

        % Button pushed function: FrequencyButton
        function FrequencyButtonPushed(app, event)
            
            
            k=size(app.items);
            
            for i=1:k(2)
              
               load(['SavedResults/',[app.items{i}(1:end-8),'_pre.mat']]);
               h = eval([app.items{i}(1:end-8),'_pre']);
               
               name = app.items{i}(1:end-8);
               
               
               gui_plot_freq(h,name);
                
            end
            
        end

        % Button pushed function: KEDButton
        function KEDButtonPushed(app, event)
            %KED figure
            k=size(app.items);
            
            for i=1:k(2)
               
               load(['SavedResults/',[app.items{i}(1:end-8),'_pre.mat']]);
               h = eval([app.items{i}(1:end-8),'_pre']);
               
               name = app.items{i}(1:end-8);
               
               
               gui_plot_KED(h,name);
                
            end
        end

        % Button pushed function: MountForcesButton
        function MountForcesButtonPushed(app, event)
            
            k=size(app.items);
            for i=1:k(2)
              load(['SavedResults/',[app.items{i}(1:end-8),'_pre.mat']]);
               h = eval([app.items{i}(1:end-8),'_pre']);
               
               name = app.items{i}(1:end-8);
               gui_plot_force(h,name);
                
            end
        end

        % Button pushed function: MountLocationsButton
        function MountLocationsButtonPushed(app, event)


            k=size(app.items);
            leg = "Car Body";    %plot legend
            for i=1:k(2)
               
            
               load(['SavedResults/',[app.items{i}(1:end-8),'_pre.mat']]);
               h = eval([app.items{i}(1:end-8),'_pre']);
               
               figure(11);    hold on %figure of mount locations
            gui_plot_body;
               name = app.items{i}(1:end-8);
            %                plot_legend=[plot_legend;app.items{i}(1:end-4)];
               gui_plot_loc(h,name);
                               
            end
            [q_TRA,~] = TRA_finder(h.eng.inertia,[0;1;0]); % finding TRA axis
            q_TRA = [0;0;0;q_TRA];
            %             TRA_plotter(q_TRA,h.sus.E_cm);
            E_cm = h.sus.E_cm .* [-1 1 1];
                 handle = line([-600*q_TRA(4)+E_cm(1),600*q_TRA(4)+E_cm(1)],...
            [-600*q_TRA(5)+E_cm(2),600*q_TRA(5)+E_cm(2)],...
            [-600*q_TRA(6)+E_cm(3),600*q_TRA(6)+E_cm(3)],'Color','green','DisplayName','TRA axis')
            text(600*q_TRA(4)+E_cm(1),600*q_TRA(5)+E_cm(2),600*q_TRA(6)+E_cm(3),'TRA','color','k')
            %         legend(handle,"TRA axis");
            %             leg = [leg;"TRA axis"];
            legend show
                  
            
            %             legend(leg);
            %             loc_vars = {'r1_x';'r1_y';'r1_z';...
            %                         'r2_x';'r2_y';'r2_z';...
            %                         'r3_x';'r3_y';'r3_z';...
            %                         'o1_x';'o1_y';'o1_z';...
            %                         'o2_x';'o2_y';'o2_z';...
            %                         'o3_x';'o3_y';'o3_z'};
            %                     
            %             %             loc_dep = num2cell(zeros(18,1));
            %             %             loc_purt1 = num2cell(h.stage1.purt(1:18));
            %             %             loc_lb1 = num2cell(zeros(18,1));
            %             %             loc_ub1 = num2cell(zeros(18,1));
            %             %             loc_init1 = num2cell(zeros(18,1));
            %             %             loc_opt1 = num2cell(zeros(18,1));
            %             for i=1:18
            %                if h.stage0.t2(i)==1 %indepenent
            %                    loc_dep(i)={'False'};
            %                else
            %                    loc_dep(i)={'True'};
            %                end
            %                if h.stage1.F(i)==1     %not opt variable
            %                    loc_lb1(i)={'---'};
            %                    loc_ub1(i)={'---'};
            %                else
            %                    loc_lb1(i)={h.stage1.lb(i)};
            %                    loc_ub1(i)={h.stage1.ub(i)};
            %                end
            %                loc_init1(i) = {h.stage1.init.x(i)};
            %                loc_opt1(i) = {h.stage1.opt.x(i)};
            %             end
            %             
            %             app.UITableLoc1.Data = [loc_vars,loc_dep,loc_purt1,loc_ub1,loc_lb1,loc_init1,loc_opt1];
            %             
            %             % STAGE 2
            %             if strcmp(h.stage2.type,'None')==0
            %             loc_purt1 = num2cell(h.stage2.purt(1:18));loc_purt2 = num2cell(zeros(18,1));loc_purt3 = num2cell(zeros(18,1));
            %             loc_lb1 = num2cell(zeros(18,1));loc_lb2 = num2cell(zeros(18,1));loc_lb3 = num2cell(zeros(18,1));
            %             loc_ub1 = num2cell(zeros(18,1));loc_ub2 = num2cell(zeros(18,1));loc_ub3 = num2cell(zeros(18,1));
            %             loc_init1 = num2cell(zeros(18,1));loc_init2 = num2cell(zeros(18,1));loc_init3 = num2cell(zeros(18,1));
            %             loc_opt1 = num2cell(zeros(18,1));loc_opt2 = num2cell(zeros(18,1));loc_opt3 = num2cell(zeros(18,1));
            %             for i=1:18
            %             %                if h.stage0.t2(i)==1 %indepenent
            %             %                    loc_dep(i)={'False'};
            %             %                else
            %             %                    loc_dep(i)={'True'};
            %             %                end
            %                if h.stage2.F(i)==1     %not opt variable
            %                    loc_lb1(i)={'---'};
            %                    loc_ub1(i)={'---'};
            %                else
            %                    loc_lb1(i)={h.stage2.lb(i)};
            %                    loc_ub1(i)={h.stage2.ub(i)};
            %                end
            %                loc_init1(i) = {h.stage2.init.x(i)};
            %                loc_opt1(i) = {h.stage2.opt.x(i)};
            %             end
            %             
            %             app.UITableLoc2.Data = [loc_vars,loc_dep,loc_purt1,loc_ub1,loc_lb1,loc_init1,loc_opt1];
            %             end
            %             % STAGE 3
            %             if strcmp(h.stage3.type,'None')==0
            %             loc_purt1 = num2cell(h.stage3.purt(1:18));loc_purt2 = num2cell(zeros(18,1));loc_purt3 = num2cell(zeros(18,1));
            %             loc_lb1 = num2cell(zeros(18,1));loc_lb2 = num2cell(zeros(18,1));loc_lb3 = num2cell(zeros(18,1));
            %             loc_ub1 = num2cell(zeros(18,1));loc_ub2 = num2cell(zeros(18,1));loc_ub3 = num2cell(zeros(18,1));
            %             loc_init1 = num2cell(zeros(18,1));loc_init2 = num2cell(zeros(18,1));loc_init3 = num2cell(zeros(18,1));
            %             loc_opt1 = num2cell(zeros(18,1));loc_opt2 = num2cell(zeros(18,1));loc_opt3 = num2cell(zeros(18,1));
            %             for i=1:18
            %             %                if h.stage0.t2(i)==1 %indepenent
            %             %                    loc_dep(i)={'False'};
            %             %                else
            %             %                    loc_dep(i)={'True'};
            %             %                end
            %                if h.stage3.F(i)==1     %not opt variable
            %                    loc_lb1(i)={'---'};
            %                    loc_ub1(i)={'---'};
            %                else
            %                    loc_lb1(i)={h.stage3.lb(i)};
            %                    loc_ub1(i)={h.stage3.ub(i)};
            %                end
            %                loc_init1(i) = {h.stage3.init.x(i)};
            %                loc_opt1(i) = {h.stage3.opt.x(i)};
            %             end
            %             
            %             app.UITableLoc3.Data = [loc_vars,loc_dep,loc_purt1,loc_ub1,loc_lb1,loc_init1,loc_opt1];
            %             end
        end

        % Button pushed function: MountStiffnessButton
        function MountStiffnessButtonPushed(app, event)
            
           
            k=size(app.items);
            for i=1:k(2)
               ss=app.items{i}
               load(['SavedResults/',[app.items{i}(1:end-8),'_pre.mat']]);
               h = eval([app.items{i}(1:end-8),'_pre']);
               
               name = app.items{i}(1:end-8);
            %                ax.Name = [name,' >> Stiffness'];
               gui_plot_stiff(h,name);
               
            
            end
            
            %                  loc_vars = {'k1_x';'k1_y';'k1_z';...
            %                         'k2_x';'k2_y';'k2_z';...
            %                         'k3_x';'k3_y';'k3_z';...
            %                         'c1_x';'c1_y';'c1_z';...
            %                         'c2_x';'c2_y';'c2_z';...
            %                         'c3_x';'c3_y';'c3_z'};
            %                     
            %             loc_dep = num2cell(zeros(18,1));
            %             loc_purt1 = num2cell(h.stage1.purt(19:36));
            %             loc_lb1 = num2cell(zeros(18,1));
            %             loc_ub1 = num2cell(zeros(18,1));
            %             loc_init1 = num2cell(zeros(18,1));
            %             loc_opt1 = num2cell(zeros(18,1));
            %             for i=19:36
            %                if h.stage0.t2(i)==1 %indepenent
            %                    loc_dep(i-18)={'False'};
            %                else
            %                    loc_dep(i-18)={'True'};
            %                end
            %                if h.stage1.F(i)==1     %not opt variable
            %                    loc_lb1(i-18)={'---'};
            %                    loc_ub1(i-18)={'---'};
            %                else
            %                    loc_lb1(i-18)={h.stage1.lb(i)};
            %                    loc_ub1(i-18)={h.stage1.ub(i)};
            %                end
            %                loc_init1(i-18) = {h.stage1.init.x(i)};
            %                loc_opt1(i-18) = {h.stage1.opt.x(i)};
            %             end
            %             
            %             app.UITableStiff1.Data = [loc_vars,loc_dep,loc_purt1,loc_ub1,loc_lb1,loc_init1,loc_opt1];
            %             
            %             % STAGE 2
            %             if strcmp(h.stage2.type,'None')==0
            %             loc_purt1 = num2cell(h.stage2.purt(19:36));loc_purt2 = num2cell(zeros(18,1));loc_purt3 = num2cell(zeros(18,1));
            %             loc_lb1 = num2cell(zeros(18,1));loc_lb2 = num2cell(zeros(18,1));loc_lb3 = num2cell(zeros(18,1));
            %             loc_ub1 = num2cell(zeros(18,1));loc_ub2 = num2cell(zeros(18,1));loc_ub3 = num2cell(zeros(18,1));
            %             loc_init1 = num2cell(zeros(18,1));loc_init2 = num2cell(zeros(18,1));loc_init3 = num2cell(zeros(18,1));
            %             loc_opt1 = num2cell(zeros(18,1));loc_opt2 = num2cell(zeros(18,1));loc_opt3 = num2cell(zeros(18,1));
            %             for i=19:36
            %             %                if h.stage0.t2(i)==1 %indepenent
            %             %                    loc_dep(i)={'False'};
            %             %                else
            %             %                    loc_dep(i)={'True'};
            %             %                end
            %                if h.stage2.F(i)==1     %not opt variable
            %                    loc_lb1(i-18)={'---'};
            %                    loc_ub1(i-18)={'---'};
            %                else
            %                    loc_lb1(i-18)={h.stage2.lb(i)};
            %                    loc_ub1(i-18)={h.stage2.ub(i)};
            %                end
            %                loc_init1(i-18) = {h.stage2.init.x(i)};
            %                loc_opt1(i-18) = {h.stage2.opt.x(i)};
            %             end
            %             
            %             app.UITableStiff2.Data = [loc_vars,loc_dep,loc_purt1,loc_ub1,loc_lb1,loc_init1,loc_opt1];
            %             end
            %             % STAGE 3
            %             if strcmp(h.stage3.type,'None')==0
            %             loc_purt1 = num2cell(h.stage3.purt(19:36));loc_purt2 = num2cell(zeros(18,1));loc_purt3 = num2cell(zeros(18,1));
            %             loc_lb1 = num2cell(zeros(18,1));loc_lb2 = num2cell(zeros(18,1));loc_lb3 = num2cell(zeros(18,1));
            %             loc_ub1 = num2cell(zeros(18,1));loc_ub2 = num2cell(zeros(18,1));loc_ub3 = num2cell(zeros(18,1));
            %             loc_init1 = num2cell(zeros(18,1));loc_init2 = num2cell(zeros(18,1));loc_init3 = num2cell(zeros(18,1));
            %             loc_opt1 = num2cell(zeros(18,1));loc_opt2 = num2cell(zeros(18,1));loc_opt3 = num2cell(zeros(18,1));
            %             for i=19:36
            %             %                if h.stage0.t2(i)==1 %indepenent
            %             %                    loc_dep(i)={'False'};
            %             %                else
            %             %                    loc_dep(i)={'True'};
            %             %                end
            %                if h.stage3.F(i)==1     %not opt variable
            %                    loc_lb1(i-18)={'---'};
            %                    loc_ub1(i-18)={'---'};
            %                else
            %                    loc_lb1(i-18)={h.stage3.lb(i)};
            %                    loc_ub1(i-18)={h.stage3.ub(i)};
            %                end
            %                loc_init1(i-18) = {h.stage3.init.x(i)};
            %                loc_opt1(i-18) = {h.stage3.opt.x(i)};
            %             end
            %             
            %             app.UITableStiff3.Data = [loc_vars,loc_dep,loc_purt1,loc_ub1,loc_lb1,loc_init1,loc_opt1];
            %             end
            
        end

        % Button pushed function: PreprocessButton
        function PreprocessButtonPushed(app, event)
           k=size(app.items);
            for i=1:k(2)
              
               load(['SavedResults/',app.items{i}]);
               
               g = eval(app.items{i}(1:end-4));
                load(['SavedResults/stg_pre']);    
        h.N = 0;
        h = read_mat(h,g,TRA,1);


        h.stage0.results = Result_Calc(h.stage0,h.eng);
            
        pre=save_mat(h);
        
        eval([app.items{i}(1:end-8),'_pre', '= pre']);
        save(['SavedResults/',[app.items{i}(1:end-8),'_pre.mat']],[app.items{i}(1:end-8),'_pre']);
        clear 'h'


            end
            msgbox('Done PreProcessing!');
           
        end

        % Button pushed function: PurturbButton
        function PurturbButtonPushed(app, event)
            status = [app.PositionXCheckBox.Value;...
                      app.PositionYCheckBox.Value;...
                      app.PositionZCheckBox.Value;...
                      app.PositionXCheckBox_2.Value;...
                      app.PositionYCheckBox_2.Value;...
                      app.PositionZCheckBox_2.Value;...
                      app.PositionXCheckBox_3.Value;...
                      app.PositionYCheckBox_3.Value;...
                      app.PositionZCheckBox_3.Value;...
                      app.OrientationXCheckBox.Value;...
                      app.OrientationYCheckBox.Value;...
                      app.OrientationZCheckBox.Value;...
                      app.OrientationXCheckBox_2.Value;...
                      app.OrientationYCheckBox_2.Value;...
                      app.OrientationZCheckBox_2.Value;...
                      app.OrientationXCheckBox_3.Value;...
                      app.OrientationYCheckBox_3.Value;...
                      app.OrientationZCheckBox_3.Value;...
                      app.StiffnessXCheckBox.Value;...
                      app.StiffnessYCheckBox.Value;...
                      app.StiffnessZCheckBox.Value;...
                      app.StiffnessXCheckBox_2.Value;...
                      app.StiffnessYCheckBox_2.Value;...
                      app.StiffnessZCheckBox_2.Value;...
                      app.StiffnessXCheckBox_3.Value;...
                      app.StiffnessYCheckBox_3.Value;...
                      app.StiffnessZCheckBox_3.Value];
          F = find(status==1);
          if length(F)==0
              return;
          end
          if length(F)==1
              k=size(app.items);
            for i=1:k(2)
               load(['SavedResults/',app.items{i}]);
               h = eval(app.items{i}(1:end-4));
               name = app.items{i}(1:end-8);
               ax=figure;    hold on;
               
               ax.Name = [name,' >> 2D Deviation'];
            
               lb_x = h.stage(h.N).lb(F(1));
               ub_x = h.stage(h.N).ub(F(1));
            %                x_init = h.stage(h.N).x_init;
                T = h.stage(h.N).T;
                T1 = h.stage(h.N).T1;
                F1 = ones(41,1);
                x_opt = h.stage(h.N).x_opt;
                option = h.stage(h.N).option;
                x = h.stage(h.N).x_opt(F(1));
                
                y = obj_TRA(zeros(41,1),eye(41),F1,x_opt,zeros(41,1), option.Mass, option.FreqLowerBound, option.FreqUpperBound, 1, 0, 0);
                        plot(x,y,'ro');
               for c1=1:60
                   
                       x = lb_x + rand * (ub_x-lb_x);
                       
                       x_opt(F(1))=x;
                       
                       y = obj_TRA(zeros(41,1),eye(41),F1,x_opt,zeros(41,1) ,option.Mass, option.FreqLowerBound, option.FreqUpperBound, 1, 0, 0);
                        plot(x,y,'b.');
                        
                  
               end
               xlabel(['parameter : ',num2str(F(1))]);
               ylabel('TRA value');
               h = gcf; %current figure handle
               axesObjs = get(h, 'Children');  %axes handles
               dataObjs = get(axesObjs, 'Children'); %handles to low-level graphics objects in axes
               objTypes = get(dataObjs, 'Type');  %type of low-level graphics object
               
               xdata = cell2mat(get(dataObjs, 'XData'));  %data from low-level grahics objects
               ydata = cell2mat(get(dataObjs, 'YData'));
                
               
               xq=lb_x:lb_x/100:ub_x;
               vq = interp1(xdata,ydata,xq);
               size(vq)
            %                vq = griddata(xdata,ydata,zdata,xq,yq);
               plot(xq,vq,'-')
            end     
              
          end
            if length(F)==2  
          k=size(app.items);
            for i=1:k(2)
               load(['SavedResults/',app.items{i}]);
               h = eval(app.items{i}(1:end-4));
               name = app.items{i}(1:end-8);
               ax=figure;    hold on;
               
               ax.Name = [name,' >> 3D Deviation'];
            
               lb_x = h.stage(h.N).lb(F(1));
               ub_x = h.stage(h.N).ub(F(1));
               lb_y = h.stage(h.N).lb(F(2));
               ub_y = h.stage(h.N).ub(F(2));
            %                x = lb_x + rand(1,20) * (ub_x-lb_x);
            %                y = lb_y + rand(1,20) * (ub_y-lb_y);
                
            %                x_init = h.stage(h.N).x_init;
                T = h.stage(h.N).T;
                T1 = [];
                F1 = ones(41,1);
                x_opt = h.stage(h.N).x_opt;
                option = h.stage(h.N).option;
                x = h.stage(h.N).x_opt(F(1));
                y = h.stage(h.N).x_opt(F(2));
                z = obj_TRA(zeros(41,1),eye(41),F1,x_opt,zeros(41,1), option.Mass, option.FreqLowerBound, option.FreqUpperBound, 1, 0, 0);
                        plot3(x,y,z,'ro');
               for c1=1:300
                   
                       x = lb_x + rand * (ub_x-lb_x);
                       y = lb_y + rand * (ub_y-lb_y);
                       x_opt(F(1))=x;
                       x_opt(F(2))=y;
                       ws = size(x_opt)
                       z = obj_TRA(zeros(41,1),eye(41),F1,x_opt,zeros(41,1), option.Mass, option.FreqLowerBound, option.FreqUpperBound, 1, 0, 0);
                        plot3(x,y,z,'b.');
                        
                  
               end
               xlabel(['parameter : ',num2str(F(1))]);
               ylabel(['parameter : ',num2str(F(2))]);
               zlabel('TRA value');
               h = gcf; %current figure handle
               axesObjs = get(h, 'Children');  %axes handles
               dataObjs = get(axesObjs, 'Children'); %handles to low-level graphics objects in axes
               objTypes = get(dataObjs, 'Type');  %type of low-level graphics object
               
               xdata = cell2mat(get(dataObjs, 'XData'));  %data from low-level grahics objects
               ydata = cell2mat(get(dataObjs, 'YData'));
               zdata = cell2mat(get(dataObjs, 'ZData')); 
               
               [xq,yq] = meshgrid(lb_x:abs(lb_x/100):ub_x,lb_y:abs(lb_x/100):ub_y);
               vq = griddata(xdata,ydata,zdata,xq,yq);
               mesh(xq,yq,vq)
                hold on
            %                 plot3(x,y,v,'o')
            end
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            mat_files=dir('SavedResults/*_inp.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.AvailableinputfilesListBox.Items(i)={mat_files(i).name};
                
            end
            app.AvailableinputfilesListBox.Value = app.AvailableinputfilesListBox.Items(1);
            
        end

        % Value changed function: SelectedinputfilesListBox
        function SelectedinputfilesListBoxValueChanged(app, event)
            
            app.items = app.SelectedinputfilesListBox.Items;
            if isempty(app.SelectedinputfilesListBox.Value)
                return;
            else
                app.SelectedEditField.Value = app.SelectedinputfilesListBox.Value;
            end
        end

        % Button pushed function: StaticDeflectionsButton
        function StaticDeflectionsButtonPushed(app, event)
            figure(5);    hold on; %static deflections figure
            
            k=size(app.items);
            plot_legend='';
            for i=1:k(2)


            end
        end

        % Button pushed function: StaticTestsButton_2
        function StaticTestsButton_2Pushed(app, event)
            if (isempty(app.SelectedinputfilesListBox.Value))
                return;
            end
            ['SavedResults/',app.SelectedEditField.Value(1:end-8),'_pre.mat']
            load(['SavedResults/',app.SelectedEditField.Value(1:end-8),'_pre.mat']);
            h = eval([app.SelectedinputfilesListBox.Value(1:end-8),'_pre']);


            tests = h.stage0.option.StaticTests;
             
            criteria = [zeros(1,4),h.stage(1).option.DeltaStatic'];    
            mat = cell2mat(tests(:,2:end));
            K = h.stage0.results.K;
            M = h.eng.M;
            mg = M(1,1)*9.81;
            
            
            stc_Mat = num2cell((abs((K(1:6,1:6)/1.5)^(-1))) * mg * [mat';zeros(3,size(mat,1))]);
            size(criteria)
            size(tests)
            size(stc_Mat)
            app.UITable_test.Data=[num2cell(criteria);tests,stc_Mat]            ;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectResultsTab = uitab(app.TabGroup);
            app.SelectResultsTab.Title = 'Select Results';
            app.AvailableinputfilesListBoxLabel = uilabel(app.SelectResultsTab);
            app.AvailableinputfilesListBoxLabel.Text = 'Available input files';
            app.AvailableinputfilesListBox = uilistbox(app.SelectResultsTab);
            app.AvailableinputfilesListBox.Items = {};
            app.AvailableinputfilesListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableinputfilesListBoxValueChanged, true);
            app.AvailableinputfilesListBox.Value = {};
            app.AddFileButton = uibutton(app.SelectResultsTab, 'push');
            app.AddFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddFileButtonPushed, true);
            app.AddFileButton.Text = 'Add File';
            app.SelectedinputfilesListBoxLabel = uilabel(app.SelectResultsTab);
            app.SelectedinputfilesListBoxLabel.Text = 'Selected input files';
            app.SelectedinputfilesListBox = uilistbox(app.SelectResultsTab);
            app.SelectedinputfilesListBox.Items = {};
            app.SelectedinputfilesListBox.ValueChangedFcn = createCallbackFcn(app, @SelectedinputfilesListBoxValueChanged, true);
            app.SelectedinputfilesListBox.Value = {};
            app.RefreshButton = uibutton(app.SelectResultsTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DeleteButton = uibutton(app.SelectResultsTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.MountForcesButton = uibutton(app.SelectResultsTab, 'push');
            app.MountForcesButton.ButtonPushedFcn = createCallbackFcn(app, @MountForcesButtonPushed, true);
            app.MountForcesButton.Text = 'Mount Forces';
            app.KEDButton = uibutton(app.SelectResultsTab, 'push');
            app.KEDButton.ButtonPushedFcn = createCallbackFcn(app, @KEDButtonPushed, true);
            app.KEDButton.Text = 'KED';
            app.FrequencyButton = uibutton(app.SelectResultsTab, 'push');
            app.FrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyButtonPushed, true);
            app.FrequencyButton.Text = 'Frequency';
            app.MountLocationsButton = uibutton(app.SelectResultsTab, 'push');
            app.MountLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @MountLocationsButtonPushed, true);
            app.MountLocationsButton.Text = 'Mount Locations';
            app.MountStiffnessButton = uibutton(app.SelectResultsTab, 'push');
            app.MountStiffnessButton.ButtonPushedFcn = createCallbackFcn(app, @MountStiffnessButtonPushed, true);
            app.MountStiffnessButton.Text = 'Mount Stiffness';
            app.PreprocessButton = uibutton(app.SelectResultsTab, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessButtonPushed, true);
            app.PreprocessButton.Text = 'Pre process';
            app.FFTButton = uibutton(app.SelectResultsTab, 'push');
            app.FFTButton.Text = 'FFT';
            app.ValidationTab = uitab(app.TabGroup);
            app.ValidationTab.Title = 'Validation';
            app.Mount1Panel_2 = uipanel(app.ValidationTab);
            app.Mount1Panel_2.Title = 'Mount 1';
            app.PositionXCheckBox = uicheckbox(app.Mount1Panel_2);
            app.PositionXCheckBox.Text = 'Position: X';
            app.PositionYCheckBox = uicheckbox(app.Mount1Panel_2);
            app.PositionYCheckBox.Text = 'Position: Y';
            app.PositionZCheckBox = uicheckbox(app.Mount1Panel_2);
            app.PositionZCheckBox.Text = 'Position: Z';
            app.OrientationXCheckBox = uicheckbox(app.Mount1Panel_2);
            app.OrientationXCheckBox.Text = 'Orientation : X';
            app.OrientationYCheckBox = uicheckbox(app.Mount1Panel_2);
            app.OrientationYCheckBox.Text = 'Orientation : Y';
            app.OrientationZCheckBox = uicheckbox(app.Mount1Panel_2);
            app.OrientationZCheckBox.Text = 'Orientation : Z';
            app.StiffnessZCheckBox = uicheckbox(app.Mount1Panel_2);
            app.StiffnessZCheckBox.Text = 'Stiffness : Z';
            app.StiffnessYCheckBox = uicheckbox(app.Mount1Panel_2);
            app.StiffnessYCheckBox.Text = 'Stiffness : Y';
            app.StiffnessXCheckBox = uicheckbox(app.Mount1Panel_2);
            app.StiffnessXCheckBox.Text = 'Stiffness : X';
            app.Mount2Panel = uipanel(app.ValidationTab);
            app.Mount2Panel.Title = 'Mount 2';
            app.PositionXCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.PositionXCheckBox_2.Text = 'Position: X';
            app.PositionYCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.PositionYCheckBox_2.Text = 'Position: Y';
            app.PositionZCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.PositionZCheckBox_2.Text = 'Position: Z';
            app.OrientationXCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.OrientationXCheckBox_2.Text = 'Orientation : X';
            app.OrientationYCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.OrientationYCheckBox_2.Text = 'Orientation : Y';
            app.OrientationZCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.OrientationZCheckBox_2.Text = 'Orientation : Z';
            app.StiffnessZCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.StiffnessZCheckBox_2.Text = 'Stiffness : Z';
            app.StiffnessYCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.StiffnessYCheckBox_2.Text = 'Stiffness : Y';
            app.StiffnessXCheckBox_2 = uicheckbox(app.Mount2Panel);
            app.StiffnessXCheckBox_2.Text = 'Stiffness : X';
            app.Mount3Panel = uipanel(app.ValidationTab);
            app.Mount3Panel.Title = 'Mount 3';
            app.PositionXCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.PositionXCheckBox_3.Text = 'Position: X';
            app.PositionYCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.PositionYCheckBox_3.Text = 'Position: Y';
            app.PositionZCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.PositionZCheckBox_3.Text = 'Position: Z';
            app.OrientationXCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.OrientationXCheckBox_3.Text = 'Orientation : X';
            app.OrientationYCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.OrientationYCheckBox_3.Text = 'Orientation : Y';
            app.OrientationZCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.OrientationZCheckBox_3.Text = 'Orientation : Z';
            app.StiffnessZCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.StiffnessZCheckBox_3.Text = 'Stiffness : Z';
            app.StiffnessYCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.StiffnessYCheckBox_3.Text = 'Stiffness : Y';
            app.StiffnessXCheckBox_3 = uicheckbox(app.Mount3Panel);
            app.StiffnessXCheckBox_3.Text = 'Stiffness : X';
            app.PurturbButton = uibutton(app.ValidationTab, 'push');
            app.PurturbButton.ButtonPushedFcn = createCallbackFcn(app, @PurturbButtonPushed, true);
            app.PurturbButton.Text = 'Purturb';
            app.inthissectionyoucanplotLabel = uilabel(app.ValidationTab);
            app.inthissectionyoucanplotLabel.Text = 'in this section you can see variation of objective value with puturbed datas';
            app.SelectvaluestobepurturbedLabel = uilabel(app.ValidationTab);
            app.SelectvaluestobepurturbedLabel.Text = 'Select values to be purturbed';
            app.StaticTestsTab = uitab(app.TabGroup);
            app.StaticTestsTab.Title = 'Static Tests';
            app.StaticTestsPanel = uipanel(app.StaticTestsTab);
            app.StaticTestsPanel.Title = 'Static Tests';
            app.UITable_test = uitable(app.StaticTestsPanel);
            app.UITable_test.ColumnName = {'name'; 'x'; 'y'; 'z'; 'disp_x'; 'disp_y'; 'disp_z'; 'rot_x'; 'rot_y'; 'rot_z'};
            app.UITable_test.RowName = {};
            app.EnterStaticAcceleraioningLabel = uilabel(app.StaticTestsPanel);
            app.EnterStaticAcceleraioningLabel.Text = 'Enter Static Acceleraion in g';
            app.StaticTestsButton_2 = uibutton(app.StaticTestsTab, 'push');
            app.StaticTestsButton_2.ButtonPushedFcn = createCallbackFcn(app, @StaticTestsButton_2Pushed, true);
            app.StaticTestsButton_2.Text = 'Static Tests';
            app.PreProcessLabel = uilabel(app.UIFigure);
            app.PreProcessLabel.Text = 'PreProcess';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.Text = 'Close';
            app.CGDisplacementsButton = uibutton(app.UIFigure, 'push');
            app.CGDisplacementsButton.Text = 'CG Displacements';
            app.StaticDeflectionsButton = uibutton(app.UIFigure, 'push');
            app.StaticDeflectionsButton.ButtonPushedFcn = createCallbackFcn(app, @StaticDeflectionsButtonPushed, true);
            app.StaticDeflectionsButton.Text = 'Static Deflections';
            app.StaticTestsButton = uibutton(app.UIFigure, 'push');
            app.StaticTestsButton.Text = 'Static Tests';
            app.SelectedEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedEditFieldLabel.Text = 'Selected';
            app.SelectedEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_pre

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end