classdef gui_struct_cons < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetConstraintUIFigure  matlab.ui.Figure
        K_xLabel               matlab.ui.control.Label
        K_yLabel_2             matlab.ui.control.Label
        K_zLabel_2             matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        K_xLabel_4             matlab.ui.control.Label
        K_yLabel               matlab.ui.control.Label
        K_zLabel               matlab.ui.control.Label
        SaveButton             matlab.ui.control.Button
        PropertyDropDownLabel  matlab.ui.control.Label
        PropertyDropDown       matlab.ui.control.DropDown
        UITable                matlab.ui.control.Table
        CloseButton            matlab.ui.control.Button
        ethaCheckBox           matlab.ui.control.CheckBox
        EditField              matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        handle_gui_main % Description
        name
        etha=0;
        
        property
    end

    methods (Access = private)
    
       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.ethaCheckBoxValueChanged();
        end

        % Value changed function: PropertyDropDown
        function PropertyDropDownValueChanged(app, event)
            value = app.PropertyDropDown.Value;
            h=app.handle_gui_main;
           switch value
               case 'Mount 1: Stiffness'
                   mat = h.t_k1;
                   app.name = 't_k1';
                   app.K_xLabel.Text = 'K_x';
                   app.K_yLabel_2.Text = 'K_y';
                   app.K_zLabel_2.Text = 'K_z';
                   app.EditField.Visible = 'off';
                   app.ethaCheckBox.Visible = 'off';
               case 'Mount 2: Stiffness'
                   mat = h.t_k2;
                   app.name = 't_k2';
                    app.K_xLabel.Text = 'K_x';
                   app.K_yLabel_2.Text = 'K_y';
                   app.K_zLabel_2.Text = 'K_z';
                   app.EditField.Visible = 'off';
                   app.ethaCheckBox.Visible = 'off';
               case 'Mount 3: Stiffness'
                   mat = h.t_k3;
                   app.name = 't_k3';
                    app.K_xLabel.Text = 'K_x';
                   app.K_yLabel_2.Text = 'K_y';
                   app.K_zLabel_2.Text = 'K_z';
                   app.EditField.Visible = 'off';
                   app.ethaCheckBox.Visible = 'off';
               case 'Mount 1: Damping'
                   mat = h.t_c1
                   app.name = 't_c1';
                   app.K_xLabel.Text = 'C_x';
                   app.K_yLabel_2.Text = 'C_y';
                   app.K_zLabel_2.Text = 'C_z';
                   app.EditField.Visible = 'on';
                   app.ethaCheckBox.Visible = 'on';
               case 'Mount 2: Damping'
                   mat = h.t_c2;
                   app.name = 't_c2';
                   app.K_xLabel.Text = 'C_x';
                   app.K_yLabel_2.Text = 'C_y';
                   app.K_zLabel_2.Text = 'C_z';
                   app.EditField.Visible = 'on';
                   app.ethaCheckBox.Visible = 'on';
               case 'Mount 3: Damping'
                   mat = h.t_c3;
                   app.name = 't_c3';
                   app.K_xLabel.Text = 'C_x';
                   app.K_yLabel_2.Text = 'C_y';
                   app.K_zLabel_2.Text = 'C_z';
                   app.EditField.Visible = 'on';
                   app.ethaCheckBox.Visible = 'on';
           end
           app.UITable.Data = num2cell(mat);
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            h=app.handle_gui_main;
            mat = cell2mat(app.UITable.Data);
            eval(['h.',app.name,'= mat;']);
            msgbox('Property Saved Successfully')
            
        end

        % Value changed function: ethaCheckBox
        function ethaCheckBoxValueChanged(app, event)
            value = app.ethaCheckBox.Value;
            if value == 1
               app.etha = app.EditField.Value;
               app.UITable.Data = num2cell((diag([app.etha,app.etha,app.etha])));
            
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetConstraintUIFigure = uifigure;
            app.SetConstraintUIFigure.Name = 'Set Constraint';
            app.K_xLabel = uilabel(app.SetConstraintUIFigure);
            app.K_xLabel.Text = 'K_x';
            app.K_yLabel_2 = uilabel(app.SetConstraintUIFigure);
            app.K_yLabel_2.Text = 'K_y';
            app.K_zLabel_2 = uilabel(app.SetConstraintUIFigure);
            app.K_zLabel_2.Text = 'K_z';
            app.Label = uilabel(app.SetConstraintUIFigure);
            app.Label.Text = '[';
            app.Label_2 = uilabel(app.SetConstraintUIFigure);
            app.Label_2.Text = '=';
            app.Label_3 = uilabel(app.SetConstraintUIFigure);
            app.Label_3.Text = ']';
            app.Label_4 = uilabel(app.SetConstraintUIFigure);
            app.Label_4.Text = '*';
            app.K_xLabel_4 = uilabel(app.SetConstraintUIFigure);
            app.K_xLabel_4.Text = 'K_x';
            app.K_yLabel = uilabel(app.SetConstraintUIFigure);
            app.K_yLabel.Text = 'K_y';
            app.K_zLabel = uilabel(app.SetConstraintUIFigure);
            app.K_zLabel.Text = 'K_z';
            app.SaveButton = uibutton(app.SetConstraintUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PropertyDropDownLabel = uilabel(app.SetConstraintUIFigure);
            app.PropertyDropDownLabel.Text = 'Property';
            app.PropertyDropDown = uidropdown(app.SetConstraintUIFigure);
            app.PropertyDropDown.Items = {'Choose property', 'Mount 1: Stiffness', 'Mount 2: Stiffness', 'Mount 3: Stiffness', 'Mount 1: Damping', 'Mount 2: Damping', 'Mount 3: Damping'};
            app.PropertyDropDown.ValueChangedFcn = createCallbackFcn(app, @PropertyDropDownValueChanged, true);
            app.PropertyDropDown.Value = 'Choose property';
            app.UITable = uitable(app.SetConstraintUIFigure);
            app.UITable.ColumnName = {''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.CloseButton = uibutton(app.SetConstraintUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ethaCheckBox = uicheckbox(app.SetConstraintUIFigure);
            app.ethaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ethaCheckBoxValueChanged, true);
            app.ethaCheckBox.Text = 'etha';
            app.EditField = uieditfield(app.SetConstraintUIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_struct_cons

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetConstraintUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetConstraintUIFigure)
        end
    end
end